classdef OpenIPDM_standalone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenFolderMenu                  matlab.ui.container.Menu
        ReportsMenu                     matlab.ui.container.Menu
        RawDatabaseMenu                 matlab.ui.container.Menu
        ParametersMenu                  matlab.ui.container.Menu
        FiguresMenu                     matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        LoadParametersMenu              matlab.ui.container.Menu
        PreprocessDataMenu              matlab.ui.container.Menu
        LoadResultsNetworkMenu          matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        ReadRealDataMenu                matlab.ui.container.Menu
        ModelTrainingNetwokMenu         matlab.ui.container.Menu
        ModelTrainingGenericMenu        matlab.ui.container.Menu
        ModelTrainingInterventionMenu   matlab.ui.container.Menu
        GenerateSyntheticDataMenu       matlab.ui.container.Menu
        InspectorsDataMenu              matlab.ui.container.Menu
        AnalysesMenu                    matlab.ui.container.Menu
        HideInspectorIDMenu             matlab.ui.container.Menu
        GenerateReportsMenu             matlab.ui.container.Menu
        EffectofInterventionsTableYearsMenu  matlab.ui.container.Menu
        MunicipalityLevelAnalysesMenu   matlab.ui.container.Menu
        StructureLevelAnalysesMenu      matlab.ui.container.Menu
        VaidateRealDataMenu             matlab.ui.container.Menu
        VerifySyntheticDataMenu         matlab.ui.container.Menu
        SingleTimeSeriesMenu            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        SoftwareManualMenu              matlab.ui.container.Menu
        DeveloperMenu                   matlab.ui.container.Menu
        UnitTestMainUIMenu              matlab.ui.container.Menu
        StatusMenu                      matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        PositionElemLabel               matlab.ui.control.Label
        PosLabel                        matlab.ui.control.Label
        SpanNoElemLabel                 matlab.ui.control.Label
        SpanLabel                       matlab.ui.control.Label
        TypeElemLabel                   matlab.ui.control.Label
        TypeLabel                       matlab.ui.control.Label
        MaterialElemLabel               matlab.ui.control.Label
        MaterialLabel                   matlab.ui.control.Label
        FilterElm                       matlab.ui.control.DropDown
        SelectCat                       matlab.ui.control.DropDown
        FilterCat                       matlab.ui.control.DropDown
        StructValue                     matlab.ui.control.DropDown
        FilterStructures                matlab.ui.control.DropDown
        TabGroup                        matlab.ui.container.TabGroup
        ElementTab                      matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        IntServiceLifeButton            matlab.ui.control.Button
        AutocorrectElem                 matlab.ui.control.Switch
        OutliersMissingIntAutocorrectLabel  matlab.ui.control.Label
        ForecastYearsSlider             matlab.ui.control.Slider
        ForecastYearsSliderLabel        matlab.ui.control.Label
        ElmSpeed                        matlab.ui.control.UIAxes
        ElmCondition                    matlab.ui.control.UIAxes
        CategoryTab                     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ExtractSaveButton_Cat           matlab.ui.control.Button
        NetworkScaleresultsreportLabel  matlab.ui.control.Label
        CondSpeedButton                 matlab.ui.control.Button
        YearSpinner                     matlab.ui.control.Spinner
        YearSpinnerLabel                matlab.ui.control.Label
        AnalysesLevelDropDown           matlab.ui.control.DropDown
        IntCodeDropDown                 matlab.ui.control.DropDown
        InterventionCDFButton           matlab.ui.control.Button
        OutliersMissingIntAutocorrectLabel_2  matlab.ui.control.Label
        AutocorrectCat                  matlab.ui.control.Switch
        ForecastYearsSlider_2Label      matlab.ui.control.Label
        ForecastYearsSlider_2           matlab.ui.control.Slider
        CatCond                         matlab.ui.control.UIAxes
        CatSpeed                        matlab.ui.control.UIAxes
        StructureTab                    matlab.ui.container.Tab
        ExtractSaveButton_Struc         matlab.ui.control.Button
        AutocorrectStruc                matlab.ui.control.Switch
        OutliersMissingIntAutocorrectLabel_3  matlab.ui.control.Label
        AnalyzeStruc                    matlab.ui.control.Button
        StructuralElementsGroupDropDown  matlab.ui.control.DropDown
        StructuralElementsGroupDropDownLabel  matlab.ui.control.Label
        ForecastYearsSliderStruc        matlab.ui.control.Slider
        ForecastYearsSlider_3Label      matlab.ui.control.Label
        StrucSpeed                      matlab.ui.control.UIAxes
        StrucCond                       matlab.ui.control.UIAxes
        NetworkTab                      matlab.ui.container.Tab
        IntDatabaseButton               matlab.ui.control.Button
        ExtractSaveButton_net           matlab.ui.control.Button
        LoadResultsButton               matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        StatusDropDownNetwork           matlab.ui.control.DropDown
        StatusDropDownLabel             matlab.ui.control.Label
        AnalyzeNet                      matlab.ui.control.Button
        OutliersMissingIntAutocorrectSwitch  matlab.ui.control.Switch
        OutliersMissingIntAutocorrectSwitchLabel  matlab.ui.control.Label
        StructuralElementsGroupNet      matlab.ui.control.DropDown
        StructuralElementsGroupDropDown_2Label  matlab.ui.control.Label
        ForecastYearsSliderNet          matlab.ui.control.Slider
        ForecastYearsSlider_4Label      matlab.ui.control.Label
        NetSpeed                        matlab.ui.control.UIAxes
        NetCond                         matlab.ui.control.UIAxes
        ElementListBox                  matlab.ui.control.ListBox
        ElementListBoxLabel             matlab.ui.control.Label
        CategoryListBox                 matlab.ui.control.ListBox
        CategoryListBoxLabel            matlab.ui.control.Label
        StructureListBox                matlab.ui.control.ListBox
        StructureListBoxLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        SeDatabaseStore         % structural elements Database
        StDatabaseStore         % Structures Database
        InspectorsDatabaseStore % Inspectors Database
        IntDatabaseStore        % Intervention Database
        IntBudgetDataStore      % Budget Database
        BridgeData              % Bridge data
        BridgeAttributes        % Bridge attributes
        Bridge_Int              % Bridge Interventions
        Bridge_Cost             % Bridge Cost (Intervention)
        Bridge_Details          % Structrual Elements Details
        ElementDetailsDataStore % Element Details
        ModelParam              % Model Parameters
        curve_param = 4;        % transformation function param
        ModelParam_Intervention % Model Parameters for Interventions
        PrimaryElements         % Primary Structural Element Group
        NoVIData                % Elements without visual inspections
        NetworkResults          % Save network analyses results
        PreLoadData=0           % Logical 0 or 1 for preloaded data
        BridgeFiles             % Files for preloaded data
        InspectorsData          % Inspectors database
        MainPathName            % Path name for preloaded data
        StructFilterVals=[];    % Filter structures by Type-Manucipality-Status
        TotalYearsDuration=2005:2054;
        warning_msg = 1;
        condition_threshold = 0;% condition threshold 
        estimates_storage = {}  % store post-analyses resutls for reports
    end
    
    methods (Access = public)
        
        function PassValues(app,SE,ST,IP,IN,IB,ED)
            app.SeDatabaseStore=SE;
            app.StDatabaseStore=ST;
            app.InspectorsDatabaseStore=IP;
            app.IntDatabaseStore=IN;
            app.IntBudgetDataStore=IB;
            app.ElementDetailsDataStore=ED;
        end
        function PassValues2(app,MP,MPI)
            app.ModelParam=MP;
            app.ModelParam_Intervention=MPI;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if (isfolder('Network Data'))
                app.BridgeFiles = dir('Network Data/b*.mat');
                Inspectors = dir('Network Data/A*.mat');
                StrructFilter = dir('Network Data/S*.mat');
                if ~isempty(app.BridgeFiles) && ~isempty(Inspectors)
                    app.PreLoadData=1;
                end
            end
            addpath(sprintf('%s/Tools/',pwd));
            if app.PreLoadData
                try
                    app.MainPathName=[pwd '/Network Data/'];
                    app.StructureListBox.Items= strrep({(app.BridgeFiles.name)},'.mat','');
                    LoadedData=load([app.MainPathName app.BridgeFiles(1).name]);
                    LoadedData=struct2cell(LoadedData);
                    app.BridgeData=LoadedData{1};
                    app.BridgeAttributes=LoadedData{2};
                    app.Bridge_Int=LoadedData{3};
                    app.Bridge_Cost=LoadedData{4};
                    app.Bridge_Details=LoadedData{5};
                    MetaData=LoadedData{6};
                    app.FilterStructures.Items=MetaData.Str;
                    app.StatusDropDownNetwork.Items=MetaData.Net;
                    app.ElementListBox.Items={'No Selection'};
                    app.CategoryListBox.Items={'No Selection'};
                    app.StructValue.Items={'No Selection'};
                    app.SelectCat.Items={'No Selection'};
                    InspectorsLoad=load([app.MainPathName Inspectors.name]);
                    InspectorsLoad=struct2cell(InspectorsLoad);
                    app.InspectorsData=InspectorsLoad{1};
                    app.StructFilterVals=load([app.MainPathName StrructFilter.name]);
                    app.StructFilterVals=struct2cell(app.StructFilterVals);
                    app.StructFilterVals=app.StructFilterVals{1};
                    app.StructFilterVals(:,1)=strcat('b',app.StructFilterVals(:,1));
                    
                    if (isfolder('Parameters'))
                        ModelParams = dir('Parameters/D*.mat');
                        if ~isempty(ModelParams)
                            FilePath=[pwd '/Parameters/'];
                            ModelParamLoad=load([FilePath ModelParams(1).name]);
                            app.ModelParam=ModelParamLoad;
                        else
                            LoadingPanel_Parameters(app);
                        end
                    else
                        LoadingPanel_Parameters(app);
                    end
                catch
                    message = 'Loading data has is not done, one or more files have not been loaded correctly';
                    uialert(app.MainWindow,message,'Error','Icon','error');
                end
            else
                
                waitfor(LoadingPanel(app));
                try
                    FilterStruc=[2 3 12];   % Filter menu list 2: Type, 3: Manicipalite, 12: Status
                    app.FilterStructures.Items=[{'All'} app.StDatabaseStore.SelectedVariableNames(FilterStruc)]';
                    app.StructValue.Items={'No Selection'};
                    app.SelectCat.Items={'No Selection'};
                    SV=app.StDatabaseStore.SelectedVariableNames;
                    app.StDatabaseStore.SelectedVariableNames=SV(1);
                    app.StructureListBox.Items=table2cell(readall(app.StDatabaseStore));
                    app.InspectorsData=str2double(table2cell(readall(app.InspectorsDatabaseStore)));
                    app.ElementListBox.Items={'No Selection'};
                    app.CategoryListBox.Items={'No Selection'};
                    app.StDatabaseStore.SelectedVariableNames=SV(12);
                    app.StatusDropDownNetwork.Items=unique(table2cell(readall(app.StDatabaseStore)));
                    app.StDatabaseStore.SelectedVariableNames=SV;
                    LoadingPanel_Parameters(app);
                catch
                    message = 'Loading data is not done, one or more files have not been loaded correctly';
                    uialert(app.MainWindow,message,'Error','Icon','error');
                end
            end
            
        end

        % Value changed function: StructureListBox
        function StructureListBoxValueChanged(app, event)
            if ~app.PreLoadData
                %             app.MainWindow.Pointer = 'watch';
                d=uiprogressdlg(app.MainWindow,'Title','Please Wait',...
                    'Message','Collecting bridge data from the database');
                pause(0.001);
                StructureID = app.StructureListBox.Value;
                SV=app.SeDatabaseStore.SelectedVariableNames;
                STv=app.StDatabaseStore.SelectedVariableNames;
                SIv=app.IntDatabaseStore.SelectedVariableNames;
                SBv=app.IntBudgetDataStore.SelectedVariableNames;
                SDv=app.ElementDetailsDataStore.SelectedVariableNames;
                app.SeDatabaseStore.SelectedVariableNames=SV(1);
                app.StDatabaseStore.SelectedVariableNames=STv(1);
                app.IntDatabaseStore.SelectedVariableNames=SIv(1);
                app.IntBudgetDataStore.SelectedVariableNames=SBv(1);
                app.ElementDetailsDataStore.SelectedVariableNames=SDv(1);
                ID=(strcmp(StructureID,table2cell(readall(app.SeDatabaseStore))));
                ID_st=(strcmp(StructureID,table2cell(readall(app.StDatabaseStore))));
                ID_Int=(strcmp(StructureID,table2cell(readall(app.IntDatabaseStore))));
                ID_Bud=(strcmp(StructureID,table2cell(readall(app.IntBudgetDataStore))));
                ID_Det=(strcmp(StructureID,table2cell(readall(app.ElementDetailsDataStore))));
                app.SeDatabaseStore.SelectedVariableNames=SV(4);
                StrucCat=table2cell(readall(app.SeDatabaseStore));
                d.Value = .45;
                d.Message = 'Loading structural elements cateories';
                pause(0.001);
                app.CategoryListBox.Items=unique(StrucCat(ID));
                app.ElementListBox.Items={'No Selection'};
                app.SeDatabaseStore.SelectedVariableNames=SV;
                app.StDatabaseStore.SelectedVariableNames=STv;
                app.IntDatabaseStore.SelectedVariableNames=SIv;
                app.IntBudgetDataStore.SelectedVariableNames=SBv;
                app.ElementDetailsDataStore.SelectedVariableNames=SDv;
                BD=table2cell(readall(app.SeDatabaseStore));
                d.Value = .8;
                d.Message = 'Loading elements data';
                pause(0.001);
                BDData=table2cell(readall(app.StDatabaseStore));
                BDIntData=table2cell(readall(app.IntDatabaseStore));
                BDBudData=table2cell(readall(app.IntBudgetDataStore));
                BDDetData=table2cell(readall(app.ElementDetailsDataStore));
                d.Value = .98;
                d.Message = 'done';
                pause(0.001);
                app.BridgeData=BD(ID,:);
                app.BridgeAttributes=BDData(ID_st,:);
                app.Bridge_Int=BDIntData(ID_Int,:);
                app.Bridge_Cost=BDBudData(ID_Bud,:);
                app.Bridge_Details=BDDetData(ID_Det,:);
                close(d);
                %             app.MainWindow.Pointer = 'arrow';
            else
                LoadedData=load([app.MainPathName app.StructureListBox.Value '.mat']);
                LoadedData=struct2cell(LoadedData);
                app.BridgeData=LoadedData{1};
                app.BridgeAttributes=LoadedData{2};
                app.Bridge_Int=LoadedData{3};
                app.Bridge_Cost=LoadedData{4};
                app.Bridge_Details=LoadedData{5};
                MetaData=LoadedData{6};
                app.FilterStructures.Items=MetaData.Str;
                app.StatusDropDownNetwork.Items=MetaData.Net;
                app.CategoryListBox.Items=unique(app.BridgeData(:,4));
                app.ElementListBox.Items={'No Selection'};
            end
            Cats=length(app.CategoryListBox.Items);
            if strcmp(app.TabGroup.SelectedTab.Title,'Element') && Cats>1 && ~isempty(app.CategoryListBox.Value)
                app.CategoryListBox.ValueChangedFcn(app, event);
            end
            
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            app.PreLoadData=0;
            addpath(sprintf('%s/Tools/',pwd));
            waitfor(LoadingPanel(app));
            try
                FilterStruc=[2 3 12];   % Filter menu list 2: Type, 3: Manicipalite, 12: Status
                app.FilterStructures.Items=[{'All'} app.StDatabaseStore.SelectedVariableNames(FilterStruc)]';
                app.StructValue.Items={'No Selection'};
                app.SelectCat.Items={'No Selection'};
                SV=app.StDatabaseStore.SelectedVariableNames;
                app.StDatabaseStore.SelectedVariableNames=SV(1);
                app.StructureListBox.Items=table2cell(readall(app.StDatabaseStore));
                app.InspectorsData=str2double(table2cell(readall(app.InspectorsDatabaseStore)));
                app.ElementListBox.Items={'No Selection'};
                app.CategoryListBox.Items={'No Selection'};
                app.StDatabaseStore.SelectedVariableNames=SV;
            catch
                message = 'One or more files have not been loaded, there are no changes on the input.';
                uialert(app.MainWindow,message,'Warning','Icon','warning');
            end
        end

        % Value changed function: FilterStructures
        function FilterStructuresValueChanged(app, event)
            value = find(strcmp(app.FilterStructures.Value,app.FilterStructures.Items));
            if app.PreLoadData
                if value==1
                    app.StructValue.Items={'None'};
                    app.StructureListBox.Items=app.StructFilterVals(:,1);
                elseif value==2
                    app.StructValue.Items=unique(app.StructFilterVals(:,2));
                elseif value==3
                    app.StructValue.Items=unique(app.StructFilterVals(:,3));
                elseif value==4
                    app.StructValue.Items=unique(app.StructFilterVals(:,4));
                end
            else
                if value==1
                    app.StructValue.Items={'None'};
                    SV=app.StDatabaseStore.SelectedVariableNames;
                    app.StDatabaseStore.SelectedVariableNames=SV(1);
                    app.StructureListBox.Items=table2cell(readall(app.StDatabaseStore));
                    app.StDatabaseStore.SelectedVariableNames=SV;
                elseif value==2
                    SV=app.StDatabaseStore.SelectedVariableNames;
                    app.StDatabaseStore.SelectedVariableNames=SV(2);
                    app.StructValue.Items=unique(table2cell(readall(app.StDatabaseStore)));
                    app.StDatabaseStore.SelectedVariableNames=SV;
                elseif value==3
                    SV=app.StDatabaseStore.SelectedVariableNames;
                    app.StDatabaseStore.SelectedVariableNames=SV(3);
                    app.StructValue.Items=unique(table2cell(readall(app.StDatabaseStore)));
                    app.StDatabaseStore.SelectedVariableNames=SV;
                elseif value==4
                    SV=app.StDatabaseStore.SelectedVariableNames;
                    app.StDatabaseStore.SelectedVariableNames=SV(12);
                    app.StructValue.Items=unique(table2cell(readall(app.StDatabaseStore)));
                    app.StDatabaseStore.SelectedVariableNames=SV;
                end
            end
        end

        % Value changed function: StructValue
        function StructValueValueChanged(app, event)
            value = app.StructValue.Value;
            option_val = find(strcmp(app.FilterStructures.Value,app.FilterStructures.Items));
            if app.PreLoadData
                FilteredItems={};
                if option_val==2
                    IDstr=strcmp(value,app.StructFilterVals(:,2));
                    FilteredItems=app.StructFilterVals(IDstr,1);
                    app.StructureListBox.Items=FilteredItems;
                    
                elseif option_val==3
                    IDstr=strcmp(value,app.StructFilterVals(:,3));
                    FilteredItems=app.StructFilterVals(IDstr,1);
                    app.StructureListBox.Items=FilteredItems;
                elseif option_val==4
                    IDstr=strcmp(value,app.StructFilterVals(:,4));
                    FilteredItems=app.StructFilterVals(IDstr,1);
                    app.StructureListBox.Items=FilteredItems;
                end
            else
                if option_val==2
                    SV=app.StDatabaseStore.SelectedVariableNames;
                    app.StDatabaseStore.SelectedVariableNames=SV(2);
                    ID=(strcmp(value,table2cell(readall(app.StDatabaseStore))));
                    app.StDatabaseStore.SelectedVariableNames=SV(1);
                    StrucID=table2cell(readall(app.StDatabaseStore));
                    app.StructureListBox.Items=StrucID(ID);
                    app.StDatabaseStore.SelectedVariableNames=SV;
                elseif option_val==3
                    SV=app.StDatabaseStore.SelectedVariableNames;
                    app.StDatabaseStore.SelectedVariableNames=SV(3);
                    ID=(strcmp(value,table2cell(readall(app.StDatabaseStore))));
                    app.StDatabaseStore.SelectedVariableNames=SV(1);
                    StrucID=table2cell(readall(app.StDatabaseStore));
                    app.StructureListBox.Items=StrucID(ID);
                    app.StDatabaseStore.SelectedVariableNames=SV;
                elseif option_val==4
                    SV=app.StDatabaseStore.SelectedVariableNames;
                    app.StDatabaseStore.SelectedVariableNames=SV(12);
                    ID=(strcmp(value,table2cell(readall(app.StDatabaseStore))));
                    app.StDatabaseStore.SelectedVariableNames=SV(1);
                    StrucID=table2cell(readall(app.StDatabaseStore));
                    app.StructureListBox.Items=StrucID(ID);
                    app.StDatabaseStore.SelectedVariableNames=SV;
                end
            end
        end

        % Value changed function: CategoryListBox
        function CategoryListBoxValueChanged(app, event)
            % Column #4: Element Category
            %             app.MainWindow.Pointer = 'watch';
            pause(0.001);
            CatID = app.CategoryListBox.Value;
            IDElm=(strcmp(CatID,app.BridgeData(:,4)));
            Elms=unique(app.BridgeData(IDElm,end));
            app.ElementListBox.Items=Elms;
            if ~isempty(Elms) && strcmp(app.TabGroup.SelectedTab.Title,'Element')
                ElementListBoxValueChanged(app, event)
            end
            %             app.MainWindow.Pointer = 'arrow';
        end

        % Value changed function: ElementListBox
        function ElementListBoxValueChanged(app, event)
            Option=app.FilterElm.Value;
            Elm = app.ElementListBox.Value;
            app.IntServiceLifeButton.Enable='Off';
            ElemAnalyses=1;CatAnalyses=0;SlCatAnalyses=0;CatReport=0;
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                if find(strcmp(Option,app.FilterElm.Items))==1
                    run('da_element.m');
                else
                    run('cec_da_element.m');
                end
            end
            
        end

        % Menu selected function: LoadParametersMenu
        function LoadParametersMenuSelected(app, event)
            try
                addpath(sprintf('%s/Tools/',pwd));
                LoadingPanel_Parameters(app);
            catch
                message = 'Loading parameters is not complete, please make sure to load the right file format';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Value changed function: ForecastYearsSlider
        function ForecastYearsSliderValueChanged(app, event)
            Option=app.FilterElm.Value;
            Elm = app.ElementListBox.Value;
            app.IntServiceLifeButton.Enable='Off';
            ElemAnalyses=1;CatAnalyses=0;SlCatAnalyses=0; CatReport=0;
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                try
                    if find(strcmp(Option,app.FilterElm.Items))==1
                        run('da_element.m');
                    else
                        run('cec_da_element.m');
                    end
                catch
                    message = 'No deteriroation analyses due to either, no structural element selection or an error related to the input data.';
                    uialert(app.MainWindow,message,'Error','Icon','error');
                end
            end
            
        end

        % Value changed function: FilterCat
        function FilterCatValueChanged(app, event)
            % Element Details
            % Database Columns
            % 1: Bridge ID % 2: NoTrav % 3: Element ID % 4: Element Category % 5: No. Element
            % 6: Position % 7: Materiau % 8: TypeElement % 9: Importance % 10: QTE Calculee
            value = app.FilterCat.Value;
            if strcmp(value,'Group')&& length(app.CategoryListBox.Items)>1
                groupname=unique(app.Bridge_Details(:,9));
                app.SelectCat.Items=['No Selection';groupname];
            else
                app.SelectCat.Items={'No Selection'};
                SelectedCats=unique(app.Bridge_Details(:,4));
                app.CategoryListBox.Items=SelectedCats;
            end
        end

        % Value changed function: SelectCat
        function SelectCatValueChanged(app, event)
            value = app.SelectCat.Value;
            ID_Val=find(strcmp(value,app.SelectCat.Items));
            if ID_Val>1
                IDs=strcmp(value,app.Bridge_Details(:,9));
                SelectedCats=unique(app.Bridge_Details(IDs,4));
                app.CategoryListBox.Items=SelectedCats;
            else
                SelectedCats=unique(app.Bridge_Details(:,4));
                app.CategoryListBox.Items=SelectedCats;
            end
        end

        % Value changed function: FilterElm
        function FilterElmValueChanged(app, event)
            Option=app.FilterElm.Value;
            Elm = app.ElementListBox.Value;
            ElemAnalyses=1;CatAnalyses=0;SlCatAnalyses=0; CatReport=0;
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                if find(strcmp(Option,app.FilterElm.Items))==1
                    run('da_element.m');
                else
                    run('cec_da_element.m');
                end
            end
            
        end

        % Menu selected function: GenerateSyntheticDataMenu
        function GenerateSyntheticDataMenuSelected(app, event)
            try
                addpath(sprintf('%s/Tools/SyntheticDataToolbox/',pwd))
                addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
                run('SyntheticData_App.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: ModelTrainingNetwokMenu
        function ModelTrainingNetwokMenuSelected(app, event)
            try
                addpath(sprintf('%s/Tools/ModelTraining/',pwd))
                addpath(sprintf('%s/Scripts/Load/',pwd))
                run('ModelTraining.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: ReadRealDataMenu
        function ReadRealDataMenuSelected(app, event)
            try
                addpath(sprintf('%s/Tools/ReadDataToolbox/',pwd))
                run('ReadDatabase_App.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: SingleTimeSeriesMenu
        function SingleTimeSeriesMenuSelected(app, event)
            try
                rmpath(sprintf('%s/Tools/PredictRealandSynthetic/',pwd))
                rmpath(sprintf('%s/Tools/ModelTraining_Generic/',pwd))
                addpath(sprintf('%s/Tools/SingleTimeSeriesAnalyses/',pwd))
                run('single_time.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: VerifySyntheticDataMenu
        function VerifySyntheticDataMenuSelected(app, event)
            try
                rmpath(sprintf('%s/Tools/ModelTraining_Generic/',pwd))
                addpath(sprintf('%s/Tools/PredictRealandSynthetic/',pwd))
                run('SyntheticPred_App.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Callback function
        function InitialStateEstimationSyntheticMenuSelected(app, event)
            try
                addpath(sprintf('%s/Tools/InitialParametersToolbox/',pwd))
                run('InitialStateAnalyses_App.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: ModelTrainingGenericMenu
        function ModelTrainingGenericMenuSelected(app, event)
            try
                addpath(sprintf('%s/Tools/ModelTraining_Generic/',pwd))
                run('OptimizationVID_App.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            uialert(app.MainWindow,[{'OpenIPDM App'};{''};{'Developed By: Zachary A. Hamida, Ph.D.'};{'Webpage: zachamida.github.io'};{''};{'Prof. James-A. Goulet Research Group'};{'Email: james.goulet@polymtl.ca'}],'Info',"Icon","info")
        end

        % Callback function
        function AnalyzeCategoryButtonPushed(app, event)
            Elms = app.ElementListBox.Items;
            CatAnalyses=1;StrucAnalyses=0;NetAnalyses=0;CatReport=0;
            AutoCorrectVal=app.AutocorrectElem.Value;
            app.IntServiceLifeButton.Enable=0;
            
            if strcmp(app.AutocorrectCat.Value,'On')
                app.AutocorrectElem.Value='On';
            else
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                run('da_category.m');
            end
            app.AutocorrectElem.Value=AutoCorrectVal;
            
        end

        % Value changed function: ForecastYearsSlider_2
        function ForecastYearsSlider_2ValueChanged(app, event)
            Elms = app.ElementListBox.Items;
            CatAnalyses=1;StrucAnalyses=0;NetAnalyses=0;SlCatAnalyses=0;
            CatReport=0;
            AutoCorrectVal=app.AutocorrectElem.Value;
            app.IntServiceLifeButton.Enable=0;
            
            if strcmp(app.AutocorrectCat.Value,'On')
                app.AutocorrectElem.Value='On';
            else
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                try
                    run('da_category.m');
                catch
                    message = 'No deteriroation analyses due to either, no active selection or an error related to the input data.';
                    uialert(app.MainWindow,message,'Error','Icon','error');
                end
            end
            app.AutocorrectElem.Value=AutoCorrectVal;
        end

        % Button pushed function: AnalyzeStruc
        function AnalyzeStrucButtonPushed(app, event)
            if length(app.CategoryListBox.Items)==1
                app.StructureListBox.ValueChangedFcn(app,event);
            end
            if length(app.SelectCat.Items)==1
                app.FilterCat.Value='Group';
                app.FilterCat.ValueChangedFcn(app,event);
            end
            if strcmp(app.StructuralElementsGroupNet.Value,app.SelectCat.Items(2))
                app.FilterCat.Value='Group';
                app.FilterCat.ValueChangedFcn(app,event);
                if length(app.SelectCat.Items)>1
                    app.SelectCat.Value=app.SelectCat.Items(2);
                    app.SelectCat.ValueChangedFcn(app,event);
                end
            else
                app.FilterCat.Value='Group';
                app.FilterCat.ValueChangedFcn(app,event);
                if length(app.SelectCat.Items)>2
                    app.SelectCat.Value=app.SelectCat.Items(3);
                    app.SelectCat.ValueChangedFcn(app,event);
                end
            end
            Cats=app.CategoryListBox.Items;
            StrucAnalyses=1;NetAnalyses=0;CatReport=0;
            app.IntServiceLifeButton.Enable=0;
            
            AutoCorrectVal=app.AutocorrectElem.Value;
            if strcmp(app.AutocorrectStruc.Value,'On')
                app.AutocorrectElem.Value='On';
            else
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                run('da_structure.m');
            end
            app.AutocorrectElem.Value=AutoCorrectVal;
        end

        % Button pushed function: AnalyzeNet
        function AnalyzeNetButtonPushed(app, event)
            if length(app.CategoryListBox.Items)==1
                app.StructureListBox.ValueChangedFcn(app,event);
            end
            if length(app.SelectCat.Items)==1
                app.FilterCat.Value='Group';
                app.FilterCat.ValueChangedFcn(app,event);
            end
            if strcmp(app.StructuralElementsGroupNet.Value,app.SelectCat.Items(2))
                app.FilterCat.Value='Group';
                app.FilterCat.ValueChangedFcn(app,event);
                if length(app.SelectCat.Items)>1
                    app.SelectCat.Value=app.SelectCat.Items(2);
                    app.SelectCat.ValueChangedFcn(app,event);
                end
            else
                app.FilterCat.Value='Group';
                app.FilterCat.ValueChangedFcn(app,event);
                if length(app.SelectCat.Items)>2
                    app.SelectCat.Value=app.SelectCat.Items(3);
                    app.SelectCat.ValueChangedFcn(app,event);
                end
            end
            app.IntServiceLifeButton.Enable=0;
            Strucs=app.StructureListBox.Items;
            NetAnalyses=1;
            AutoCorrectVal=app.AutocorrectElem.Value;
            
            if strcmp(app.OutliersMissingIntAutocorrectSwitch.Value,'On')
                app.AutocorrectElem.Value='On';
            else
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                try
                    run('da_network.m');
                catch
                    message = 'Analyses stopped due to an unexpected error - network level';
                    uialert(app.MainWindow,message,'Error','Icon','error');
                end
            end
            app.AutocorrectElem.Value=AutoCorrectVal;
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            if ~isempty(app.NetworkResults)
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as',sprintf('NetworkResults_%s',app.NetworkResults.StrucVal));
                NetworkResults=app.NetworkResults;
                try
                    save(fullfile(pathname,filename),'NetworkResults');
                catch
                    message = 'File is not saved';
                    uialert(app.MainWindow,message,'Warning','Icon','warning');
                end
            else
                message = 'Network-scale analyses are required in order to obtain and save results.';
                uialert(app.MainWindow,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadResultsButton
        function LoadResultsButtonPushed(app, event)
            try
                addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd));
                addpath(sprintf('%s/Scripts/Load/',pwd));
                run('LoadResults.m');
                FilterStructuresValueChanged(app, event);
                StructValueValueChanged(app, event);
                app.StructValue.Value=app.NetworkResults.StrucVal;
                PlotTimeSeries(YearTotal,xtb,Std,yOr,[],Rtop,Rlow,[],InspectorIDLabel_y,InterventionVector,app.NetCond,app.NetSpeed,ColorCode);
            catch
                message = 'Data is not loaded';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: LoadResultsNetworkMenu
        function LoadResultsNetworkMenuSelected(app, event)
            try
                addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd));
                addpath(sprintf('%s/Scripts/Load/',pwd));
                run('LoadResults.m');
                FilterStructuresValueChanged(app, event);
                StructValueValueChanged(app, event);
                app.StructValue.Value=app.NetworkResults.StrucVal;
                PlotTimeSeries(YearTotal,xtb,Std,yOr,[],Rtop,Rlow,[],InspectorIDLabel_y,InterventionVector,app.NetCond,app.NetSpeed,ColorCode);
            catch
                message = 'Data is not loaded';
                uialert(app.MainWindow,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: IntServiceLifeButton
        function IntServiceLifeButtonPushed(app, event)
            Option=app.FilterElm.Value;
            Elm = app.ElementListBox.Value;
            ElemAnalyses=1;CatAnalyses=0;
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                run('da_element.m');
            end
        end

        % Menu selected function: PreprocessDataMenu
        function PreprocessDataMenuSelected(app, event)
            if not(isfolder('Network Data'))
                mkdir('Network Data');
            end
            pathname=[pwd '/Network Data/'];
            if strcmp(app.StructureListBox.Items{1}(1),'b')
                message = 'Data is already pre-processed!';
                uialert(app.MainWindow,message,'Warning','Icon','warning');
            else
                SV=app.StDatabaseStore.SelectedVariableNames;
                app.StDatabaseStore.SelectedVariableNames=SV([1 2 3 12]);
                StructFilters=table2cell(readall(app.StDatabaseStore));
                app.StDatabaseStore.SelectedVariableNames = SV;
                for i=1:length(app.StructureListBox.Items)
                    app.StructureListBox.Value=app.StructureListBox.Items(i);
                    StructureListBoxValueChanged(app, event);
                    filename=['b' app.StructureListBox.Value];
                    BD=app.BridgeData;
                    BDData=app.BridgeAttributes;
                    BDIntData=app.Bridge_Int;
                    BDBudData=app.Bridge_Cost;
                    BDDetData=app.Bridge_Details;
                    MetaData.Net=app.StatusDropDownNetwork.Items;
                    MetaData.Str=app.FilterStructures.Items;
                    save(fullfile(pathname,filename),"BD","BDData","BDDetData","BDBudData","BDIntData","MetaData");
                end
                AllInspections=str2double(table2cell(readall(app.InspectorsDatabaseStore)));
                filename='AllInspectors';
                save(fullfile(pathname,filename),"AllInspections");
                filename='StructFilters';
                save(fullfile(pathname,filename),"StructFilters");
            end
        end

        % Menu selected function: HideInspectorIDMenu
        function HideInspectorIDMenuSelected(app, event)
            if app.HideInspectorIDMenu.Checked
                app.HideInspectorIDMenu.Checked=0;
            else
                app.HideInspectorIDMenu.Checked=1;
            end
        end

        % Button pushed function: InterventionCDFButton
        function InterventionCDFButtonPushed(app, event)
            
            Elms = app.ElementListBox.Items;
            CatAnalyses=1;StrucAnalyses=0;NetAnalyses=0;CatReport=0;
            AutoCorrectVal=app.AutocorrectElem.Value;
            app.IntServiceLifeButton.Enable=0;
            
            if strcmp(app.AutocorrectCat.Value,'On')
                app.AutocorrectElem.Value='On';
            else
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                if strcmp(app.AnalysesLevelDropDown.Value,'Local')
                    NetCatAnalyses=0;
                    run('sl_category.m');
                else
                    Strucs=app.StructureListBox.Items;
                    run('sl_network.m');
                end
            end
            app.AutocorrectElem.Value=AutoCorrectVal;
        end

        % Value changed function: AutocorrectElem
        function AutocorrectElemValueChanged(app, event)
            
            Option=app.FilterElm.Value;
            Elm = app.ElementListBox.Value;
            app.IntServiceLifeButton.Enable='Off';
            ElemAnalyses=1;CatAnalyses=0;SlCatAnalyses=0;CatReport=0;
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                if find(strcmp(Option,app.FilterElm.Items))==1
                    run('da_element.m');
                else
                    run('cec_da_element.m');
                end
            end
        end

        % Value changed function: AutocorrectCat
        function AutocorrectCatValueChanged(app, event)
            
            Elms = app.ElementListBox.Items;
            CatAnalyses=1;CatReport=0;StrucAnalyses=0;NetAnalyses=0;SlCatAnalyses=0;
            AutoCorrectVal=app.AutocorrectElem.Value;
            app.IntServiceLifeButton.Enable=0;
            
            if strcmp(app.AutocorrectCat.Value,'On')
                app.AutocorrectElem.Value='On';
            else
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                run('da_category.m');
            end
            app.AutocorrectElem.Value=AutoCorrectVal;
        end

        % Button pushed function: CondSpeedButton
        function CondSpeedButtonPushed(app, event)
            Elms = app.ElementListBox.Items;
            CatAnalyses=0;StrucAnalyses=0;NetAnalyses=1;CatReport=0;
            AutoCorrectVal=app.AutocorrectElem.Value;
            
            if strcmp(app.AutocorrectCat.Value,'On')
                app.AutocorrectElem.Value='On';
            else
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            Strucs=app.StructureListBox.Items;
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                run('est_network.m');
            end
            app.AutocorrectElem.Value=AutoCorrectVal;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            fig_h = figure;
            copyobj(app.ElmCondition, fig_h);
            copyobj(app.ElmSpeed, fig_h);
            set(fig_h, 'Resize', 'on');
            figurename=app.ElementListBox.Value;
            %addpath(sprintf('%s/Tools/matlab2tikz-master/src',pwd));
            results = array2table(round([app.estimates_storage{1}' app.estimates_storage{2}(1:2,:)' ...
                app.estimates_storage{3}(5,:)'+app.estimates_storage{2}(1,:)' ...
                app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(6,:)' ...
                app.estimates_storage{3}(1,:)'+app.estimates_storage{2}(1,:)' ...
                app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(4,:)' ...
                app.estimates_storage{3}(8,:)'+app.estimates_storage{2}(2,:)' ...
                app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(9,:)' ...
                app.estimates_storage{3}(2,:)'+app.estimates_storage{2}(2,:)' ...
                app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(7,:)'], 2), ...
                'VariableNames',{'Years','Ex. Condition','Ex. Speed', ...
                'Ex.+1Std. Condition','Ex.-1Std. Condition','Ex.+2Std. Condition', ...
                'Ex.-2Std. Condition','Ex.+1Std. Speed','Ex.-1Std. Speed','Ex.+2Std. Speed', ...
                'Ex.-2Std. Speed'}); 
            try
                FOLDER_NAME = sprintf('Structure/%s/%s',app.StructureListBox.Value,app.CategoryListBox.Value);

                [status, msg, msgID] = mkdir(sprintf('Reports/%s',FOLDER_NAME));
            catch
                uialert(app.MainWindow,msg,'Warning','Icon','warning');
            end
            try
                matlab2tikz('figurehandle',fig_h,'filename',['Reports/' FOLDER_NAME '/' figurename '.tex'] ,'standalone', true,'showInfo', false,'floatFormat','%.5g');
                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME, figurename))
            catch
                exportgraphics(fig_h,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME, figurename))
                if app.warning_msg
                    message = 'In order to export .tex files, please make sure to install and add "matlab2tikz" library to the matlab path';
                    uialert(app.MainWindow,message,'Warning','Icon','warning');
                    app.warning_msg = 0;
                end
            end
        end

        % Button pushed function: ExtractSaveButton_Cat
        function ExtractSaveButton_CatPushed(app, event)
            fig_h = figure;
            copyobj(app.CatCond, fig_h);
            copyobj(app.CatSpeed, fig_h);
            set(fig_h, 'Resize', 'on');
            figurename=app.CategoryListBox.Value;
            %addpath(sprintf('%s/Tools/matlab2tikz-master/',pwd));
            results = array2table(round([app.estimates_storage{1}' app.estimates_storage{2}(1:2,:)' ...
                app.estimates_storage{3}(5,:)'+app.estimates_storage{2}(1,:)' ...
                app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(6,:)' ...
                app.estimates_storage{3}(1,:)'+app.estimates_storage{2}(1,:)' ...
                app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(4,:)' ...
                app.estimates_storage{3}(8,:)'+app.estimates_storage{2}(2,:)' ...
                app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(9,:)' ...
                app.estimates_storage{3}(2,:)'+app.estimates_storage{2}(2,:)' ...
                app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(7,:)'], 2), ...
                'VariableNames',{'Years','Ex. Condition','Ex. Speed', ...
                'Ex.+1Std. Condition','Ex.-1Std. Condition','Ex.+2Std. Condition', ...
                'Ex.-2Std. Condition','Ex.+1Std. Speed','Ex.-1Std. Speed','Ex.+2Std. Speed', ...
                'Ex.-2Std. Speed'}); 
            try
                FOLDER_NAME = sprintf('Structure/%s/%s',app.StructureListBox.Value,app.CategoryListBox.Value);

                [status, msg, msgID] = mkdir(sprintf('Reports/%s',FOLDER_NAME));
            catch
                uialert(app.MainWindow,msg,'Warning','Icon','warning');
            end
            try
                matlab2tikz('figurehandle',fig_h,'filename',['Reports/' FOLDER_NAME '/' figurename '.tex'] ,'standalone', true,'showInfo', false,'floatFormat','%.5g');
                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME, figurename))
            catch
                exportgraphics(fig_h,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME, figurename))
                if app.warning_msg
                    message = 'In order to export .tex files, please make sure to install and add "matlab2tikz" library to the matlab path';
                    uialert(app.MainWindow,message,'Warning','Icon','warning');
                    app.warning_msg = 0;
                end
            end
        end

        % Button pushed function: ExtractSaveButton_Struc
        function ExtractSaveButton_StrucPushed(app, event)
            fig_h = figure;
            copyobj(app.StrucCond, fig_h);
            copyobj(app.StrucSpeed, fig_h);
            set(fig_h, 'Resize', 'on');
            figurename=app.StructureListBox.Value;
            %addpath(sprintf('%s/Tools/matlab2tikz-master/',pwd));
            results = array2table(round([app.estimates_storage{1}' app.estimates_storage{2}(1:2,:)' ...
                app.estimates_storage{3}(5,:)'+app.estimates_storage{2}(1,:)' ...
                app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(6,:)' ...
                app.estimates_storage{3}(1,:)'+app.estimates_storage{2}(1,:)' ...
                app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(4,:)' ...
                app.estimates_storage{3}(8,:)'+app.estimates_storage{2}(2,:)' ...
                app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(9,:)' ...
                app.estimates_storage{3}(2,:)'+app.estimates_storage{2}(2,:)' ...
                app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(7,:)'], 2), ...
                'VariableNames',{'Years','Ex. Condition','Ex. Speed', ...
                'Ex.+1Std. Condition','Ex.-1Std. Condition','Ex.+2Std. Condition', ...
                'Ex.-2Std. Condition','Ex.+1Std. Speed','Ex.-1Std. Speed','Ex.+2Std. Speed', ...
                'Ex.-2Std. Speed'});    

            try
                FOLDER_NAME = sprintf('Structure/%s',app.StructureListBox.Value);

                [status, msg, msgID] = mkdir(sprintf('Reports/%s',FOLDER_NAME));
            catch
                uialert(app.MainWindow,msg,'Warning','Icon','warning');
            end
            
            try
                matlab2tikz('figurehandle',fig_h,'filename',['Reports/' FOLDER_NAME '/' figurename '.tex'] ,'standalone', true,'showInfo', false,'floatFormat','%.5g');
                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME, figurename))
            catch
                exportgraphics(fig_h,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME, figurename))
                if app.warning_msg
                    message = 'In order to export .tex files, please make sure to install and add "matlab2tikz" library to the matlab path';
                    uialert(app.MainWindow,message,'Warning','Icon','warning');
                    app.warning_msg = 0;
                end
            end
        end

        % Button pushed function: ExtractSaveButton_net
        function ExtractSaveButton_netPushed(app, event)
            fig_h = figure;
            copyobj(app.NetCond, fig_h);
            copyobj(app.NetSpeed, fig_h);
            set(fig_h, 'Resize', 'on');
            figurename=sprintf('network_%s',app.StructuralElementsGroupNet.Value);
            %addpath(sprintf('%s/Tools/matlab2tikz-master/',pwd));
            results = array2table(round([app.estimates_storage{1}' app.estimates_storage{2}(1:2,:)' ...
                app.estimates_storage{3}(5,:)'+app.estimates_storage{2}(1,:)' ...
                app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(6,:)' ...
                app.estimates_storage{3}(1,:)'+app.estimates_storage{2}(1,:)' ...
                app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(4,:)' ...
                app.estimates_storage{3}(8,:)'+app.estimates_storage{2}(2,:)' ...
                app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(9,:)' ...
                app.estimates_storage{3}(2,:)'+app.estimates_storage{2}(2,:)' ...
                app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(7,:)'], 2), ...
                'VariableNames',{'Years','Ex. Condition','Ex. Speed', ...
                'Ex.+1Std. Condition','Ex.-1Std. Condition','Ex.+2Std. Condition', ...
                'Ex.-2Std. Condition','Ex.+1Std. Speed','Ex.-1Std. Speed','Ex.+2Std. Speed', ...
                'Ex.-2Std. Speed'});     
            try
                if strcmp(app.FilterStructures.Value,'All')
                    FOLDER_NAME = 'Network';
                else
                    FOLDER_NAME = ['Network/' app.StructValue.Value];
                end

                [status, msg, msgID] = mkdir(sprintf('Reports/%s',FOLDER_NAME));
            catch
                uialert(app.MainWindow,msg,'Warning','Icon','warning');
            end

            try
                matlab2tikz('figurehandle',fig_h,'filename',['Reports/' FOLDER_NAME '/' figurename '.tex'] ,'standalone', true,'showInfo', false,'floatFormat','%.5g');
                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME, figurename))
            catch
                exportgraphics(fig_h,sprintf('Reports/%s/%s.png',FOLDER_NAME, figurename));
                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME, figurename))
                if app.warning_msg
                    message = 'In order to export .tex files, please make sure to install and add "matlab2tikz" library to the matlab path';
                    uialert(app.MainWindow,message,'Warning','Icon','warning');
                    app.warning_msg = 0;
                end
            end
        end

        % Menu selected function: InspectorsDataMenu
        function InspectorsDataMenuSelected(app, event)
            try
                addpath(sprintf('%s/Tools/InspectorsToolbox/',pwd))
                run('InspectorAnalysis_App.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: VaidateRealDataMenu
        function VaidateRealDataMenuSelected(app, event)
            try
                rmpath(sprintf('%s/Tools/ModelTraining_Generic/',pwd))
                addpath(sprintf('%s/Tools/PredictRealandSynthetic/',pwd))
                run('RealDataModelParameters_App.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: ModelTrainingInterventionMenu
        function ModelTrainingInterventionMenuSelected(app, event)
            try
                rmpath(sprintf('%s/Tools/ModelTraining_Generic/',pwd))
                rmpath(sprintf('%s/Tools/PredictRealandSynthetic/',pwd))
                addpath(sprintf('%s/Tools/ModelTraining_Int/',pwd))
                addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
                run('Interventions_App.mlapp');
            catch
                message = 'Loading toolbox has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: SoftwareManualMenu
        function SoftwareManualMenuSelected(app, event)
            try
                open('Help/DOC_ OpenIPDM_v1.pdf');
            catch
                message = 'Loading file has failed due to unexpected error, please make sure that the toolbox folder name and files are accessible';
                uialert(app.MainWindow,message,'Error','Icon','error');
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            selection = uiconfirm(app.MainWindow,'Close OpenIPDM?','Confirm Exit',...
                'Icon','warning');
            if strcmp(selection,'OK')
                close(app.MainWindow);
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            selection = uiconfirm(app.MainWindow,'Reset OpenIPDM main window?','Confirm reset',...
                'Icon','warning');
            if strcmp(selection,'OK')
                app.MainWindow.Visible = 'off';
                OpenIPDM();
                close(app.MainWindow);
            end
        end

        % Menu selected function: UnitTestMainUIMenu
        function UnitTestMainUIMenuSelected(app, event)
            results = runtests('Scripts/DeteriorationAnalyses/test_da_elem.m');
            display(results)
        end

        % Button pushed function: IntDatabaseButton
        function IntDatabaseButtonPushed(app, event)
            Elms = app.ElementListBox.Items;
            CatAnalyses=1;StrucAnalyses=0;NetAnalyses=0;CatReport=0;
            AutoCorrectVal=app.AutocorrectElem.Value;
            app.IntServiceLifeButton.Enable=0;
            
            if strcmp(app.AutocorrectCat.Value,'On')
                app.AutocorrectElem.Value='On';
            else
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                Strucs=app.StructureListBox.Items;
                run('int_network.m');
            end
            app.AutocorrectElem.Value=AutoCorrectVal;
        end

        % Menu selected function: StatusMenu
        function StatusMenuSelected(app, event)
            addpath(sprintf('%s/Tools/',pwd))
            status_menu()
        end

        % Menu selected function: RawDatabaseMenu
        function RawDatabaseMenuSelected(app, event)
            winopen(sprintf('%s/csv Data/',pwd));
        end

        % Menu selected function: ReportsMenu
        function ReportsMenuSelected(app, event)
            winopen(sprintf('%s/Reports/',pwd));
        end

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected(app, event)
            winopen(sprintf('%s/Parameters/',pwd));
        end

        % Menu selected function: FiguresMenu
        function FiguresMenuSelected(app, event)
            winopen(sprintf('%s/Figures/',pwd));
        end

        % Menu selected function: EffectofInterventionsTableYearsMenu
        function EffectofInterventionsTableYearsMenuSelected(app, event)
            Elms = app.ElementListBox.Items;
            CatAnalyses=1;StrucAnalyses=0;NetAnalyses=0;CatReport=0;
            AutoCorrectVal=app.AutocorrectElem.Value;
            app.IntServiceLifeButton.Enable=0;
            
            store_autocorrect_state_elem = app.AutocorrectElem.Value;
            store_autocorrect_state_cat = app.AutocorrectCat.Value;
            
            if strcmp(app.AutocorrectCat.Value,'On')
                app.AutocorrectCat.Value='Off';
                app.AutocorrectElem.Value='Off';
            end
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd))
            addpath(sprintf('%s/Tools/ModelTraining/OpenTAGI/src/matlab',pwd))
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                msg = "Analyses will be performed according to the type of intervention specified in the category menu.";
                title = "Confirm Run";
                selection = uiconfirm(app.MainWindow,msg,title, ...
                    "Options",["Start!","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                if selection == "Start!"
                    try
                        FOLDER_NAME = 'Interventions';
                    
                        [status, msg, msgID] = mkdir(sprintf('Reports/%s',FOLDER_NAME));
                    catch
                        uialert(app.MainWindow, msg ,'Warning','Icon','warning');
                    end
    
                    Strucs = app.StructureListBox.Items;
    
                    run('sl_network_table.m');
                end
            end
            app.AutocorrectElem.Value = store_autocorrect_state_elem;
            app.AutocorrectCat.Value = store_autocorrect_state_cat ;
        end

        % Menu selected function: MunicipalityLevelAnalysesMenu
        function MunicipalityLevelAnalysesMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(4);
            app.FilterStructures.Value = app.FilterStructures.Items{3};
            FilterStructuresValueChanged(app, event)
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            else
                try
                    FOLDER_NAME = 'Municipalities';
                
                    [status, msg, msgID] = mkdir(sprintf('Reports/%s',FOLDER_NAME));
                catch
                    uialert(app.MainWindow, msg ,'Warning','Icon','warning');
                end
                d=uiprogressdlg(app.MainWindow,'Title','Please Wait',...
                        'Message','Preparing for municipality level analyses...','Cancelable','on'...
                        ,'CancelText','Stop');
                d.Value = 0; 
                muncipalities = app.StructValue.Items;
                store_muncipality_ex = nan([3, length(muncipalities)]);
                store_muncipality_2std = nan([4, length(muncipalities)]);
                msg = "Analyses will be performed according to the number of years and type of elements specified in the network menu.";
                title = "Confirm Run";
                selection = uiconfirm(app.MainWindow,msg,title, ...
                    "Options",["Start!","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                if selection == "Start!"
                try 
                    for municipality = 1: length(muncipalities)
                        pause(0.001);
                        if d.CancelRequested
                            break
                        end    
                        d.Value = (municipality/length(muncipalities))/1.2;
                        d.Message=sprintf('Processing data from municipality %s ', ...
                            app.StructValue.Value);
                        
                        app.StructValue.Value = app.StructValue.Items{municipality};
                        StructValueValueChanged(app, event)
                        StructureListBoxValueChanged(app, event)
                        struct_item = 2;
                        while isempty(app.CategoryListBox.Items) && length(app.StructureListBox.Items) > 1
                            app.StructureListBox.Value = app.StructureListBox.Items{struct_item};
                            StructureListBoxValueChanged(app, event)
                            struct_item = struct_item + 1;
                        end
                        if ~isempty(app.CategoryListBox.Items)
                            app.CategoryListBox.Value = app.CategoryListBox.Items{1};
                        
                            CategoryListBoxValueChanged(app, event)
                            AnalyzeNetButtonPushed(app, event)
    
                            if ~isempty(app.estimates_storage{1})
                                fig_h = figure('visible','off');
                                copyobj(app.NetCond, fig_h);
                                copyobj(app.NetSpeed, fig_h);
                                set(fig_h, 'Resize', 'on');
                                figurename = [app.StructValue.Value '-' app.StructuralElementsGroupNet.Value];
                                exportgraphics(fig_h,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                        
                                structures = cell2table(app.StructureListBox.Items);
                                % Write the table to a CSV file
                                writetable(structures,sprintf('Reports/%s/%s-structures.csv',FOLDER_NAME,figurename))
                                
                                store_muncipality_ex(1:2, municipality) = app.estimates_storage{2}(1:2,end);
                                store_muncipality_ex(3, municipality) = length(app.StructureListBox.Items);
                                store_muncipality_2std(1, municipality) = app.estimates_storage{3}(4,end)';
                                store_muncipality_2std(2, municipality) = app.estimates_storage{3}(1,end)';
                                store_muncipality_2std(3, municipality) = app.estimates_storage{3}(2,end)';
                                store_muncipality_2std(4, municipality) = app.estimates_storage{3}(7,end)';
    
                                results = array2table(round([app.estimates_storage{1}' app.estimates_storage{2}(1:2,:)' ...
                                    app.estimates_storage{3}(5,:)'+app.estimates_storage{2}(1,:)' ...
                                    app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(6,:)' ...
                                    app.estimates_storage{3}(1,:)'+app.estimates_storage{2}(1,:)' ...
                                    app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(4,:)' ...
                                    app.estimates_storage{3}(8,:)'+app.estimates_storage{2}(2,:)' ...
                                    app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(9,:)' ...
                                    app.estimates_storage{3}(2,:)'+app.estimates_storage{2}(2,:)' ...
                                    app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(7,:)'], 2), ...
                                    'VariableNames',{'Years','Ex. Condition','Ex. Speed', ...
                                    'Ex.+1Std. Condition','Ex.-1Std. Condition','Ex.+2Std. Condition', ...
                                    'Ex.-2Std. Condition','Ex.+1Std. Speed','Ex.-1Std. Speed','Ex.+2Std. Speed', ...
                                    'Ex.-2Std. Speed'});   
                                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME,figurename))
                            end
                        end
                    end
                    d=uiprogressdlg(app.MainWindow,'Title','Please Wait',...
                    'Message','Finalizing municipality level analyses...');
                    muncipality_list = array2table(store_muncipality_ex,'RowNames', muncipalities, ...
                        'VariableNames',{'Ex. Condition','Ex. Speed', 'Num. Bridges'});
                    writetable(muncipality_list,sprintf('Reports/%s/%s.csv',FOLDER_NAME, ...
                               [app.StructuralElementsGroupNet.Value '_municipality_list']), ...
                               'WriteRowNames',true);

                    [sort_cond, ind_sort_1] = sort(store_muncipality_ex(1, :));
                    [sort_speed_cond, ind_sort_2] = sort(store_muncipality_ex(2, :));

                    subset_size = 18;
                    for fig_ = 1 : ceil(numel(muncipalities)/subset_size)
                        s_ = subset_size*(fig_ - 1) +1;
                        sub_size = length(sort_cond(s_:end)); 
                        if sub_size < subset_size
                            subset_size = sub_size;
                            e_ = length(muncipalities) ;
                        else
                            e_ = subset_size * fig_;
                        end

                        fig_h2 = figure('Visible','off');
                        scatter(1:subset_size, sort_cond(s_:e_), 'filled');
                        hold on;
                        errorbar(1:subset_size, sort_cond(s_:e_), store_muncipality_2std(1,ind_sort_1(s_:e_)), ...
                        store_muncipality_2std(2,ind_sort_1(s_:e_)), 'LineStyle','none','CapSize',10,'Color','blue','Linewidth',1);
                        xtick_label = strcat(muncipalities(ind_sort_1(s_:e_)), {'-'}, string(store_muncipality_ex(3, ind_sort_1(s_:e_))));
                        set(gca, 'XTick', 1:subset_size, 'XTickLabel', xtick_label );
                        xlabel('Muncipality');
                        ylabel('Condition');
                        grid on;
                        figurename = sprintf('Report_Muncipality_Cond_Part_%d',fig_);
                        exportgraphics(fig_h2,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                        
                        fig_h3 = figure('Visible','off');
                        sort_cond_speed = store_muncipality_ex(2, ind_sort_1(s_:e_));
                        scatter(1:subset_size, sort_cond_speed, 'filled');
                        hold on;
                        errorbar(1:subset_size, sort_cond_speed, store_muncipality_2std(4,ind_sort_1(s_:e_)), ...
                        store_muncipality_2std(3,ind_sort_1(s_:e_)), 'LineStyle','none','CapSize',10,'Color','blue','Linewidth',1);
                         
                        set(gca, 'XTick', 1:subset_size, 'XTickLabel', xtick_label );
                        xlabel('Muncipality');
                        ylabel('Speed');
                        ylim([-3, 0]);
                        grid on;
                        figurename = sprintf('Report_Muncipality_Speed_Part_%d',fig_);
                        exportgraphics(fig_h3,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));

                        fig_h4 = figure('Visible','off');
                        scatter(1:subset_size, sort_speed_cond(s_:e_), 'filled');
                        hold on;
                        errorbar(1:subset_size, sort_speed_cond(s_:e_), store_muncipality_2std(4,ind_sort_2(s_:e_)), ...
                        store_muncipality_2std(3,ind_sort_2(s_:e_)), 'LineStyle','none','CapSize',10,'Color','blue','Linewidth',1);
                        xtick_label_2 = strcat(muncipalities(ind_sort_2(s_:e_)), {'-'}, string(store_muncipality_ex(3, ind_sort_2(s_:e_))));
                      
                        set(gca, 'XTick', 1:subset_size, 'XTickLabel', xtick_label_2);
                        xlabel('Muncipality');
                        ylabel('Speed');
                        ylim([-3, 0]);
                        grid on;
                        figurename = sprintf('Report_Muncipality_Speed_sort_by_speed_Part_%d',fig_);
                        exportgraphics(fig_h4,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
    
                        fig_h5 = figure('Visible','off');
                        sort_cond_ = store_muncipality_ex(1, ind_sort_2(s_:e_));
                        scatter(1:subset_size, sort_cond_, 'filled');
                        hold on;
                        errorbar(1:subset_size, sort_cond_, store_muncipality_2std(1,ind_sort_2(s_:e_)), ...
                        store_muncipality_2std(2,ind_sort_2(s_:e_)), 'LineStyle','none','CapSize',10,'Color','blue','Linewidth',1);
                        set(gca, 'XTick', 1:subset_size, 'XTickLabel', xtick_label_2);
                        xlabel('Muncipality');
                        ylabel('Condition');
                        grid on;
                        figurename = sprintf('Report_Muncipality_Cond_sort_by_speed_Part_%d',fig_);
                        exportgraphics(fig_h5,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                    end
                catch
                    message = 'Analyses have failed, likely due to an error in processing one of the structural element';
                    uialert(app.MainWindow,message,'Error','Icon','error');
                end
                
                d.Value=0.99;
                pause(0.001);
                close(d);
                end
            end
            
        end

        % Menu selected function: StructureLevelAnalysesMenu
        function StructureLevelAnalysesMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(3);
            if isempty(app.ModelParam)
                message = 'The model parameters file is not loaded in OpenIPDM';
                uialert(app.MainWindow,message,'Error','Icon','error');
            elseif strcmp(app.FilterStructures.Value, 'All')
                message = 'Please use one of the filters to select a subset of structures before the analyses. Performing analyses on the entire list of structures is currently not supported.';
                uialert(app.MainWindow,message,'Warning','Icon','warning');
            else
                try
                    FOLDER_NAME = ['Structures' '-' app.StructValue.Value];
                
                    [status, msg, msgID] = mkdir(sprintf('Reports/%s',FOLDER_NAME));
                catch
                    uialert(app.MainWindow, msg ,'Warning','Icon','warning');
                end
                d=uiprogressdlg(app.MainWindow,'Title','Please Wait',...
                        'Message','Preparing for structure level analyses...','Cancelable','on'...
                        ,'CancelText','Stop');
                d.Value = 0; 
                structures = app.StructureListBox.Items;
                store_structures_ex = nan([2, length(structures)]);
                store_structures_2std = nan([4, length(structures)]);
                msg = "Analyses will be performed according to the number of years and type of elements specified in the structure menu.";
                title = "Confirm Run";
                selection = uiconfirm(app.MainWindow,msg,title, ...
                    "Options",["Start!","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                if selection == "Start!"
                try 
                    for structure = 1: length(structures)
                        pause(0.001);
                        if d.CancelRequested
                            break
                        end    
                        d.Value = (structure/length(structures))/1.2;
                        d.Message=sprintf('Processing data from structure %s ', ...
                            app.StructureListBox.Value);
                        
                        app.StructureListBox.Value = app.StructureListBox.Items{structure};
                        StructureListBoxValueChanged(app, event)

                        if ~isempty(app.CategoryListBox.Items)
                            app.CategoryListBox.Value = app.CategoryListBox.Items{1};
                        
                            CategoryListBoxValueChanged(app, event)
                            AnalyzeStrucButtonPushed(app, event)
    
                            if ~isempty(app.estimates_storage{1})
                                fig_h = figure('visible','off');
                                copyobj(app.StrucCond, fig_h);
                                copyobj(app.StrucSpeed, fig_h);
                                set(fig_h, 'Resize', 'on');
                                figurename = [app.StructureListBox.Items{structure} '-' app.StructuralElementsGroupDropDown.Value];
                                exportgraphics(fig_h,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                                
                                store_structure_ex(1:2, structure) = app.estimates_storage{2}(1:2,end);
                                store_structure_2std(1, structure) = app.estimates_storage{3}(4,end)';
                                store_structure_2std(2, structure) = app.estimates_storage{3}(1,end)';
                                store_structure_2std(3, structure) = app.estimates_storage{3}(2,end)';
                                store_structure_2std(4, structure) = app.estimates_storage{3}(7,end)';
    
                                results = array2table(round([app.estimates_storage{1}' app.estimates_storage{2}(1:2,:)' ...
                                    app.estimates_storage{3}(5,:)'+app.estimates_storage{2}(1,:)' ...
                                    app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(6,:)' ...
                                    app.estimates_storage{3}(1,:)'+app.estimates_storage{2}(1,:)' ...
                                    app.estimates_storage{2}(1,:)'-app.estimates_storage{3}(4,:)' ...
                                    app.estimates_storage{3}(8,:)'+app.estimates_storage{2}(2,:)' ...
                                    app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(9,:)' ...
                                    app.estimates_storage{3}(2,:)'+app.estimates_storage{2}(2,:)' ...
                                    app.estimates_storage{2}(2,:)'-app.estimates_storage{3}(7,:)'], 2), ...
                                    'VariableNames',{'Years','Ex. Condition','Ex. Speed', ...
                                    'Ex.+1Std. Condition','Ex.-1Std. Condition','Ex.+2Std. Condition', ...
                                    'Ex.-2Std. Condition','Ex.+1Std. Speed','Ex.-1Std. Speed','Ex.+2Std. Speed', ...
                                    'Ex.-2Std. Speed'});   
                                writetable(results,sprintf('Reports/%s/%s.csv',FOLDER_NAME,figurename))
                            end
                        end
                    end
                    d=uiprogressdlg(app.MainWindow,'Title','Please Wait',...
                    'Message','Finalizing structure level analyses...');
                    structure_list = array2table(store_structure_ex','RowNames', structures, ...
                        'VariableNames', {'Ex. Condition','Ex. Speed'});
                    writetable(structure_list,sprintf('Reports/%s/%s.csv',FOLDER_NAME, ...
                               [app.StructuralElementsGroupDropDown.Value '_structures_list']) ...
                               ,'WriteRowNames',true);

                    [sort_cond, ind_sort_1] = sort(store_structure_ex(1, :));
                    [sort_speed_cond, ind_sort_2] = sort(store_structure_ex(2, :));

                    subset_size = 18;
                    for fig_ = 1 : ceil(numel(structures)/subset_size)
                        s_ = subset_size*(fig_ - 1) +1;
                        sub_size = length(sort_cond(s_:end)); 
                        if sub_size < subset_size
                            subset_size = sub_size;
                            e_ = length(structures) ;
                        else
                            e_ = subset_size * fig_;
                        end

                        fig_h2 = figure('Visible','off');
                        scatter(1:subset_size, sort_cond(s_:e_), 'filled');
                        hold on;
                        errorbar(1:subset_size, sort_cond(s_:e_), store_structure_2std(1,ind_sort_1(s_:e_)), ...
                        store_structure_2std(2,ind_sort_1(s_:e_)), 'LineStyle','none','CapSize',10,'Color','blue','Linewidth',1);
                        xtick_label = structures(ind_sort_1(s_:e_));
                        set(gca, 'XTick', 1:subset_size, 'XTickLabel', xtick_label );
                        xlabel('Structure');
                        ylabel('Condition');
                        grid on;
                        figurename = sprintf('Report_Structure_Cond_Part_%d',fig_);
                        exportgraphics(fig_h2,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                        
                        fig_h3 = figure('Visible','off');
                        sort_cond_speed = store_structure_ex(2, ind_sort_1(s_:e_));
                        scatter(1:subset_size, sort_cond_speed, 'filled');
                        hold on;
                        errorbar(1:subset_size, sort_cond_speed, store_structure_2std(4,ind_sort_1(s_:e_)), ...
                        store_structure_2std(3,ind_sort_1(s_:e_)), 'LineStyle','none','CapSize',10,'Color','blue','Linewidth',1);
                         
                        set(gca, 'XTick', 1:subset_size, 'XTickLabel', xtick_label );
                        xlabel('Structure');
                        ylabel('Speed');
                        ylim([-3, 0]);
                        grid on;
                        figurename = sprintf('Report_Structure_Speed_Part_%d',fig_);
                        exportgraphics(fig_h3,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));

                        fig_h4 = figure('Visible','off');
                        scatter(1:subset_size, sort_speed_cond(s_:e_), 'filled');
                        hold on;
                        errorbar(1:subset_size, sort_speed_cond(s_:e_), store_structure_2std(4,ind_sort_2(s_:e_)), ...
                        store_structure_2std(3,ind_sort_2(s_:e_)), 'LineStyle','none','CapSize',10,'Color','blue','Linewidth',1);
                        xtick_label_2 = structures(ind_sort_2(s_:e_));
                      
                        set(gca, 'XTick', 1:subset_size, 'XTickLabel', xtick_label_2);
                        xlabel('Structure');
                        ylabel('Speed');
                        ylim([-3, 0]);
                        grid on;
                        figurename = sprintf('Report_Structure_Speed_sort_by_speed_Part_%d',fig_);
                        exportgraphics(fig_h4,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
    
                        fig_h5 = figure('Visible','off');
                        sort_cond_ = store_structure_ex(1, ind_sort_2(s_:e_));
                        scatter(1:subset_size, sort_cond_, 'filled');
                        hold on;
                        errorbar(1:subset_size, sort_cond_, store_structure_2std(1,ind_sort_2(s_:e_)), ...
                        store_structure_2std(2,ind_sort_2(s_:e_)), 'LineStyle','none','CapSize',10,'Color','blue','Linewidth',1);
                        set(gca, 'XTick', 1:subset_size, 'XTickLabel', xtick_label_2);
                        xlabel('Structure');
                        ylabel('Condition');
                        grid on;
                        figurename = sprintf('Report_Structure_Cond_sort_by_speed_Part_%d',fig_);
                        exportgraphics(fig_h5,sprintf('Reports/%s/%s.png',FOLDER_NAME,figurename));
                    end
                catch
                    message = 'Analyses have failed, likely due to an error in processing one of the structural element';
                    uialert(app.MainWindow,message,'Error','Icon','error');
                end
                
                d.Value=0.99;
                pause(0.001);
                close(d);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'OpenIPDM App (v. 0.98.8 beta)';
            app.MainWindow.Icon = 'OpenIPDM_icon_r.png';
            app.FileMenu = uimenu(app.MainWindow);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenFolderMenu = uimenu(app.FileMenu);
            app.OpenFolderMenu.Text = 'Open Folder ...';
            app.ReportsMenu = uimenu(app.OpenFolderMenu);
            app.ReportsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportsMenuSelected, true);
            app.ReportsMenu.Text = 'Reports';
            app.RawDatabaseMenu = uimenu(app.OpenFolderMenu);
            app.RawDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDatabaseMenuSelected, true);
            app.RawDatabaseMenu.Text = 'Raw Database';
            app.ParametersMenu = uimenu(app.OpenFolderMenu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected, true);
            app.ParametersMenu.Text = 'Parameters';
            app.FiguresMenu = uimenu(app.OpenFolderMenu);
            app.FiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @FiguresMenuSelected, true);
            app.FiguresMenu.Text = 'Figures';
            app.LoadDataMenu = uimenu(app.FileMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.LoadParametersMenu = uimenu(app.FileMenu);
            app.LoadParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadParametersMenuSelected, true);
            app.LoadParametersMenu.Text = 'Load Parameters';
            app.PreprocessDataMenu = uimenu(app.FileMenu);
            app.PreprocessDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PreprocessDataMenuSelected, true);
            app.PreprocessDataMenu.Text = 'Pre-process Data';
            app.LoadResultsNetworkMenu = uimenu(app.FileMenu);
            app.LoadResultsNetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadResultsNetworkMenuSelected, true);
            app.LoadResultsNetworkMenu.Text = 'Load Results - Network ';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.MainWindow);
            app.ToolsMenu.Text = 'Tools';
            app.ReadRealDataMenu = uimenu(app.ToolsMenu);
            app.ReadRealDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadRealDataMenuSelected, true);
            app.ReadRealDataMenu.Visible = 'off';
            app.ReadRealDataMenu.Text = 'Read Real Data';
            app.ModelTrainingNetwokMenu = uimenu(app.ToolsMenu);
            app.ModelTrainingNetwokMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelTrainingNetwokMenuSelected, true);
            app.ModelTrainingNetwokMenu.Text = 'Model Training - Netwok';
            app.ModelTrainingGenericMenu = uimenu(app.ToolsMenu);
            app.ModelTrainingGenericMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelTrainingGenericMenuSelected, true);
            app.ModelTrainingGenericMenu.Visible = 'off';
            app.ModelTrainingGenericMenu.Enable = 'off';
            app.ModelTrainingGenericMenu.Text = 'Model Training - Generic';
            app.ModelTrainingInterventionMenu = uimenu(app.ToolsMenu);
            app.ModelTrainingInterventionMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelTrainingInterventionMenuSelected, true);
            app.ModelTrainingInterventionMenu.Text = 'Model Training - Intervention';
            app.GenerateSyntheticDataMenu = uimenu(app.ToolsMenu);
            app.GenerateSyntheticDataMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateSyntheticDataMenuSelected, true);
            app.GenerateSyntheticDataMenu.Text = 'Generate Synthetic Data';
            app.InspectorsDataMenu = uimenu(app.ToolsMenu);
            app.InspectorsDataMenu.MenuSelectedFcn = createCallbackFcn(app, @InspectorsDataMenuSelected, true);
            app.InspectorsDataMenu.Text = 'Inspectors Data';
            app.AnalysesMenu = uimenu(app.MainWindow);
            app.AnalysesMenu.Text = 'Analyses';
            app.HideInspectorIDMenu = uimenu(app.AnalysesMenu);
            app.HideInspectorIDMenu.MenuSelectedFcn = createCallbackFcn(app, @HideInspectorIDMenuSelected, true);
            app.HideInspectorIDMenu.Visible = 'off';
            app.HideInspectorIDMenu.Separator = 'on';
            app.HideInspectorIDMenu.Checked = 'on';
            app.HideInspectorIDMenu.Text = 'Hide Inspector ID';
            app.GenerateReportsMenu = uimenu(app.AnalysesMenu);
            app.GenerateReportsMenu.Text = 'Generate Reports';
            app.EffectofInterventionsTableYearsMenu = uimenu(app.GenerateReportsMenu);
            app.EffectofInterventionsTableYearsMenu.MenuSelectedFcn = createCallbackFcn(app, @EffectofInterventionsTableYearsMenuSelected, true);
            app.EffectofInterventionsTableYearsMenu.Text = 'Effect of Interventions Table (Years)';
            app.MunicipalityLevelAnalysesMenu = uimenu(app.GenerateReportsMenu);
            app.MunicipalityLevelAnalysesMenu.MenuSelectedFcn = createCallbackFcn(app, @MunicipalityLevelAnalysesMenuSelected, true);
            app.MunicipalityLevelAnalysesMenu.Text = 'Municipality-Level Analyses';
            app.StructureLevelAnalysesMenu = uimenu(app.GenerateReportsMenu);
            app.StructureLevelAnalysesMenu.MenuSelectedFcn = createCallbackFcn(app, @StructureLevelAnalysesMenuSelected, true);
            app.StructureLevelAnalysesMenu.Text = 'Structure-Level Analyses';
            app.VaidateRealDataMenu = uimenu(app.AnalysesMenu);
            app.VaidateRealDataMenu.MenuSelectedFcn = createCallbackFcn(app, @VaidateRealDataMenuSelected, true);
            app.VaidateRealDataMenu.Text = 'Vaidate Real Data';
            app.VerifySyntheticDataMenu = uimenu(app.AnalysesMenu);
            app.VerifySyntheticDataMenu.MenuSelectedFcn = createCallbackFcn(app, @VerifySyntheticDataMenuSelected, true);
            app.VerifySyntheticDataMenu.Text = 'Verify Synthetic Data';
            app.SingleTimeSeriesMenu = uimenu(app.AnalysesMenu);
            app.SingleTimeSeriesMenu.MenuSelectedFcn = createCallbackFcn(app, @SingleTimeSeriesMenuSelected, true);
            app.SingleTimeSeriesMenu.Text = 'Single Time Series';
            app.HelpMenu = uimenu(app.MainWindow);
            app.HelpMenu.Text = 'Help';
            app.SoftwareManualMenu = uimenu(app.HelpMenu);
            app.SoftwareManualMenu.MenuSelectedFcn = createCallbackFcn(app, @SoftwareManualMenuSelected, true);
            app.SoftwareManualMenu.Text = 'Software Manual';
            app.DeveloperMenu = uimenu(app.HelpMenu);
            app.DeveloperMenu.Visible = 'off';
            app.DeveloperMenu.Enable = 'off';
            app.DeveloperMenu.Text = 'Developer';
            app.UnitTestMainUIMenu = uimenu(app.DeveloperMenu);
            app.UnitTestMainUIMenu.MenuSelectedFcn = createCallbackFcn(app, @UnitTestMainUIMenuSelected, true);
            app.UnitTestMainUIMenu.Text = 'Unit Test Main UI';
            app.StatusMenu = uimenu(app.HelpMenu);
            app.StatusMenu.MenuSelectedFcn = createCallbackFcn(app, @StatusMenuSelected, true);
            app.StatusMenu.Text = 'Status';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.StructureListBoxLabel = uilabel(app.MainWindow);
            app.StructureListBoxLabel.Text = 'Structure';
            app.StructureListBox = uilistbox(app.MainWindow);
            app.StructureListBox.Items = {'No Data'};
            app.StructureListBox.ValueChangedFcn = createCallbackFcn(app, @StructureListBoxValueChanged, true);
            app.StructureListBox.Value = 'No Data';
            app.CategoryListBoxLabel = uilabel(app.MainWindow);
            app.CategoryListBoxLabel.Text = 'Category';
            app.CategoryListBox = uilistbox(app.MainWindow);
            app.CategoryListBox.Items = {'No Data'};
            app.CategoryListBox.ValueChangedFcn = createCallbackFcn(app, @CategoryListBoxValueChanged, true);
            app.CategoryListBox.Value = 'No Data';
            app.ElementListBoxLabel = uilabel(app.MainWindow);
            app.ElementListBoxLabel.Text = 'Element';
            app.ElementListBox = uilistbox(app.MainWindow);
            app.ElementListBox.Items = {'No Data'};
            app.ElementListBox.ValueChangedFcn = createCallbackFcn(app, @ElementListBoxValueChanged, true);
            app.ElementListBox.Value = 'No Data';
            app.TabGroup = uitabgroup(app.MainWindow);
            app.ElementTab = uitab(app.TabGroup);
            app.ElementTab.Title = 'Element';
            app.ElmCondition = uiaxes(app.ElementTab);
            xlabel(app.ElmCondition, 'Time (Year)')
            ylabel(app.ElmCondition, 'Condition')
            app.ElmSpeed = uiaxes(app.ElementTab);
            xlabel(app.ElmSpeed, 'Time (Year)')
            ylabel(app.ElmSpeed, 'Speed')
            app.ForecastYearsSliderLabel = uilabel(app.ElementTab);
            app.ForecastYearsSliderLabel.Text = 'Forecast (Years)';
            app.ForecastYearsSlider = uislider(app.ElementTab);
            app.ForecastYearsSlider.Limits = [0 20];
            app.ForecastYearsSlider.ValueChangedFcn = createCallbackFcn(app, @ForecastYearsSliderValueChanged, true);
            app.ForecastYearsSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ForecastYearsSlider.Value = 1;
            app.OutliersMissingIntAutocorrectLabel = uilabel(app.ElementTab);
            app.OutliersMissingIntAutocorrectLabel.Text = {'Outliers/Missing '; 'Int. Autocorrect'};
            app.AutocorrectElem = uiswitch(app.ElementTab, 'slider');
            app.AutocorrectElem.ValueChangedFcn = createCallbackFcn(app, @AutocorrectElemValueChanged, true);
            app.AutocorrectElem.Value = 'On';
            app.IntServiceLifeButton = uibutton(app.ElementTab, 'push');
            app.IntServiceLifeButton.ButtonPushedFcn = createCallbackFcn(app, @IntServiceLifeButtonPushed, true);
            app.IntServiceLifeButton.Enable = 'off';
            app.IntServiceLifeButton.Text = 'Int. Service Life';
            app.ExportButton = uibutton(app.ElementTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CategoryTab = uitab(app.TabGroup);
            app.CategoryTab.Title = 'Category';
            app.GridLayout = uigridlayout(app.CategoryTab);
            app.CatSpeed = uiaxes(app.GridLayout);
            xlabel(app.CatSpeed, 'Time (Year)')
            ylabel(app.CatSpeed, 'Speed')
            app.CatCond = uiaxes(app.GridLayout);
            xlabel(app.CatCond, 'Time (Year)')
            ylabel(app.CatCond, 'Condition')
            app.ForecastYearsSlider_2 = uislider(app.GridLayout);
            app.ForecastYearsSlider_2.Limits = [0 20];
            app.ForecastYearsSlider_2.ValueChangedFcn = createCallbackFcn(app, @ForecastYearsSlider_2ValueChanged, true);
            app.ForecastYearsSlider_2.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ForecastYearsSlider_2.Value = 1;
            app.ForecastYearsSlider_2Label = uilabel(app.GridLayout);
            app.ForecastYearsSlider_2Label.Text = 'Forecast (Years)';
            app.AutocorrectCat = uiswitch(app.GridLayout, 'slider');
            app.AutocorrectCat.ValueChangedFcn = createCallbackFcn(app, @AutocorrectCatValueChanged, true);
            app.AutocorrectCat.Value = 'On';
            app.OutliersMissingIntAutocorrectLabel_2 = uilabel(app.GridLayout);
            app.OutliersMissingIntAutocorrectLabel_2.Text = {'Outliers/Missing '; 'Int. Autocorrect'};
            app.InterventionCDFButton = uibutton(app.GridLayout, 'push');
            app.InterventionCDFButton.ButtonPushedFcn = createCallbackFcn(app, @InterventionCDFButtonPushed, true);
            app.InterventionCDFButton.Text = 'Intervention CDF';
            app.IntCodeDropDown = uidropdown(app.GridLayout);
            app.IntCodeDropDown.Items = {'Intervention Code 1000', 'Intervention Code 2000', 'Intervention Code 3000'};
            app.IntCodeDropDown.Value = 'Intervention Code 3000';
            app.AnalysesLevelDropDown = uidropdown(app.GridLayout);
            app.AnalysesLevelDropDown.Items = {'Local', 'Network'};
            app.AnalysesLevelDropDown.Value = 'Local';
            app.YearSpinnerLabel = uilabel(app.GridLayout);
            app.YearSpinnerLabel.Text = 'Year';
            app.YearSpinner = uispinner(app.GridLayout);
            app.YearSpinner.Limits = [2010 2500];
            app.YearSpinner.Value = 2010;
            app.CondSpeedButton = uibutton(app.GridLayout, 'push');
            app.CondSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @CondSpeedButtonPushed, true);
            app.CondSpeedButton.Text = 'Cond. & Speed';
            app.NetworkScaleresultsreportLabel = uilabel(app.GridLayout);
            app.NetworkScaleresultsreportLabel.Text = {'Network-Scale '; 'results report'};
            app.ExtractSaveButton_Cat = uibutton(app.GridLayout, 'push');
            app.ExtractSaveButton_Cat.ButtonPushedFcn = createCallbackFcn(app, @ExtractSaveButton_CatPushed, true);
            app.ExtractSaveButton_Cat.Text = 'Export';
            app.StructureTab = uitab(app.TabGroup);
            app.StructureTab.Title = 'Structure';
            app.StrucCond = uiaxes(app.StructureTab);
            xlabel(app.StrucCond, 'Time (Year)')
            ylabel(app.StrucCond, 'Condition')
            app.StrucSpeed = uiaxes(app.StructureTab);
            xlabel(app.StrucSpeed, 'Time (Year)')
            ylabel(app.StrucSpeed, 'Speed')
            app.ForecastYearsSlider_3Label = uilabel(app.StructureTab);
            app.ForecastYearsSlider_3Label.Text = 'Forecast (Years)';
            app.ForecastYearsSliderStruc = uislider(app.StructureTab);
            app.ForecastYearsSliderStruc.Limits = [0 20];
            app.ForecastYearsSliderStruc.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ForecastYearsSliderStruc.Value = 1;
            app.StructuralElementsGroupDropDownLabel = uilabel(app.StructureTab);
            app.StructuralElementsGroupDropDownLabel.Text = 'Structural Elements Group';
            app.StructuralElementsGroupDropDown = uidropdown(app.StructureTab);
            app.StructuralElementsGroupDropDown.Items = {'Principal', 'Secondaire'};
            app.StructuralElementsGroupDropDown.Value = 'Principal';
            app.AnalyzeStruc = uibutton(app.StructureTab, 'push');
            app.AnalyzeStruc.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeStrucButtonPushed, true);
            app.AnalyzeStruc.Text = 'Analyze';
            app.OutliersMissingIntAutocorrectLabel_3 = uilabel(app.StructureTab);
            app.OutliersMissingIntAutocorrectLabel_3.Text = {'Outliers/Missing '; 'Int. Autocorrect'};
            app.AutocorrectStruc = uiswitch(app.StructureTab, 'slider');
            app.AutocorrectStruc.Value = 'On';
            app.ExtractSaveButton_Struc = uibutton(app.StructureTab, 'push');
            app.ExtractSaveButton_Struc.ButtonPushedFcn = createCallbackFcn(app, @ExtractSaveButton_StrucPushed, true);
            app.ExtractSaveButton_Struc.Text = 'Export';
            app.NetworkTab = uitab(app.TabGroup);
            app.NetworkTab.Title = 'Network';
            app.NetCond = uiaxes(app.NetworkTab);
            xlabel(app.NetCond, 'Time (Year)')
            ylabel(app.NetCond, 'Condition')
            app.NetSpeed = uiaxes(app.NetworkTab);
            xlabel(app.NetSpeed, 'Time (Year)')
            ylabel(app.NetSpeed, 'Speed')
            app.ForecastYearsSlider_4Label = uilabel(app.NetworkTab);
            app.ForecastYearsSlider_4Label.Text = 'Forecast (Years)';
            app.ForecastYearsSliderNet = uislider(app.NetworkTab);
            app.ForecastYearsSliderNet.Limits = [0 20];
            app.ForecastYearsSliderNet.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ForecastYearsSliderNet.Value = 1;
            app.StructuralElementsGroupDropDown_2Label = uilabel(app.NetworkTab);
            app.StructuralElementsGroupDropDown_2Label.Text = 'Structural Elements Group';
            app.StructuralElementsGroupNet = uidropdown(app.NetworkTab);
            app.StructuralElementsGroupNet.Items = {'Principal', 'Secondaire'};
            app.StructuralElementsGroupNet.Value = 'Principal';
            app.OutliersMissingIntAutocorrectSwitchLabel = uilabel(app.NetworkTab);
            app.OutliersMissingIntAutocorrectSwitchLabel.Text = {'Outliers/Missing '; 'Int. Autocorrect'};
            app.OutliersMissingIntAutocorrectSwitch = uiswitch(app.NetworkTab, 'slider');
            app.OutliersMissingIntAutocorrectSwitch.Value = 'On';
            app.AnalyzeNet = uibutton(app.NetworkTab, 'push');
            app.AnalyzeNet.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeNetButtonPushed, true);
            app.AnalyzeNet.Text = 'Analyze';
            app.StatusDropDownLabel = uilabel(app.NetworkTab);
            app.StatusDropDownLabel.Text = 'Status';
            app.StatusDropDownNetwork = uidropdown(app.NetworkTab);
            app.StatusDropDownNetwork.Items = {'Undefined'};
            app.StatusDropDownNetwork.Value = 'Undefined';
            app.SaveResultsButton = uibutton(app.NetworkTab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.LoadResultsButton = uibutton(app.NetworkTab, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsButtonPushed, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.ExtractSaveButton_net = uibutton(app.NetworkTab, 'push');
            app.ExtractSaveButton_net.ButtonPushedFcn = createCallbackFcn(app, @ExtractSaveButton_netPushed, true);
            app.ExtractSaveButton_net.Text = 'Export';
            app.IntDatabaseButton = uibutton(app.NetworkTab, 'push');
            app.IntDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @IntDatabaseButtonPushed, true);
            app.IntDatabaseButton.Text = 'Int. Database';
            app.FilterStructures = uidropdown(app.MainWindow);
            app.FilterStructures.Items = {'All'};
            app.FilterStructures.ValueChangedFcn = createCallbackFcn(app, @FilterStructuresValueChanged, true);
            app.FilterStructures.Value = 'All';
            app.StructValue = uidropdown(app.MainWindow);
            app.StructValue.Items = {'None'};
            app.StructValue.ValueChangedFcn = createCallbackFcn(app, @StructValueValueChanged, true);
            app.StructValue.Value = 'None';
            app.FilterCat = uidropdown(app.MainWindow);
            app.FilterCat.Items = {'All', 'Group'};
            app.FilterCat.ValueChangedFcn = createCallbackFcn(app, @FilterCatValueChanged, true);
            app.FilterCat.Value = 'All';
            app.SelectCat = uidropdown(app.MainWindow);
            app.SelectCat.Items = {'None'};
            app.SelectCat.ValueChangedFcn = createCallbackFcn(app, @SelectCatValueChanged, true);
            app.SelectCat.Value = 'None';
            app.FilterElm = uidropdown(app.MainWindow);
            app.FilterElm.Items = {'Visual Inspection', 'CEC'};
            app.FilterElm.ValueChangedFcn = createCallbackFcn(app, @FilterElmValueChanged, true);
            app.FilterElm.Value = 'Visual Inspection';
            app.MaterialLabel = uilabel(app.MainWindow);
            app.MaterialLabel.Text = 'Material:';
            app.MaterialElemLabel = uilabel(app.MainWindow);
            app.MaterialElemLabel.Text = 'N.A.';
            app.TypeLabel = uilabel(app.MainWindow);
            app.TypeLabel.Text = 'Type:';
            app.TypeElemLabel = uilabel(app.MainWindow);
            app.TypeElemLabel.Text = 'N.A.';
            app.SpanLabel = uilabel(app.MainWindow);
            app.SpanLabel.Text = 'Span:';
            app.SpanNoElemLabel = uilabel(app.MainWindow);
            app.SpanNoElemLabel.Text = 'N.A.';
            app.PosLabel = uilabel(app.MainWindow);
            app.PosLabel.Text = 'Pos.:';
            app.PositionElemLabel = uilabel(app.MainWindow);
            app.PositionElemLabel.Text = 'N.A.';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenIPDM_standalone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end