classdef BoundsMenuKR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        OKbounds                     matlab.ui.control.Button
        CancelBounds                 matlab.ui.control.Button
        uipanel1                     matlab.ui.container.Panel
        ChkCondition                 matlab.ui.control.CheckBox
        ChkSpeed                     matlab.ui.control.CheckBox
        ChkAcc                       matlab.ui.control.CheckBox
        MinKFCond                    matlab.ui.control.EditField
        MinKFSpeed                   matlab.ui.control.EditField
        MinKFAcc                     matlab.ui.control.EditField
        MaxKFCond                    matlab.ui.control.EditField
        MaxKFSpeed                   matlab.ui.control.EditField
        MaxKFAcc                     matlab.ui.control.EditField
        text5                        matlab.ui.control.Label
        text6                        matlab.ui.control.Label
        ConsIniKF                    matlab.ui.control.CheckBox
        ConsSmoother                 matlab.ui.control.CheckBox
        ConsKF                       matlab.ui.control.CheckBox
        ResetDatabaseButton          matlab.ui.control.Button
        uipanel2                     matlab.ui.container.Panel
        OptParamTable                matlab.ui.control.Table
        uipanel1_2                   matlab.ui.container.Panel
        ReferencePointsSpinnerLabel  matlab.ui.control.Label
        ReferencePointsSpinner       matlab.ui.control.Spinner
        StructuralAttributesSpinnerLabel  matlab.ui.control.Label
        StructuralAttributesSpinner  matlab.ui.control.Spinner
        UseRecommendedBandwidth      matlab.ui.control.CheckBox
        ViewAttributesButton         matlab.ui.control.Button
        TestDataSpinnerLabel         matlab.ui.control.Label
        TestDataSpinner              matlab.ui.control.Spinner
        StartYearSpinnerLabel        matlab.ui.control.Label
        StartYearSpinner             matlab.ui.control.Spinner
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BoundsMenu_OpeningFcn(app)

            global OptBoundsData GlobalCondData KernelParameters
            global TestPercent AnalysesStartYear
            
            if isempty(OptBoundsData)
                load(sprintf(['%s/Tools/ModelTraining_Generic/InitialParameterValues/SoftwareInitialKR.mat'],pwd));
                set(app.OptParamTable,'data',BoundsData);
                app.OptParamTable.ColumnFormat=({[] [] [] {'RBF' 'AAK' 'Matern12' 'Matern32' 'Matern52' 'Anisotropic-RBF' 'Laplace' 'Polynomial' 'Linear'}});
            else
                KRParam=num2cell(KernelParameters{3});
                KRParam(:,4)={''};
                KRParam(2:end,4)=KernelParameters{1}(:);
                KRParam1=num2cell(OptBoundsData);
                KRParam1(:,4)={''};
                TableContent=[KRParam1;KRParam];
                set(app.OptParamTable,'data',TableContent);
                set(app.MinKFCond,'value',num2str(GlobalCondData(1,3)));
                set(app.MaxKFCond,'value',num2str(GlobalCondData(1,4)));
                set(app.MinKFSpeed,'value',num2str(GlobalCondData(2,3)));
                set(app.MaxKFSpeed,'value',num2str(GlobalCondData(2,4)));
                set(app.MinKFAcc,'value',num2str(GlobalCondData(3,3)));
                set(app.MaxKFAcc,'value',num2str(GlobalCondData(3,4)));
                set(app.ConsIniKF,'value',logical(GlobalCondData(1,1)));
                set(app.ConsSmoother,'value',logical(GlobalCondData(2,1)));
                set(app.ConsKF,'value',logical(GlobalCondData(3,1)));
                set(app.ChkCondition,'value',logical(GlobalCondData(1,2)));
                set(app.ChkSpeed,'value',logical(GlobalCondData(2,2)));
                set(app.ChkAcc,'value',logical(GlobalCondData(3,2)));
                app.TestDataSpinner.Value = TestPercent*100;
                app.StartYearSpinner.Value = AnalysesStartYear;
                set(app.UseRecommendedBandwidth,'Value',logical(KernelParameters{5}));
%                 set(app.KernelTypeDropDown,'Value',KernelParameters{1});
                set(app.ReferencePointsSpinner,'Value',KernelParameters{2});
                set(app.StructuralAttributesSpinner,'Value',KernelParameters{4});
                TableRows = {'SigmaW'; 
                'SigmaV'; 'Init. Cond. Uncert.'; 'Init. Speed Uncert.';
                'Init. Acc. Uncert.'; 'Init. Speed (intercept)'; 'SigmaW_KR'};
                
                for i=1:KernelParameters{4}
                    TableRows=[TableRows ;{sprintf('L%d',i)}];
                end
                app.OptParamTable.RowName = TableRows;
                app.OptParamTable.ColumnFormat=({[] [] [] {'RBF' 'AAK' 'Matern12' 'Matern32' 'Matern52' 'Anisotropic-RBF' 'Laplace' 'Polynomial' 'Linear'}});
            end
        end

        % Button pushed function: CancelBounds
        function CancelBounds_Callback(app, event)
            % hObject    handle to CancelBounds (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global VIDataSTAtt
            VIDataSTAtt=[];
            close(app.figure1);
        end

        % Button pushed function: OKbounds
        function OKbounds_Callback(app, event)
            % hObject    handle to OKbounds (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global OptBoundsData GlobalCondData KernelParameters VIDataSTAtt
            global TestPercent AnalysesStartYear MdataEngy
            VIDataSTAtt=[];
            if isempty(GlobalCondData)
                GlobalCondData=zeros(3,4);
            end
            OptBoundsData=cell2mat(app.OptParamTable.Data(1:6,1:3));
            GlobalCondData(1,1)=get(app.ConsIniKF,'value');
            GlobalCondData(2,1)=get(app.ConsSmoother,'value');
            GlobalCondData(3,1)=get(app.ConsKF,'value');
            GlobalCondData(1,2)=get(app.ChkCondition,'value');
            GlobalCondData(2,2)=get(app.ChkSpeed,'value');
            GlobalCondData(3,2)=get(app.ChkAcc,'value');
            GlobalCondData(1,3)=str2double(get(app.MinKFCond,'value'));
            GlobalCondData(1,4)=str2double(get(app.MaxKFCond,'value'));
            GlobalCondData(2,3)=str2double(get(app.MinKFSpeed,'value'));
            GlobalCondData(2,4)=str2double(get(app.MaxKFSpeed,'value'));
            GlobalCondData(3,3)=str2double(get(app.MinKFAcc,'value'));
            GlobalCondData(3,4)=str2double(get(app.MaxKFAcc,'value'));
            TestPercent = app.TestDataSpinner.Value/100;
            AnalysesStartYear = app.StartYearSpinner.Value;
            KernelParameters{1}=app.OptParamTable.Data(8:end,end);
            KernelParameters{2}=get(app.ReferencePointsSpinner,'Value');
            KernelParameters{3}=cell2mat(app.OptParamTable.Data(7:end,1:end-1));
            KernelParameters{4}=get(app.StructuralAttributesSpinner,'Value');
            KernelParameters{5}=get(app.UseRecommendedBandwidth,'Value');
            close(app.figure1);
        end

        % Value changed function: StructuralAttributesSpinner
        function StructuralAttributesSpinnerValueChanged(app, event)
            StAttNum = app.StructuralAttributesSpinner.Value;
            TableRows = {'SigmaW'; 
                'SigmaV'; 'Init. Cond. Uncert.'; 'Init. Speed Uncert.';
                'Init. Acc. Uncert.'; 'Init. Speed (intercept)'; 'SigmaW_KR'};
            TableData=app.OptParamTable.Data(1:7,:);
            InitialRow={0.1 0 inf 'RBF'};
            for i=1:StAttNum
                TableRows=[TableRows ;{sprintf('L%d',i)}];
                TableData=[TableData;InitialRow];
            end
            app.OptParamTable.RowName = TableRows;
            app.OptParamTable.Data=TableData;
        end

        % Button pushed function: ViewAttributesButton
        function ViewAttributesButtonPushed(app, event)
        global KernelParameters  OptBoundsData 
        global TestPercent AnalysesStartYear
            KernelParameters{4}=get(app.StructuralAttributesSpinner,'Value');
            OptBoundsData=cell2mat(app.OptParamTable.Data(1:6,1:3));
            TestPercent = app.TestDataSpinner.Value/100;
            AnalysesStartYear = app.StartYearSpinner.Value;
            CleanAttributeData_App
        end

        % Button pushed function: ResetDatabaseButton
        function ResetDatabaseButtonPushed(app, event)
            global MdataEngy
            MdataEngy =[];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Set bounds';
            app.OKbounds = uibutton(app.figure1, 'push');
            app.OKbounds.ButtonPushedFcn = createCallbackFcn(app, @OKbounds_Callback, true);
            app.OKbounds.Text = 'OK';
            app.CancelBounds = uibutton(app.figure1, 'push');
            app.CancelBounds.ButtonPushedFcn = createCallbackFcn(app, @CancelBounds_Callback, true);
            app.CancelBounds.Text = 'Cancel';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Kalman Filter Constraints';
            app.ChkCondition = uicheckbox(app.uipanel1);
            app.ChkCondition.Enable = 'off';
            app.ChkCondition.Visible = 'off';
            app.ChkCondition.Text = 'Condition';
            app.ChkSpeed = uicheckbox(app.uipanel1);
            app.ChkSpeed.Text = 'Speed';
            app.ChkSpeed.Value = true;
            app.ChkAcc = uicheckbox(app.uipanel1);
            app.ChkAcc.Enable = 'off';
            app.ChkAcc.Visible = 'off';
            app.ChkAcc.Text = 'Acceleration';
            app.MinKFCond = uieditfield(app.uipanel1, 'text');
            app.MinKFCond.Enable = 'off';
            app.MinKFCond.Visible = 'off';
            app.MinKFCond.Value = '25';
            app.MinKFSpeed = uieditfield(app.uipanel1, 'text');
            app.MinKFSpeed.Value = '-50';
            app.MinKFAcc = uieditfield(app.uipanel1, 'text');
            app.MinKFAcc.Enable = 'off';
            app.MinKFAcc.Visible = 'off';
            app.MinKFAcc.Value = '-25';
            app.MaxKFCond = uieditfield(app.uipanel1, 'text');
            app.MaxKFCond.Enable = 'off';
            app.MaxKFCond.Visible = 'off';
            app.MaxKFCond.Value = '100';
            app.MaxKFSpeed = uieditfield(app.uipanel1, 'text');
            app.MaxKFSpeed.Value = '0';
            app.MaxKFAcc = uieditfield(app.uipanel1, 'text');
            app.MaxKFAcc.Enable = 'off';
            app.MaxKFAcc.Visible = 'off';
            app.MaxKFAcc.Value = '0';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'Min.';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = 'Max.';
            app.ConsIniKF = uicheckbox(app.uipanel1);
            app.ConsIniKF.Text = 'Constraint Initial KF Run';
            app.ConsIniKF.Value = true;
            app.ConsSmoother = uicheckbox(app.uipanel1);
            app.ConsSmoother.Text = 'Constraint Smoother';
            app.ConsSmoother.Value = true;
            app.ConsKF = uicheckbox(app.uipanel1);
            app.ConsKF.Text = 'Constraint KF';
            app.ConsKF.Value = true;
            app.ResetDatabaseButton = uibutton(app.uipanel1, 'push');
            app.ResetDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDatabaseButtonPushed, true);
            app.ResetDatabaseButton.Text = 'Reset Database';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Optimization Algorithm Constraints';
            app.OptParamTable = uitable(app.uipanel2);
            app.OptParamTable.ColumnName = {'Initial'; 'Min'; 'Max'; 'Kernel Type'};
            app.OptParamTable.RowName = {'SigmaW'; 'SigmaV'; 'Init. Cond. Uncert.'; 'Init. Speed Uncert.'; 'Init. Acc. Uncert.'; 'Init. Speed (intercept)'; 'SigmaW_KR'; 'L1'};
            app.OptParamTable.ColumnEditable = true;
            app.uipanel1_2 = uipanel(app.figure1);
            app.uipanel1_2.Title = 'Kernel Regression Options';
            app.ReferencePointsSpinnerLabel = uilabel(app.uipanel1_2);
            app.ReferencePointsSpinnerLabel.Text = '# Reference Points';
            app.ReferencePointsSpinner = uispinner(app.uipanel1_2);
            app.ReferencePointsSpinner.Limits = [1 Inf];
            app.ReferencePointsSpinner.Value = 1;
            app.StructuralAttributesSpinnerLabel = uilabel(app.uipanel1_2);
            app.StructuralAttributesSpinnerLabel.Text = '# Structural Attributes';
            app.StructuralAttributesSpinner = uispinner(app.uipanel1_2);
            app.StructuralAttributesSpinner.Limits = [1 Inf];
            app.StructuralAttributesSpinner.ValueChangedFcn = createCallbackFcn(app, @StructuralAttributesSpinnerValueChanged, true);
            app.StructuralAttributesSpinner.Value = 1;
            app.UseRecommendedBandwidth = uicheckbox(app.uipanel1_2);
            app.UseRecommendedBandwidth.Text = 'Use Recommended Bandwidth';
            app.ViewAttributesButton = uibutton(app.uipanel1_2, 'push');
            app.ViewAttributesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAttributesButtonPushed, true);
            app.ViewAttributesButton.Text = 'View Attributes';
            app.TestDataSpinnerLabel = uilabel(app.figure1);
            app.TestDataSpinnerLabel.Text = 'Test Data %';
            app.TestDataSpinner = uispinner(app.figure1);
            app.TestDataSpinner.Limits = [0 100];
            app.TestDataSpinner.Value = 5;
            app.StartYearSpinnerLabel = uilabel(app.figure1);
            app.StartYearSpinnerLabel.Text = 'Start Year';
            app.StartYearSpinner = uispinner(app.figure1);
            app.StartYearSpinner.Limits = [1900 Inf];
            app.StartYearSpinner.Value = 2008;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoundsMenuKR_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @BoundsMenu_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end