classdef InspectorAnalysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        AboutMenu                   matlab.ui.container.Menu
        AboutInspectorsToolboxMenu  matlab.ui.container.Menu
        SaveInspectors              matlab.ui.control.Button
        text3                       matlab.ui.control.Label
        text2                       matlab.ui.control.Label
        InspectionsData             matlab.ui.control.EditField
        LoadInspectionDataFile      matlab.ui.control.Button
        ObsHisotgram                matlab.ui.control.Button
        InspectorsTable             matlab.ui.control.Table
        SelectInspector             matlab.ui.control.DropDown
        AxesHist                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        VI2Data % Database
        InspObs %
    end
    
    methods (Access = private)
        function InspID_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to InspID (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InspectorAnalysis_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to InspectorAnalysis (see VARARGIN)
%             
%             % Choose default command line output for InspectorAnalysis
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: LoadInspectionDataFile
        function LoadInspectionDataFile_Callback(app, event)
            % hObject    handle to LoadInspectionDataFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            FilterSuffix={'*.mat';'*.*'};
            try
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Visual Inspections data file');
                set(app.InspectionsData,'Value',VIDataFileName)
                app.VI2Data=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.VI2Data=struct2cell(app.VI2Data);
                app.VI2Data=app.VI2Data{1};
                CID=sum(~cellfun(@isempty,app.VI2Data),2);
                fx=zeros(length(CID),max(CID));
                h = waitbar(0,'Looking up inspectors...');
                Inspectors=[];
                for i=1:length(CID)
                    for j=1:CID(i)
                        UniqueInspectors=unique(app.VI2Data{i,j}(:,2));
                        Inspectors = union(UniqueInspectors,Inspectors);
                    end
                end
                
                app.InspObs={};InspStruc={};ObsStore=[];StrucStore=[];ElemStore=[];
                for k=1:length(Inspectors)
                    for i=1:length(CID)
                        for j=1:CID(i)
                            InspecIndex=find(app.VI2Data{i,j}(:,2)==Inspectors(k));
                            if ~isempty(InspecIndex)
                                ObsStore=[ObsStore app.VI2Data{i,j}(InspecIndex,1)'];
                                StrucStore=[StrucStore app.VI2Data{i,j}(InspecIndex,4)'];
                                ElemStore=[ElemStore j];
                            end
                        end
                    end
                    app.InspObs{k}=ObsStore;
                    InspStruc{k}=StrucStore;
                    ElemStruc{k}=ElemStore;
                    ObsStore=[];StrucStore=[];ElemStore=[];
                    waitbar(k/length(k))
                end
                Vc = cellfun(@(x) unique(x(:)), InspStruc, 'UniformOutput',false);
                Ec = cellfun(@(x) unique(x(:)), ElemStruc, 'UniformOutput',false);
                NumStruc=cellfun('size',Vc,1);
                NumElem=cellfun('size',Ec,1);
                ObsMean = cellfun(@(x) nanmean(x(:)), app.InspObs, 'UniformOutput',false);
                ObsStd = cellfun(@(x) nanstd(x(:)), app.InspObs, 'UniformOutput',false);
                NumObs=cellfun('size',app.InspObs,2);
                Inspectors=[Inspectors NumStruc' NumObs' cell2mat(ObsMean)' cell2mat(ObsStd)'];
                set(app.InspectorsTable,'Data',Inspectors);
                set(app.SelectInspector,'Items',cellstr(string(Inspectors(:,1))));
                close(h)
            catch
                message = 'No file was selected, or the selected file is incompatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: ObsHisotgram
        function ObsHisotgram_Callback(app, event)
            % hObject    handle to ObsHisotgram (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Index=find(strcmp(app.SelectInspector.Value,app.SelectInspector.Items));
            try
                histogram(app.AxesHist,app.InspObs{Index}(:),[25:5:100]);
            catch
                message = 'Inspector data is not available';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: SaveInspectors
        function SaveInspectors_Callback(app, event)
            try
                AW=15;
                AllInspectors=get(app.InspectorsTable,'Data');
                AllInspectors=AllInspectors(:,1);
                [Inspectors]=FIlteredInspectors(app.VI2Data,AllInspectors,AW);
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','All Inspectors ID Data');
                save(fullfile(pathname,filename),'AllInspectors');
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','Filtered Inspectors ID Data');
                save(fullfile(pathname,filename),'Inspectors');
            catch
                message = 'Inspectors data is not available';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Menu selected function: AboutInspectorsToolboxMenu
        function AboutInspectorsToolboxMenuSelected(app, event)
            uialert(app.ReaddatabaseMain,[{'Inspectors Toolbox'};{''};{'Developed By: Zachary A. Hamida, Ph.D.'};{'Webpage:zachamida.github.io'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Inspectors Data';
            app.figure1.Icon = 'OpenIPDM_icon_r.png';
            app.AboutMenu = uimenu(app.figure1);
            app.AboutMenu.Text = 'About';
            app.AboutInspectorsToolboxMenu = uimenu(app.AboutMenu);
            app.AboutInspectorsToolboxMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutInspectorsToolboxMenuSelected, true);
            app.AboutInspectorsToolboxMenu.Text = 'About Inspectors Toolbox';
            app.AxesHist = uiaxes(app.figure1);
            app.SelectInspector = uidropdown(app.figure1);
            app.SelectInspector.Items = {'...'};
            app.SelectInspector.Value = '...';
            app.InspectorsTable = uitable(app.figure1);
            app.InspectorsTable.ColumnName = {'Inspector ID'; 'No. Of Structures'; 'No. Of Observations'; 'Avg. Condition'; 'Std. Condition'; 'Material'};
            app.InspectorsTable.ColumnEditable = [false false false false false false];
            app.ObsHisotgram = uibutton(app.figure1, 'push');
            app.ObsHisotgram.ButtonPushedFcn = createCallbackFcn(app, @ObsHisotgram_Callback, true);
            app.ObsHisotgram.Text = 'Obs. Histogram';
            app.LoadInspectionDataFile = uibutton(app.figure1, 'push');
            app.LoadInspectionDataFile.ButtonPushedFcn = createCallbackFcn(app, @LoadInspectionDataFile_Callback, true);
            app.LoadInspectionDataFile.Text = '...';
            app.InspectionsData = uieditfield(app.figure1, 'text');
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Load Inspection Data';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Select Inspector ID';
            app.SaveInspectors = uibutton(app.figure1, 'push');
            app.SaveInspectors.ButtonPushedFcn = createCallbackFcn(app, @SaveInspectors_Callback, true);
            app.SaveInspectors.Text = 'Extract IDs';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InspectorAnalysis_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @InspectorAnalysis_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end