classdef LoadingPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadData                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        LoadInfrastructuresDataPanel    matlab.ui.container.Panel
        ElementsDetailsEditField        matlab.ui.control.EditField
        ElementsDetailsEditFieldLabel   matlab.ui.control.Label
        LoadDetDb                       matlab.ui.control.Button
        InspectorsDatabaseEditField     matlab.ui.control.EditField
        InspectorsDatabaseEditFieldLabel  matlab.ui.control.Label
        LoadInspDb                      matlab.ui.control.Button
        BudgetDatabaseEditField         matlab.ui.control.EditField
        BudgetDatabaseEditFieldLabel    matlab.ui.control.Label
        LoadBudDb                       matlab.ui.control.Button
        InterventionsDatabaseEditField  matlab.ui.control.EditField
        InterventionsDatabaseEditFieldLabel  matlab.ui.control.Label
        LoadIntDb                       matlab.ui.control.Button
        InspectionsDatabaseEditField    matlab.ui.control.EditField
        InspectionsDatabaseEditFieldLabel  matlab.ui.control.Label
        LoadSeDb                        matlab.ui.control.Button
        StructuresDatabaseEditField     matlab.ui.control.EditField
        StructuresDatabaseEditFieldLabel  matlab.ui.control.Label
        LoadStDb                        matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainWindow   % Main App
        SE           % structural elements Database
        ST           % Structures Database
        IP           % Inspectors Database
        IN           % Intervention Database
        IB           % Budget Database
        ED           % Element Details
        CurrentPath=[];  % Files Path
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
           app.MainWindow = MainApp;
        end

        % Button pushed function: LoadStDb
        function LoadStDbPushed(app, event)
            try
                addpath(sprintf('%s/Scripts/Load/',pwd))
                run('LoadStructures.m');
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.LoadData, message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadSeDb
        function LoadSeDbButtonPushed(app, event)
            try
                addpath(sprintf('%s/Scripts/Load/',pwd))
                run('LoadElements.m');
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.LoadData, message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadIntDb
        function LoadIntDbButtonPushed(app, event)
            try
                addpath(sprintf('%s/Scripts/Load/',pwd))
                run('LoadInterventions.m');
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.LoadData, message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadBudDb
        function LoadBudDbButtonPushed(app, event)
            try
                addpath(sprintf('%s/Scripts/Load/',pwd))
                run('LoadBudget.m');
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.LoadData, message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadInspDb
        function LoadInspDbButtonPushed(app, event)
            try
                addpath(sprintf('%s/Scripts/Load/',pwd))
                run('LoadInspectors.m');
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.LoadData, message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadDetDb
        function LoadDetDbButtonPushed(app, event)
            try
                addpath(sprintf('%s/Scripts/Load/',pwd))
                run('LoadElementsDetails.m');
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.LoadData, message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            try
                PassValues(app.MainWindow,app.SE,app.ST,app.IP,app.IN,app.IB,app.ED);
                close(app.LoadData);
            catch
                message = 'One file or more are not loaded, or have incompatible format';
                uialert(app.LoadData, message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.LoadData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadData = uifigure('Visible', 'off');
            app.LoadData.Name = 'Load Database';
            app.LoadData.Icon = 'OpenIPDM_icon_r.png';
            app.LoadInfrastructuresDataPanel = uipanel(app.LoadData);
            app.LoadInfrastructuresDataPanel.Title = 'Load Infrastructures Data';
            app.LoadStDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadStDb.ButtonPushedFcn = createCallbackFcn(app, @LoadStDbPushed, true);
            app.LoadStDb.Text = '...';
            app.StructuresDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.StructuresDatabaseEditFieldLabel.Text = 'Structures Database';
            app.StructuresDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadSeDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadSeDb.ButtonPushedFcn = createCallbackFcn(app, @LoadSeDbButtonPushed, true);
            app.LoadSeDb.Text = '...';
            app.InspectionsDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.InspectionsDatabaseEditFieldLabel.Text = 'Inspections Database';
            app.InspectionsDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadIntDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadIntDb.ButtonPushedFcn = createCallbackFcn(app, @LoadIntDbButtonPushed, true);
            app.LoadIntDb.Text = '...';
            app.InterventionsDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.InterventionsDatabaseEditFieldLabel.Text = 'Interventions Database';
            app.InterventionsDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadBudDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadBudDb.ButtonPushedFcn = createCallbackFcn(app, @LoadBudDbButtonPushed, true);
            app.LoadBudDb.Text = '...';
            app.BudgetDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.BudgetDatabaseEditFieldLabel.Text = 'Budget Database';
            app.BudgetDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadInspDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadInspDb.ButtonPushedFcn = createCallbackFcn(app, @LoadInspDbButtonPushed, true);
            app.LoadInspDb.Text = '...';
            app.InspectorsDatabaseEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.InspectorsDatabaseEditFieldLabel.Text = 'Inspectors Database';
            app.InspectorsDatabaseEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.LoadDetDb = uibutton(app.LoadInfrastructuresDataPanel, 'push');
            app.LoadDetDb.ButtonPushedFcn = createCallbackFcn(app, @LoadDetDbButtonPushed, true);
            app.LoadDetDb.Text = '...';
            app.ElementsDetailsEditFieldLabel = uilabel(app.LoadInfrastructuresDataPanel);
            app.ElementsDetailsEditFieldLabel.Text = 'Elements Details';
            app.ElementsDetailsEditField = uieditfield(app.LoadInfrastructuresDataPanel, 'text');
            app.OKButton = uibutton(app.LoadData, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.LoadData, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.LoadData.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadingPanel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadData)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadData)
        end
    end
end