classdef LoadingPanel_Parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadModelParametersUIFigure    matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        LoadModelParametersPanel       matlab.ui.container.Panel
        ModelParametersEditField       matlab.ui.control.EditField
        ModelParametersEditFieldLabel  matlab.ui.control.Label
        LoadParam_Det                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        MP           % Deterioration Model Parameters
        MPI          % Intervention Parameters
        MainWindow   % Main window instance
        CurrentPath=[];  % Files Path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainWindow = MainApp;
        end

        % Button pushed function: LoadParam_Det
        function LoadParam_DetButtonPushed(app, event)
            try
                addpath(sprintf('%s/Scripts/Load/',pwd))
                run('LoadParameters.m');
            catch
                message = 'Loading parameters file is not done.';
                uialert(app.LoadModelParametersUIFigure,message,'Error','Icon','error');
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            PassValues2(app.MainWindow,app.MP,app.MPI);
            close(app.LoadModelParametersUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.LoadModelParametersUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadModelParametersUIFigure = uifigure('Visible', 'off');
            app.LoadModelParametersUIFigure.Name = 'Load Model Parameters';
            app.LoadModelParametersUIFigure.Icon = 'OpenIPDM_icon_r.png';
            app.LoadModelParametersPanel = uipanel(app.LoadModelParametersUIFigure);
            app.LoadModelParametersPanel.Title = 'Load Model Parameters';
            app.LoadParam_Det = uibutton(app.LoadModelParametersPanel, 'push');
            app.LoadParam_Det.ButtonPushedFcn = createCallbackFcn(app, @LoadParam_DetButtonPushed, true);
            app.LoadParam_Det.Text = '...';
            app.ModelParametersEditFieldLabel = uilabel(app.LoadModelParametersPanel);
            app.ModelParametersEditFieldLabel.Text = 'Model Parameters';
            app.ModelParametersEditField = uieditfield(app.LoadModelParametersPanel, 'text');
            app.OKButton = uibutton(app.LoadModelParametersUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.LoadModelParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.LoadModelParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadingPanel_Parameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadModelParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadModelParametersUIFigure)
        end
    end
end