classdef BoundsMenuKR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        OKbounds                      matlab.ui.control.Button
        CancelBounds                  matlab.ui.control.Button
        uipanel2                      matlab.ui.container.Panel
        OptParamTable                 matlab.ui.control.Table
        uipanel1_2                    matlab.ui.container.Panel
        NumControlPointsSpinnerLabel  matlab.ui.control.Label
        NumControlPointsSpinner       matlab.ui.control.Spinner
        NumStrAttributesSpinnerLabel  matlab.ui.control.Label
        NumStrAttributesSpinner       matlab.ui.control.Spinner
        UseRecommendedBandwidth       matlab.ui.control.CheckBox
        ViewAttributesButton          matlab.ui.control.Button
        uipanel2_2                    matlab.ui.container.Panel
        OptParamTable_int             matlab.ui.control.Table
        StatusDropDownLabel           matlab.ui.control.Label
        StatusDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        TrainingApp
        TrainingData
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BoundsMenu_OpeningFcn(app, LoadedData)
            app.TrainingApp=LoadedData;
            % fill the status drop menu
            SV=app.TrainingApp.StDatabaseStore.SelectedVariableNames;
            app.TrainingApp.StDatabaseStore.SelectedVariableNames=SV(12);
            app.StatusDropDown.Items=['All';unique(table2cell(readall(app.TrainingApp.StDatabaseStore)))];
            app.TrainingApp.StDatabaseStore.SelectedVariableNames=SV;
            % Selected element category
            Index=find(strcmp(app.TrainingApp.AllElementsParameters(:,1),app.TrainingApp.Tree.SelectedNodes.Text));
            if size(app.TrainingApp.AllElementsParameters,2)<2
                app.TrainingApp.AllElementsParameters{1,2}=[];
            end
            if isempty(app.TrainingApp.AllElementsParameters{Index,2})
                load(sprintf(['%s/Tools/ModelTraining_Generic/InitialParameterValues/SoftwareInitialKR_net.mat'],pwd));
                load(sprintf(['%s/Tools/ModelTraining_Generic/InitialParameterValues/SoftwareInitialInt_net.mat'],pwd));
                set(app.OptParamTable,'data',BoundsData);
                set(app.OptParamTable_int,'data',SoftwareInitialInt);
                app.OptParamTable.ColumnFormat=({[] [] [] {'RBF' 'AAK' 'Matern12' 'Matern32' 'Matern52' 'Anisotropic-RBF' 'Laplace' 'Polynomial' 'Linear'}});
            else
                KRParam=num2cell(app.TrainingApp.AllElementsParameters{Index,2}{4});
                KRParam(:,4)={''};
                KRParam(1:end,4)=app.TrainingApp.AllElementsParameters{Index,2}{2}(:);
                KRParam1=num2cell(app.TrainingApp.AllElementsParameters{Index,2}{1});
                KRParam1(:,4)={''};
                TableContent=[KRParam1;KRParam];
                IntBounds=app.TrainingApp.AllElementsParameters{Index,2}{8};
                set(app.OptParamTable,'data',TableContent);
                set(app.OptParamTable_int,'data',IntBounds);
                set(app.UseRecommendedBandwidth,'Value',logical(app.TrainingApp.AllElementsParameters{Index,2}{6}));
                set(app.NumControlPointsSpinner,'Value',app.TrainingApp.AllElementsParameters{Index,2}{3});
                set(app.NumStrAttributesSpinner,'Value',app.TrainingApp.AllElementsParameters{Index,2}{5});
                TableRows = {'n (Trans. fun.)';'SigmaW'; 'SigmaV'; 'Init. Cond. Uncert.'; 
                    'Init. Speed Uncert.';'Init. Acc. Uncert.'; 
                    'Init. Speed (intercept)'; 'SigmaW_KR'};
                
                for i=1:app.TrainingApp.AllElementsParameters{Index,2}{5}
                    TableRows=[TableRows ;{sprintf('L%d',i)}];
                end
                app.OptParamTable.RowName = TableRows;
                app.OptParamTable.ColumnFormat=({[] [] [] {'RBF' 'AAK' 'Matern12' 'Matern32' 'Matern52' 'Anisotropic-RBF' 'Laplace' 'Polynomial' 'Linear'}});
            end
        end

        % Button pushed function: CancelBounds
        function CancelBounds_Callback(app, event)

            close(app.figure1);
        end

        % Button pushed function: OKbounds
        function OKbounds_Callback(app, event)
            Index=find(strcmp(app.TrainingApp.AllElementsParameters(:,1),app.TrainingApp.Tree.SelectedNodes.Text));
            app.TrainingApp.AllElementsParameters{Index,2}{1}=cell2mat(app.OptParamTable.Data(1:7,1:3)); % SSM Parameters
            app.TrainingApp.AllElementsParameters{Index,2}{2}=app.OptParamTable.Data(8:end,end);         % Kernel Type
            app.TrainingApp.AllElementsParameters{Index,2}{3}=get(app.NumControlPointsSpinner,'Value');  % Number of reference points
            app.TrainingApp.AllElementsParameters{Index,2}{4}=cell2mat(app.OptParamTable.Data(8:end,1:end-1)); % KR Parameters
            app.TrainingApp.AllElementsParameters{Index,2}{5}=get(app.NumStrAttributesSpinner,'Value');  % Number of structural attributes
            app.TrainingApp.AllElementsParameters{Index,2}{6}=get(app.UseRecommendedBandwidth,'Value');  % Automatically set kernel length (band width) 
            app.TrainingApp.AllElementsParameters{Index,2}{7}=get(app.StatusDropDown,'Value'); 
            app.TrainingApp.AllElementsParameters{Index,2}{8}=app.OptParamTable_int.Data;
            close(app.figure1);
        end

        % Value changed function: NumStrAttributesSpinner
        function NumStrAttributesSpinnerValueChanged(app, event)
            StAttNum = app.NumStrAttributesSpinner.Value;
            TableRows = {'n (Trans. fun.)';'SigmaW'; 
                'SigmaV'; 'Init. Cond. Uncert.'; 'Init. Speed Uncert.';
                'Init. Acc. Uncert.'; 'Init. Speed (intercept)'; 'SigmaW_KR'};
            TableData=app.OptParamTable.Data(1:8,:);
            InitialRow={0.1 0 inf 'RBF'};
            for i=1:StAttNum
                TableRows=[TableRows ;{sprintf('L%d',i)}];
                TableData=[TableData;InitialRow];
            end
            app.OptParamTable.RowName = TableRows;
            app.OptParamTable.Data=TableData;
        end

        % Button pushed function: ViewAttributesButton
        function ViewAttributesButtonPushed(app, event)
            addpath(sprintf('%s/Scripts/ModelTraining/',pwd)); 
            Index=find(strcmp(app.TrainingApp.AllElementsParameters(:,1),app.TrainingApp.Tree.SelectedNodes.Text));
            InterventionCond=2;% All: 1, without Interventions: 2, Interventions only: 3
            [FullPathEx,FullPathInspector]=HandleReadData(app,InterventionCond);
            InterventionCond=3;% All: 1, without Interventions: 2, Interventions only: 3
            HandleReadData(app,InterventionCond);
            app.TrainingApp.AllElementsParameters{Index,2}{5}=get(app.NumStrAttributesSpinner,'Value');
            app.TrainingApp.AllElementsParameters{Index,2}{1}=cell2mat(app.OptParamTable.Data(1:6,1:3));
            InspectorsData=load(FullPathInspector);
            InspectorsData=struct2cell(InspectorsData);
            app.TrainingData=load(FullPathEx);
            app.TrainingData=struct2cell(app.TrainingData);
            app.TrainingData=app.TrainingData{1};
            
            MetaData=load(strrep(FullPathEx,'InspectionData','MetaData'));
            MetaData=struct2cell(MetaData);
            MetaData=MetaData{1};
            AttributeLbl=MetaData.AttributesLabels;
            TestSet=app.TrainingApp.TestsetSpinner.Value/100;
            CleanAttributeData_App(app.TrainingData,InspectorsData,app.OptParamTable.Data,app.NumStrAttributesSpinner.Value,AttributeLbl,FullPathEx,TestSet);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Set bounds';
            app.OKbounds = uibutton(app.figure1, 'push');
            app.OKbounds.ButtonPushedFcn = createCallbackFcn(app, @OKbounds_Callback, true);
            app.OKbounds.Text = 'OK';
            app.CancelBounds = uibutton(app.figure1, 'push');
            app.CancelBounds.ButtonPushedFcn = createCallbackFcn(app, @CancelBounds_Callback, true);
            app.CancelBounds.Text = 'Cancel';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Deterioration Model Parameters';
            app.OptParamTable = uitable(app.uipanel2);
            app.OptParamTable.ColumnName = {'Initial'; 'Min'; 'Max'; 'Kernel Type'};
            app.OptParamTable.RowName = {'n (Trans. fun.)'; 'SigmaW'; 'SigmaV'; 'Init. Cond. Sigma'; 'Init. Speed Sigma'; 'Init. Acc. Sigma'; 'Init. Speed (intercept)'; 'SigmaW_KR'; 'L1'};
            app.OptParamTable.ColumnEditable = true;
            app.uipanel1_2 = uipanel(app.figure1);
            app.uipanel1_2.Title = 'Kernel Regression Options';
            app.NumControlPointsSpinnerLabel = uilabel(app.uipanel1_2);
            app.NumControlPointsSpinnerLabel.Text = 'Num. Control Points';
            app.NumControlPointsSpinner = uispinner(app.uipanel1_2);
            app.NumControlPointsSpinner.Limits = [1 Inf];
            app.NumControlPointsSpinner.Value = 1;
            app.NumStrAttributesSpinnerLabel = uilabel(app.uipanel1_2);
            app.NumStrAttributesSpinnerLabel.Text = 'Num. Str. Attributes';
            app.NumStrAttributesSpinner = uispinner(app.uipanel1_2);
            app.NumStrAttributesSpinner.Limits = [1 Inf];
            app.NumStrAttributesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumStrAttributesSpinnerValueChanged, true);
            app.NumStrAttributesSpinner.Value = 1;
            app.UseRecommendedBandwidth = uicheckbox(app.uipanel1_2);
            app.UseRecommendedBandwidth.Visible = 'off';
            app.UseRecommendedBandwidth.Text = 'Use Recommended Bandwidth';
            app.ViewAttributesButton = uibutton(app.uipanel1_2, 'push');
            app.ViewAttributesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAttributesButtonPushed, true);
            app.ViewAttributesButton.Text = 'View Attributes';
            app.uipanel2_2 = uipanel(app.figure1);
            app.uipanel2_2.Title = 'Intervention Parameters';
            app.OptParamTable_int = uitable(app.uipanel2_2);
            app.OptParamTable_int.ColumnName = {'Initial'; 'Min'; 'Max'};
            app.OptParamTable_int.RowName = {'SigmaW1 (cond.)'; 'SigmaW2 (speed.)'; 'SigmaW3 (acc.)'; 'SigmaI1 (cond.)'; 'SigmaI2 (speed.)'; 'SigmaI3 (acc.)'};
            app.OptParamTable_int.ColumnEditable = [true true true];
            app.StatusDropDownLabel = uilabel(app.figure1);
            app.StatusDropDownLabel.Text = 'Status';
            app.StatusDropDown = uidropdown(app.figure1);
            app.StatusDropDown.Items = {'None'};
            app.StatusDropDown.Value = 'None';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoundsMenuKR_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)BoundsMenu_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end