classdef BoundsMenu_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        OKbounds             matlab.ui.control.Button
        CancelBounds         matlab.ui.control.Button
        uipanel2             matlab.ui.container.Panel
        OptParamTable        matlab.ui.control.Table
        uipanel2_2           matlab.ui.container.Panel
        OptParamTable_int    matlab.ui.control.Table
        StatusDropDownLabel  matlab.ui.control.Label
        StatusDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        TrainingApp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BoundsMenu_OpeningFcn(app, LoadedData)
            try
                app.TrainingApp=LoadedData;
                % fill the status drop menu
                SV=app.TrainingApp.StDatabaseStore.SelectedVariableNames;
                app.TrainingApp.StDatabaseStore.SelectedVariableNames=SV(12);
                app.StatusDropDown.Items=['All';unique(table2cell(readall(app.TrainingApp.StDatabaseStore)))];
                app.TrainingApp.StDatabaseStore.SelectedVariableNames=SV;
                % Selected element category
                Index=find(strcmp(app.TrainingApp.AllElementsParameters(:,1),app.TrainingApp.Tree.SelectedNodes.Text));
                if size(app.TrainingApp.AllElementsParameters,2)<2
                    app.TrainingApp.AllElementsParameters{1,2}=[];
                end
                if isempty(app.TrainingApp.AllElementsParameters{Index,2})
                    load(sprintf(['%s/Parameters/ModelTraining_SoftwareInitial_net.mat'],pwd));
                    load(sprintf(['%s/Parameters/ModelTraining_SoftwareInitialInt_net.mat'],pwd));
                    set(app.OptParamTable,'data',BoundsData);
                    set(app.OptParamTable_int,'data',SoftwareInitialInt);
                    app.TrainingApp.AllElementsParameters{Index,2}{1}=get(app.OptParamTable,'data');
                    app.TrainingApp.AllElementsParameters{Index,2}{7}=get(app.StatusDropDown,'Value');           % Get Bridge Status
                    app.TrainingApp.AllElementsParameters{Index,2}{8}=app.OptParamTable_int.Data;
                else
                    set(app.OptParamTable,'data',app.TrainingApp.AllElementsParameters{Index,2}{1});
                    IntBounds=app.TrainingApp.AllElementsParameters{Index,2}{8};
                    set(app.OptParamTable_int,'data',IntBounds);
                end
            catch
                message = 'Please select a structural category from the list below in order to define/modify the corresponding parameters';
                uialert(app.figure1,message, 'Error', 'Icon', 'error')
            end
        end

        % Button pushed function: CancelBounds
        function CancelBounds_Callback(app, event)
            close(app.figure1);
        end

        % Button pushed function: OKbounds
        function OKbounds_Callback(app, event)
            Index=find(strcmp(app.TrainingApp.AllElementsParameters(:,1),app.TrainingApp.Tree.SelectedNodes.Text));
            app.TrainingApp.AllElementsParameters{Index,2}{1}=get(app.OptParamTable,'data');
            app.TrainingApp.AllElementsParameters{Index,2}{7}=get(app.StatusDropDown,'Value');           % Get Bridge Status
            app.TrainingApp.AllElementsParameters{Index,2}{8}=app.OptParamTable_int.Data;
            close(app.figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Set bounds';
            app.figure1.Icon = 'OpenIPDM_icon_r.png';
            app.OKbounds = uibutton(app.figure1, 'push');
            app.OKbounds.ButtonPushedFcn = createCallbackFcn(app, @OKbounds_Callback, true);
            app.OKbounds.Text = 'OK';
            app.CancelBounds = uibutton(app.figure1, 'push');
            app.CancelBounds.ButtonPushedFcn = createCallbackFcn(app, @CancelBounds_Callback, true);
            app.CancelBounds.Text = 'Cancel';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Deterioration Model Parameters';
            app.OptParamTable = uitable(app.uipanel2);
            app.OptParamTable.ColumnName = {'Initial'; 'Min'; 'Max'};
            app.OptParamTable.RowName = {'n (Trans. fun.)'; 'SigmaW'; 'SigmaV'; 'Init. Cond. Sigma'; 'Init. Speed Sigma'; 'Init. Acc. Sigma'; 'Init. Speed (intercept)'};
            app.OptParamTable.ColumnEditable = [true true true];
            app.uipanel2_2 = uipanel(app.figure1);
            app.uipanel2_2.Title = 'Intervention Parameters';
            app.OptParamTable_int = uitable(app.uipanel2_2);
            app.OptParamTable_int.ColumnName = {'Initial'; 'Min'; 'Max'};
            app.OptParamTable_int.RowName = {'SigmaW'; 'SigmaV'; 'Init. Cond. Sigma'; 'Init. Speed Sigma'; 'Init. Acc. Sigma'; 'Init. Speed (intercept)'};
            app.OptParamTable_int.ColumnEditable = [true true true];
            app.StatusDropDownLabel = uilabel(app.figure1);
            app.StatusDropDownLabel.Text = 'Status';
            app.StatusDropDown = uidropdown(app.figure1);
            app.StatusDropDown.Items = {'None'};
            app.StatusDropDown.Value = 'None';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoundsMenu_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)BoundsMenu_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end