classdef ModelTraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelTrainingUIFigure          matlab.ui.Figure
        QuickStartMenu                 matlab.ui.container.Menu
        SelectSourceFolderMenu         matlab.ui.container.Menu
        LogFoldersMenu                 matlab.ui.container.Menu
        OpenParametersFolderMenu       matlab.ui.container.Menu
        OpenDataFolderMenu             matlab.ui.container.Menu
        LoadInputDataMenu              matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        AboutModelTrainingNetworkMenu  matlab.ui.container.Menu
        ExportDataButton               matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        TrainModelButton               matlab.ui.control.Button
        ResumeTrainingButton           matlab.ui.control.Button
        TrainingOptionsButtonGroup     matlab.ui.container.ButtonGroup
        JointOption                    matlab.ui.control.RadioButton
        IndependentOption              matlab.ui.control.RadioButton
        ResetButton                    matlab.ui.control.Button
        TestsetSpinnerLabel_2          matlab.ui.control.Label
        TestsetSpinner                 matlab.ui.control.Spinner
        TestsetSpinnerLabel            matlab.ui.control.Label
        YearLabel                      matlab.ui.control.Label
        InspectionsstartedatLabel      matlab.ui.control.Label
        StartYearSpinner               matlab.ui.control.Spinner
        AssignButton                   matlab.ui.control.Button
        RemoveButton                   matlab.ui.control.Button
        Tree                           matlab.ui.container.Tree
        SSMModelNode                   matlab.ui.container.TreeNode
        SSMBNNModelNode                matlab.ui.container.TreeNode
        LoadData                       matlab.ui.control.Button
        ParametersButton               matlab.ui.control.Button
        ElementListBox                 matlab.ui.control.ListBox
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SeDatabaseStore         % structural elements Database
        StDatabaseStore         % Structures Database
        InspectorsDatabaseStore % Inspectors Database
        IntDatabaseStore        % Intervention Database
        IntBudgetDataStore      % Budget Database
        ElementDetailsDataStore % Element Details
        AllElementsParameters   % Elements Parameters
        PreLoadData_training = 0
    end
    
    methods (Access = public)
        
        function PassValues(app,SE,ST,IP,IN,IB,ED)
            app.SeDatabaseStore=SE;
            app.StDatabaseStore=ST;
            app.InspectorsDatabaseStore=IP;
            app.IntDatabaseStore=IN;
            app.IntBudgetDataStore=IB;
            app.ElementDetailsDataStore=ED;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            if (isfolder('csv Data'))
                database_folder = dir(sprintf('%s/csv Data',pwd));
                if ~isempty(database_folder)
                    Inspectors = dir(sprintf('%s/csv Data/*ingenieur_responsable*.csv',pwd));
                    structures = dir(sprintf('%s/csv Data/*inventaire_inspect*.csv',pwd));
                    details = dir(sprintf('%s/csv Data/*inventaire_detail*.csv',pwd));
                    elements = dir(sprintf('%s/csv Data/*elements_inspect*.csv',pwd));
                    budget = dir(sprintf('%s/csv Data/budget*.csv',pwd));
                    realized = dir(sprintf('%s/csv Data/*travaux_realis*.csv',pwd));
                end
                if ~isempty(Inspectors) && ~isempty(structures) && ~isempty(details) ...
                    && ~isempty(elements) && ~isempty(budget) && ~isempty(realized) 
                    app.PreLoadData_training=1;
                end
            end
            addpath(sprintf('%s/Tools/',pwd));
            if app.PreLoadData_training
                Budget_col_id = [1,2,3];
                elements_col_id = [2,3,4,6,7,8,9,10,13,14,15,16,17,19,20];
                details_col_id = [1,2,3,5,6,8,10,16,20,26];
                Inspctor_col_id = [2,6];
                Interventions_col_id = [2,4,5,8,13,16];
                Struc_col_id = [1,4,7,11,12,13,14,15,16,17,18,19,23,24,25];
                
                app.InspectorsDatabaseStore = datastore(fullfile(Inspectors.folder,Inspectors.name));
                app.InspectorsDatabaseStore.SelectedVariableNames= app.InspectorsDatabaseStore.VariableNames(Inspctor_col_id);
                app.InspectorsDatabaseStore.SelectedFormats(~strcmp(app.InspectorsDatabaseStore.SelectedFormats,'%q'))={'%q'};
                
                app.StDatabaseStore=datastore(fullfile(structures.folder,structures.name));
                app.StDatabaseStore.SelectedVariableNames= app.StDatabaseStore.VariableNames(Struc_col_id);
                app.StDatabaseStore.SelectedFormats(~strcmp(app.InspectorsDatabaseStore.SelectedFormats,'%q'))={'%q'};
                
                app.SeDatabaseStore=datastore(fullfile(elements.folder,elements.name));
                app.SeDatabaseStore.SelectedVariableNames= app.SeDatabaseStore.VariableNames(elements_col_id);
                app.SeDatabaseStore.SelectedFormats(~strcmp(app.SeDatabaseStore.SelectedFormats,'%q'))={'%q'};
                
                app.IntDatabaseStore=datastore(fullfile(realized.folder,realized.name));
                app.IntDatabaseStore.SelectedVariableNames= app.IntDatabaseStore.VariableNames(Interventions_col_id);
                app.IntDatabaseStore.SelectedFormats(~strcmp(app.IntDatabaseStore.SelectedFormats,'%q'))={'%q'};
                
                app.IntBudgetDataStore=datastore(fullfile(budget.folder,budget.name));
                app.IntBudgetDataStore.SelectedVariableNames= app.IntBudgetDataStore.VariableNames(Budget_col_id);
                app.IntBudgetDataStore.SelectedFormats(~strcmp(app.IntBudgetDataStore.SelectedFormats,'%q'))={'%q'};
                
                app.ElementDetailsDataStore=datastore(fullfile(details.folder,details.name));
                app.ElementDetailsDataStore.SelectedVariableNames= app.ElementDetailsDataStore.VariableNames(details_col_id);
                app.ElementDetailsDataStore.SelectedFormats(~strcmp(app.ElementDetailsDataStore.SelectedFormats,'%q'))={'%q'};
                
                SV=app.SeDatabaseStore.SelectedVariableNames;
                app.SeDatabaseStore.SelectedVariableNames=SV(4);
                app.ElementListBox.Items=unique(table2cell(readall(app.SeDatabaseStore)));
                app.AllElementsParameters=app.ElementListBox.Items';
                app.SeDatabaseStore.SelectedVariableNames=SV;
                
                % load elements into models menu
                load(sprintf(['%s/Parameters/ModelTraining_config_elem.mat'],pwd));
                
                for i=1:size(config_elem,1)
                    check_exist = find(strcmp(config_elem{i,1},app.ElementListBox.Items));
                    if check_exist>0
                        ElemCat=app.ElementListBox.Items{check_exist};
                        if strcmp(app.Tree.Children(1).Text,config_elem{i,2})
                            uitreenode(app.SSMModelNode,'Text',string(ElemCat));
                        elseif strcmp(app.Tree.Children(2).Text,config_elem{i,2})
                            uitreenode(app.SSMBNNModelNode,'Text',string(ElemCat));
                        end
                    end
                end
                
            else
                try
                    waitfor(LoadingPanel(app));
                    % Deterioration Info:
                    % 1: NOStruc % 2: DateInsp % 3: NoTrav % 4: Element % 5: NoElem % 6: Position
                    % 7: Materiau % 8: TypeElement % 9: A % 10: B % 11: C % 12: D % 13: CEC
                    % 14: Inspection ID % 15: Element ID
                    SV=app.SeDatabaseStore.SelectedVariableNames;
                    app.SeDatabaseStore.SelectedVariableNames=SV(4);
                    app.ElementListBox.Items=unique(table2cell(readall(app.SeDatabaseStore)));
                    app.AllElementsParameters=app.ElementListBox.Items';
                    app.SeDatabaseStore.SelectedVariableNames=SV;
                    
                    % load elements into models menu
                    load(sprintf(['%s/Parameters/ModelTraining_config_elem.mat'],pwd));
                    
                    for i=1:size(config_elem,1)
                        check_exist = find(strcmp(config_elem{i,1},app.ElementListBox.Items));
                        if check_exist>0
                            ElemCat=app.ElementListBox.Items{check_exist};
                            if strcmp(app.Tree.Children(1).Text,config_elem{check_exist,2})
                                uitreenode(app.SSMModelNode,'Text',string(ElemCat));
                            elseif strcmp(app.Tree.Children(2).Text,config_elem{check_exist,2})
                                uitreenode(app.SSMBNNModelNode,'Text',string(ElemCat));
                            end
                        end
                    end
                catch
                    message = 'Input data is not loaded, loading data has failed due to one or more files not loaded correctly';
                    uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
                end
            end
        end

        % Button pushed function: LoadData
        function LoadDataButtonPushed(app, event)
            try
                waitfor(LoadingPanel(app));
                % Deterioration Info:
                % 1: NOStruc % 2: DateInsp % 3: NoTrav % 4: Element % 5: NoElem % 6: Position
                % 7: Materiau % 8: TypeElement % 9: A % 10: B % 11: C % 12: D % 13: CEC
                % 14: Inspection ID % 15: Element ID
                SV=app.SeDatabaseStore.SelectedVariableNames;
                app.SeDatabaseStore.SelectedVariableNames=SV(4);
                app.ElementListBox.Items=unique(table2cell(readall(app.SeDatabaseStore)));
                app.AllElementsParameters=app.ElementListBox.Items';
                app.SeDatabaseStore.SelectedVariableNames=SV;
            catch
                message = 'Input data is not loaded, loading data has failed due to one orr more files not loaded correctly';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            %SelectedCat=app.Tree.SelectedNodes.Text;
            addpath(sprintf('%s/Tools/ModelTraining/',pwd));
            BoundsMenu_App(app);
            
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            try
                ElemCat=app.ElementListBox.Value;
                if strcmp(app.Tree.Children(1).Text,app.Tree.SelectedNodes.Text)
                    uitreenode(app.SSMModelNode,'Text',string(ElemCat));
                elseif strcmp(app.Tree.Children(2).Text,app.Tree.SelectedNodes.Text)
                    uitreenode(app.SSMBNNModelNode,'Text',string(ElemCat));
                end
            catch
                message = 'Select a deterioration model from the list, and then click "Assign"';
                uialert(app.ModelTrainingUIFigure,message, 'Warning', 'Icon', 'warning')
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            try
                for i=1:length(app.Tree.Children)
                    Vals(i)= strcmp(app.Tree.SelectedNodes.Text,app.Tree.Children(i).Text);
                end
                if sum(Vals)==0
                    app.Tree.SelectedNodes.delete;
                end
            catch
                message = 'Select a structural categoery from the list, and then click "Remove"';
                uialert(app.ModelTrainingUIFigure,message, 'Warning', 'Icon', 'warning')
            end
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            ResumeTraining=0;
            cla(app.UIAxes);
            ThisYear=date;
            ThisYear=str2double(ThisYear(end-3:end));
            joint_inspector_train = app.JointOption.Value;
            TrainingWindow=ThisYear-app.StartYearSpinner.Value;
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd));
            FullPath=sprintf('%s/ExtractedData/',pwd);
            SavePath = sprintf('%s/Parameters/',pwd);
            NumCats = length(app.Tree.Children(1).Children)+length(app.Tree.Children(2).Children);
            d=uiprogressdlg(app.ModelTrainingUIFigure,'Title','Please Wait',...
            'Message',sprintf('Processing structure %d/%d',1,NumCats),'Cancelable','on'...
            ,'CancelText','Stop');
            Included_Elements = zeros(length(app.Tree.Children), NumCats);
            try
                for model_i=1:length(app.Tree.Children) % model type iterations: 2: SSM-KR or 1: SSM
                    for elem_j=1:length(app.Tree.Children(model_i).Children)
                        ElementName= app.Tree.Children(model_i).Children(elem_j).Text;
                        d.Value = (elem_j/NumCats)/1.2;
                        d.Message=sprintf('Processing structural category %s',ElementName);
                        if d.CancelRequested
                            break
                        end
                        pause(0.001);
                        app.Tree.SelectedNodes=app.Tree.Children(model_i).Children(elem_j);
                        Index=find(strcmp(app.AllElementsParameters(:,1),ElementName));
                        PreprocessingData();
                        if exist('Success','var') == 1
                            Included_Elements(model_i, elem_j) = Success;
                        end
                        %% Interventions optimization
                        %PreprocessingIntData();
                    end
                end
                save([FullPath 'Included_Elements' '.mat'],"Included_Elements");
                % cleaning up by removing elements without enough data for
                % model training
                for model_i=length(app.Tree.Children):-1:1 % model type iterations: 2: SSM-KR or 1: SSM
                     for elem_j=length(app.Tree.Children(model_i).Children):-1:1
                         app.Tree.SelectedNodes=app.Tree.Children(model_i).Children(elem_j);
                         if Included_Elements(model_i, elem_j)==0
                             app.Tree.SelectedNodes.delete;
                         end
                     end
                end
                RunTraining_unit();
                save([SavePath '/AllElementsParameters.mat'],'AllElementsParameters');
                d.Value=0.99;
                pause(0.001);
                close(d)
                d=uiprogressdlg(app.ModelTrainingUIFigure,'Title','Please Wait',...
                'Message',sprintf('Processing structure %d/%d',1,NumCats),'Cancelable','on'...
                ,'CancelText','Stop');
                Included_Elements_Interventions = zeros(length(app.Tree.Children), NumCats);
                %try
                for model_i=1:length(app.Tree.Children) % model type iterations: 2: SSM-KR or 1: SSM
                    for elem_j=1:length(app.Tree.Children(model_i).Children)
                        ElementName= app.Tree.Children(model_i).Children(elem_j).Text;
                        d.Value = (elem_j/NumCats)/1.2;
                        d.Message=sprintf('Processing structural category %s',ElementName);
                        if d.CancelRequested
                            break
                        end
                        pause(0.001);
                        app.Tree.SelectedNodes=app.Tree.Children(model_i).Children(elem_j);
                        Index=find(strcmp(app.AllElementsParameters(:,1),ElementName));
                        %display(ElementName)
                        %% Interventions optimization
                        InspectorsData{1} = AllElementsParameters{Index,3};
                        PreprocessingIntData();
                        if exist('Success','var') == 1
                            Included_Elements_Interventions(model_i, elem_j) = Success;
                        end
                        for ind_int = 1:3 % placeholders for intervention estimates
                            AllElementsParameters{Index,6}{1,ind_int}=nan(3,1);
                            AllElementsParameters{Index,7}{1,ind_int}=nan(3);
                            AllElementsParameters{Index,8}{1,ind_int}=nan(1,6);
                        end
                    end
                end
                save([FullPath 'Included_Elements_Interventions' '.mat'],"Included_Elements_Interventions");
                for model_i=length(app.Tree.Children):-1:1 % model type iterations: 2: SSM-KR or 1: SSM
                    for elem_j=length(app.Tree.Children(model_i).Children):-1:1
                        app.Tree.SelectedNodes=app.Tree.Children(model_i).Children(elem_j);
                        if Included_Elements_Interventions(model_i, elem_j)==0
                            app.Tree.SelectedNodes.delete;
                        end
                    end
                end
                training_interventions_unit();
                
                save([SavePath '/AllElementsParameters.mat'],'AllElementsParameters');
                d.Value=0.99;
                pause(0.001);
                close(d)
            catch ME
                message = ME.message;%'Model training has failed due to input or model parameters are not defined/loaded correctly, check the initial parameters values and bounds, also ensure loading the input data correctly.';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end

        % Button pushed function: ResumeTrainingButton
        function ResumeTrainingButtonPushed(app, event)
            ResumeTraining = 1;
            try
                [DataFileName,FilePath]=uigetfile('.mat','Select parameters file');
                if ~isempty(DataFileName)
                    AllElementsParameters=load(fullfile(FilePath,DataFileName));
                    AllElementsParameters=struct2cell(AllElementsParameters);
                    AllElementsParameters = AllElementsParameters{1};
                end
                [ConfigFileName,FilePath]=uigetfile('.mat','Select config file');
                if ~isempty(DataFileName)
                    config_opt=load(fullfile(FilePath,ConfigFileName));
                    app.AllElementsParameters=config_opt.config_opt.elements;
                    s_model = config_opt.config_opt.indexes(1);
                    s_elem = config_opt.config_opt.indexes(2);
                end
                cla(app.UIAxes);
                ThisYear=date;
                ThisYear=str2double(ThisYear(end-3:end));
                TrainingWindow=ThisYear-app.StartYearSpinner.Value;
                addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd));
                FullPath=sprintf('%s/ExtractedData/',pwd);
                SavePath = sprintf('%s/Parameters/',pwd);
                for model_i=s_model:length(app.Tree.Children) % model type iterations: 1: SSM-KR or 2: SSM
                    for elem_j=s_elem:length(app.Tree.Children(model_i).Children)
                        ElementName= app.Tree.Children(model_i).Children(elem_j).Text;
                        app.Tree.SelectedNodes=app.Tree.Children(model_i).Children(elem_j);
                        Index=find(strcmp(app.AllElementsParameters(:,1),ElementName));
                        training_deterioration();
                        % Interventions optimization
                        ModelParamLocal=app.AllElementsParameters{Index,2}{8}(:,1)';
                        OptBoundsInt=app.AllElementsParameters{Index,2}{8}(:,2:3);
                        training_interventions();
                    end
                    s_elem = 1;
                    ResumeTraining = 0;
                end
            catch ME
                message = ME.message;%'Resume training has failed due to input or model parameters are not defined/loaded correctly';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end

        % Menu selected function: AboutModelTrainingNetworkMenu
        function AboutModelTrainingNetworkMenuSelected(app, event)
            uialert(app.ModelTrainingUIFigure,[{'Model Training - Network Toolbox'};{''};{'Contributing Developers:'};{'Zachary A. Hamida, Ph.D.'};{'Ali Fakhri, M.Eng.'};{'Blanche Laurent, M.Eng.'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            try
            num_model = length(app.Tree.Children);
                for model_i=1:num_model % model type iterations: 1: SSM-KR or 2: SSM
                    app.Tree.Children(model_i).Children.delete;
                end
            catch
                message = 'Select a structural categoery from the list, and then click "Remove"';
                uialert(app.ModelTrainingUIFigure,message, 'Warning', 'Icon', 'warning')
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'Real')
                startupFcn(app)
            elseif strcmp(value, 'Synthetic')
                try
                    [DataFileName,FilePath]=uigetfile('.mat','Select synthetic observations file');
                    if ~isempty(DataFileName)
                        app.ElementListBox.Items = {DataFileName(1:end-4)};
                        app.AllElementsParameters=app.ElementListBox.Items';
                    end
                    ElementName = load(fullfile(FilePath,DataFileName));
                    ElementData = ElementName.SynDatabaseShort;
                    [InspectorFileName,FilePath]=uigetfile('.mat','Select inspectors ID file',FilePath);
                    InspectorsIDs = load(fullfile(FilePath,InspectorFileName));
                    FullPath_save = sprintf('%s/ExtractedData/',pwd);
                    for i=1:length(ElementData)
                        ElementData{i,1}(:,1)=RevSpaceTransformVec(4,ElementData{i,1}(:,1),100,25);
                    end
                    save([FullPath_save 'InspectionData_' erase(DataFileName,"/")],'ElementData', '-v7.3');
                    Insepctors = InspectorsIDs.InspID;
                    save([FullPath_save 'Inspectors_' erase(DataFileName,"/")], 'Insepctors');
                catch ME
                    message = ME.message;%'Resume training has failed due to input or model parameters are not defined/loaded correctly';
                    uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
                end
            end
        end

        % Menu selected function: LoadInputDataMenu
        function LoadInputDataMenuSelected(app, event)
            try
                waitfor(LoadingPanel(app));
                % Deterioration Info:
                % 1: NOStruc % 2: DateInsp % 3: NoTrav % 4: Element % 5: NoElem % 6: Position
                % 7: Materiau % 8: TypeElement % 9: A % 10: B % 11: C % 12: D % 13: CEC
                % 14: Inspection ID % 15: Element ID
                SV=app.SeDatabaseStore.SelectedVariableNames;
                app.SeDatabaseStore.SelectedVariableNames=SV(4);
                app.ElementListBox.Items=unique(table2cell(readall(app.SeDatabaseStore)));
                app.AllElementsParameters=app.ElementListBox.Items';
                app.SeDatabaseStore.SelectedVariableNames=SV;
            catch
                message = 'Input data is not loaded, loading data has failed due to one orr more files not loaded correctly';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end

        % Menu selected function: SelectSourceFolderMenu
        function SelectSourceFolderMenuSelected(app, event)
            try
                default_path=sprintf('%s/csv Data/',pwd);
                existing_csv_files = dir( fullfile(default_path, '*.csv') );
                existing_csv_names = {existing_csv_files.name};
                if ~isempty(existing_csv_names)
                    selection = uiconfirm(app.ModelTrainingUIFigure,'Delete existing .csv files in OpenIPDM directory?','Confirm deletion',...
                        'Icon','warning');
                    if strcmp(selection,'OK')
                        delete(sprintf('%s*.csv',default_path));
                        Selected_path = uigetdir(default_path,'Select the folder that contains the CSV file');
                        dinfo = dir( fullfile(Selected_path, '*.csv') );
                        filenames = {dinfo.name};
                        if length(filenames) ~= 6
                            message = 'The source folder should contain only the required files (total: 6 csv files)';
                            uialert(app.ModelTrainingUIFigure,message,'Warning','Icon','error');
                        else
                            status = copyfile(Selected_path,default_path);
                            if status == 1
                                startupFcn(app)
                            else
                                message = 'The selected path is not accessible, no change took place on the current input data';
                                uialert(app.ModelTrainingUIFigure,message,'Warning','Icon','warning');
                            end
                        end
                    end
                else
                    Selected_path = uigetdir(default_path,'Select the folder that contains the CSV file');
                    dinfo = dir( fullfile(Selected_path, '*.csv') );
                    filenames = {dinfo.name};
                    if length(filenames) ~= 6
                        message = 'The source folder should contain only the required files (total: 6 csv files)';
                        uialert(app.ModelTrainingUIFigure,message,'Warning','Icon','error');
                    else
                        status = copyfile(Selected_path,default_path);
                        if status == 1
                            startupFcn(app)
                        else
                            message = 'The selected path is not accessible, no change took place on the current input data';
                            uialert(app.ModelTrainingUIFigure,message,'Warning','Icon','warning');
                        end
                    end
                end
            catch
                message = 'The selected path is not accessible';
                uialert(app.ModelTrainingUIFigure,message,'Warning','Icon','warning');
            end
        end

        % Menu selected function: LogFoldersMenu
        function LogFoldersMenuSelected(app, event)
            
        end

        % Menu selected function: OpenParametersFolderMenu
        function OpenParametersFolderMenuSelected(app, event)
            winopen(sprintf('%s/Parameters/',pwd));
        end

        % Menu selected function: OpenDataFolderMenu
        function OpenDataFolderMenuSelected(app, event)
            winopen(sprintf('%s/csv Data/',pwd));
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            ResumeTraining=0;
            cla(app.UIAxes);
            ThisYear=date;
            ThisYear=str2double(ThisYear(end-3:end));
            joint_inspector_train = app.JointOption.Value;
            TrainingWindow=ThisYear-app.StartYearSpinner.Value;
            addpath(sprintf('%s/Scripts/DeteriorationAnalyses/',pwd));
            FullPath=sprintf('%s/ExtractedData/',pwd);
            SavePath = sprintf('%s/Parameters/',pwd);
            NumCats = length(app.Tree.Children(1).Children)+length(app.Tree.Children(2).Children);
            d=uiprogressdlg(app.ModelTrainingUIFigure,'Title','Please Wait',...
            'Message',sprintf('Processing structure %d/%d',1,NumCats),'Cancelable','on'...
            ,'CancelText','Stop');
            try
                for model_i=1:length(app.Tree.Children) % model type iterations: 2: SSM-KR or 1: SSM
                    for elem_j=1:length(app.Tree.Children(model_i).Children)
                        ElementName= app.Tree.Children(model_i).Children(elem_j).Text;
                        d.Value = (elem_j/NumCats)/1.2;
                        d.Message=sprintf('Processing structural category %s',ElementName);
                        if d.CancelRequested
                            break
                        end
                        pause(0.001);
                        app.Tree.SelectedNodes=app.Tree.Children(model_i).Children(elem_j);
                        Index=find(strcmp(app.AllElementsParameters(:,1),ElementName));
                        PreprocessingData();
                        %% Interventions optimization
                        InspectorsData{1} = [InspectorsData{1} ones(length(InspectorsData{1}),1)*0 ones(length(InspectorsData{1}),1)*3];
                        PreprocessingIntData();
                    end
                end
                d.Value=0.99;
                pause(0.001);
                close(d)
            catch ME
                message = ME.message;%'Model training has failed due to input or model parameters are not defined/loaded correctly, check the initial parameters values and bounds, also ensure loading the input data correctly.';
                uialert(app.ModelTrainingUIFigure,message, 'Error', 'Icon', 'error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelTrainingUIFigure = uifigure('Visible', 'off');
            app.ModelTrainingUIFigure.Name = 'Model Training';
            app.ModelTrainingUIFigure.Icon = 'OpenIPDM_icon_r.png';
            app.QuickStartMenu = uimenu(app.ModelTrainingUIFigure);
            app.QuickStartMenu.Text = 'Quick Start';
            app.SelectSourceFolderMenu = uimenu(app.QuickStartMenu);
            app.SelectSourceFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectSourceFolderMenuSelected, true);
            app.SelectSourceFolderMenu.Text = 'Select Source Folder';
            app.LogFoldersMenu = uimenu(app.QuickStartMenu);
            app.LogFoldersMenu.MenuSelectedFcn = createCallbackFcn(app, @LogFoldersMenuSelected, true);
            app.LogFoldersMenu.Text = 'Log Folders';
            app.OpenParametersFolderMenu = uimenu(app.LogFoldersMenu);
            app.OpenParametersFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenParametersFolderMenuSelected, true);
            app.OpenParametersFolderMenu.Text = 'Open Parameters Folder';
            app.OpenDataFolderMenu = uimenu(app.LogFoldersMenu);
            app.OpenDataFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDataFolderMenuSelected, true);
            app.OpenDataFolderMenu.Text = 'Open Data Folder';
            app.LoadInputDataMenu = uimenu(app.QuickStartMenu);
            app.LoadInputDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadInputDataMenuSelected, true);
            app.LoadInputDataMenu.Text = 'Load Input Data';
            app.AboutMenu = uimenu(app.ModelTrainingUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutModelTrainingNetworkMenu = uimenu(app.AboutMenu);
            app.AboutModelTrainingNetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutModelTrainingNetworkMenuSelected, true);
            app.AboutModelTrainingNetworkMenu.Text = 'About Model Training Network';
            app.UIAxes = uiaxes(app.ModelTrainingUIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Log-Likelihood')
            zlabel(app.UIAxes, 'Z')
            app.ElementListBox = uilistbox(app.ModelTrainingUIFigure);
            app.ElementListBox.Items = {'None'};
            app.ElementListBox.Multiselect = 'on';
            app.ElementListBox.Value = {'None'};
            app.ParametersButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            app.LoadData = uibutton(app.ModelTrainingUIFigure, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadData.Visible = 'off';
            app.LoadData.Text = 'Load Data';
            app.Tree = uitree(app.ModelTrainingUIFigure);
            app.SSMModelNode = uitreenode(app.Tree);
            app.SSMModelNode.Text = 'SSM Model';
            app.SSMBNNModelNode = uitreenode(app.Tree);
            app.SSMBNNModelNode.Text = 'SSM-BNN Model';
            app.RemoveButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '<< Remove';
            app.AssignButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Text = 'Assign >>';
            app.StartYearSpinner = uispinner(app.ModelTrainingUIFigure);
            app.StartYearSpinner.Limits = [2005 Inf];
            app.StartYearSpinner.Value = 2005;
            app.InspectionsstartedatLabel = uilabel(app.ModelTrainingUIFigure);
            app.InspectionsstartedatLabel.Text = 'Inspections started at:';
            app.YearLabel = uilabel(app.ModelTrainingUIFigure);
            app.YearLabel.Text = 'Year';
            app.TestsetSpinnerLabel = uilabel(app.ModelTrainingUIFigure);
            app.TestsetSpinnerLabel.Text = 'Test set';
            app.TestsetSpinner = uispinner(app.ModelTrainingUIFigure);
            app.TestsetSpinner.Limits = [0 50];
            app.TestsetSpinner.Value = 5;
            app.TestsetSpinnerLabel_2 = uilabel(app.ModelTrainingUIFigure);
            app.TestsetSpinnerLabel_2.Text = '%';
            app.ResetButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainingOptionsButtonGroup = uibuttongroup(app.ModelTrainingUIFigure);
            app.TrainingOptionsButtonGroup.Title = 'Training Options';
            app.IndependentOption = uiradiobutton(app.TrainingOptionsButtonGroup);
            app.IndependentOption.Text = 'Independent';
            app.JointOption = uiradiobutton(app.TrainingOptionsButtonGroup);
            app.JointOption.Text = 'Joint';
            app.JointOption.Value = true;
            app.ResumeTrainingButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.ResumeTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeTrainingButtonPushed, true);
            app.ResumeTrainingButton.Enable = 'off';
            app.ResumeTrainingButton.Visible = 'off';
            app.ResumeTrainingButton.Text = 'Resume Training';
            app.TrainModelButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.Switch = uiswitch(app.ModelTrainingUIFigure, 'slider');
            app.Switch.Items = {'Synthetic', 'Real'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Real';
            app.ExportDataButton = uibutton(app.ModelTrainingUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            % Show the figure after all components are created
            app.ModelTrainingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelTraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelTrainingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelTrainingUIFigure)
        end
    end
end