classdef CleanAttributeData_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CleanAttributes                 matlab.ui.Figure
        RemovevalueslessthanEditField   matlab.ui.control.NumericEditField
        ApplyFilterButton               matlab.ui.control.Button
        RemoveValuesGreaterthanEditField  matlab.ui.control.NumericEditField
        ResetAllFiltersButton           matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        SelectedAttributeDropDownLabel  matlab.ui.control.Label
        SelectedAttributeDropDown       matlab.ui.control.DropDown
        TransformAttributeDropDownLabel  matlab.ui.control.Label
        TransformAttributeDropDown      matlab.ui.control.DropDown
        RemoveValuesGreaterthanCheckBox  matlab.ui.control.CheckBox
        RemoveValuesLessthanCheckBox    matlab.ui.control.CheckBox
        HistogramButton                 matlab.ui.control.Button
        OrderintheListSpinner           matlab.ui.control.Spinner
        ChangeorderinthelistCheckBox    matlab.ui.control.CheckBox
        RemoveNaNsCheckBox              matlab.ui.control.CheckBox
        NumNaN                          matlab.ui.control.Label
        Loadlables                      matlab.ui.control.Button
        InferNaNsCheckBox               matlab.ui.control.CheckBox
        ShowHistogram                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        StructuralAtt % Structural Attributes
        FilteredStructuralAtt % Filtered Structural Attributes
        ModStructuralAtt % Mod Structural Attributes
        RemovedIndex % Filtered Indexes
        UpdateIndex % Updated index
        NaNIndex % Index of nan/missing values
        AttributesLabels % Labels for structural attributes
        InferedIndex % Index structural attributes
        InferedValues  % Infered structural attributes
        Length_Train
        Length_Valid
        Length_Test
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global NTr AnalysisSpace OptBoundsData UpdatedInspectorsData MdataEngy
            global DataTransformedSpace  KernelParameters ParCompVal VIDataSTAtt
            global TestPercent AnalysesStartYear
            % Analysis Compute options
            f = waitbar(0,'Please wait...');
            pause(.5)
            if strcmp(ParCompVal,'Sequential')
                ParComp=0;
            elseif strcmp(ParCompVal,'Parallel')
                ParComp=1;
            else
                ParComp=2;
            end
            if AnalysisSpace==1
                NTr=[];
            end
            UpdatedInspectorsData{1}(:,2)=OptBoundsData(2,1);
            IncludeStructuralAtt=1;
            NumStructuralAtt=KernelParameters{4};
            set(app.SelectedAttributeDropDown,'Items',[string(num2cell(1:NumStructuralAtt))';'Avg. Observations'])
            waitbar(.33,f,'Loading Structural Attributes');
            pause(0.5)
            if isempty(MdataEngy)
                ThisYear=date;
                ThisYear=str2double(ThisYear(end-3:end));
                TrainingWindow=ThisYear-AnalysesStartYear;
                [MdataEngy]=OrgnizeData(VIDataSTAtt,90E10,[0 0],UpdatedInspectorsData{1},...
                    DataTransformedSpace,NTr,OptBoundsData,TrainingWindow,ParComp,...
                    IncludeStructuralAtt,NumStructuralAtt,TestPercent);
            elseif ~isempty(app.AttributesLabels)
                set(app.SelectedAttributeDropDown,'Tooltip',[string(1:length(app.AttributesLabels))'+ ':'+' '+string(app.AttributesLabels)]);
            end
            app.Length_Train=size(MdataEngy.StrucAtt,2);
            app.Length_Valid=size(MdataEngy.ModelValid.StrucAtt,2)+app.Length_Train;
            app.Length_Test=size(MdataEngy.ModelTest.StrucAtt,2)+app.Length_Valid;
            app.StructuralAtt=cat(2,MdataEngy.StrucAtt,MdataEngy.ModelValid.StrucAtt,MdataEngy.ModelTest.StrucAtt);
            Init_X_val=cat(2,MdataEngy.init_x,MdataEngy.ModelValid.init_x,MdataEngy.ModelTest.init_x);
            app.StructuralAtt=cat(3,app.StructuralAtt, Init_X_val);
            app.UpdateIndex=0;
            waitbar(.67,f,'Preparing your data');
            pause(0.5)
            
            waitbar(1,f,'Finishing');
            pause(0.5)
            
            close(f)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            global MdataEngy
            if ~app.UpdateIndex
                app.FilteredStructuralAtt=app.StructuralAtt;
            end
            MdataEngy.StrucAtt=app.FilteredStructuralAtt(1,1:app.Length_Train,:);
            MdataEngy.ModelValid.StrucAtt=app.FilteredStructuralAtt(1,app.Length_Train+1:app.Length_Valid,:);
            MdataEngy.ModelTest.StrucAtt=app.FilteredStructuralAtt(1,app.Length_Valid+1:app.Length_Test,:);
            MdataEngy.NaNRemoved=app.NaNIndex;
            close(app.CleanAttributes);
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
        Transformation=app.TransformAttributeDropDown.Value;
            IndexVal=find(strcmp(get(app.SelectedAttributeDropDown,'Value'),get(app.SelectedAttributeDropDown,'Items')));
            if ~app.UpdateIndex
                app.FilteredStructuralAtt=app.StructuralAtt;
            end
            if app.RemoveNaNsCheckBox.Value
                app.NaNIndex=(isnan(app.FilteredStructuralAtt(1,:,IndexVal)));
                app.FilteredStructuralAtt(:,app.NaNIndex,:)=[];
                app.UpdateIndex=1;
            elseif app.InferNaNsCheckBox.Value
                app.NaNIndex=(isnan(app.FilteredStructuralAtt(1,:,IndexVal)));
                IndexData=find(gather(app.NaNIndex));
                IV=gather(app.FilteredStructuralAtt(:,app.NaNIndex,:));
                AV=gather(app.FilteredStructuralAtt(:,:,:));
                IV=reshape(IV,length(IV(1,:,1)),length(IV(1,1,:)));
                AV=reshape(AV,length(AV(1,:,1)),length(AV(1,1,:)));
                IV=IV./max(abs(AV));
                AV=AV./max(abs(AV));
                for i=1:length(IV(:,1))
                    Measure = sqrt(nansum((AV-IV(i,:)).^2,2));
                    Measure(app.NaNIndex)=99999;
                    [~,SimlarityIndex]=sort(Measure);
                    IndexSimilar=SimlarityIndex(1);
                    %UpdateValues=find(isnan(app.FilteredStructuralAtt(:,IndexData(i),:)));
                    app.InferedValues(i,:)=gather(app.FilteredStructuralAtt(:,IndexSimilar,:));
                end
                app.InferedIndex=app.NaNIndex;
                app.NaNIndex=[];
                app.UpdateIndex=1;
            end
            switch Transformation
                case 'exp()'
                    app.FilteredStructuralAtt(1,:,IndexVal)=exp(app.FilteredStructuralAtt(1,:,IndexVal));
                    app.UpdateIndex=1;
                case 'Log(abs())'
                    MinIndex=find(abs(app.FilteredStructuralAtt(1,:,IndexVal))>0);
                    MinVal=min(app.FilteredStructuralAtt(1,MinIndex,IndexVal));
                    ZeroIndex=find((app.FilteredStructuralAtt(1,:,IndexVal))==0);
                    app.FilteredStructuralAtt(1,ZeroIndex,IndexVal)=MinVal;
                    app.FilteredStructuralAtt(1,:,IndexVal)=log(abs(app.FilteredStructuralAtt(1,:,IndexVal)));
                    app.UpdateIndex=1;
                case 'Abs()'
                    app.FilteredStructuralAtt(1,:,IndexVal)=abs(app.FilteredStructuralAtt(1,:,IndexVal));
                    app.UpdateIndex=1;
                case 'No Transformation'
                    if isempty(app.RemovedIndex) && isempty(app.NaNIndex) && isempty(app.InferedIndex)
                        app.FilteredStructuralAtt(1,:,IndexVal)=app.StructuralAtt(1,:,IndexVal);
                    elseif isempty(app.RemovedIndex) && ~isempty(app.NaNIndex)
                        app.FilteredStructuralAtt(1,:,IndexVal)=app.StructuralAtt(1,~app.NaNIndex,IndexVal);
                    elseif isempty(app.RemovedIndex) && ~isempty(app.InferedIndex)
                        app.FilteredStructuralAtt(1,:,IndexVal)=app.StructuralAtt(1,:,IndexVal);
                        app.FilteredStructuralAtt(1,app.InferedIndex,IndexVal)=app.InferedValues(:,IndexVal);
                    else
                        app.FilteredStructuralAtt(1,~logical(app.RemovedIndex),IndexVal)=app.StructuralAtt(1,~logical(app.RemovedIndex),IndexVal);
                    end
            end
            
            if app.RemoveValuesLessthanCheckBox.Value
                RemoveLess=app.RemovevalueslessthanEditField.Value;
                RemoveIndex1=gather(app.FilteredStructuralAtt(1,:,IndexVal)<RemoveLess);
                app.FilteredStructuralAtt(:,RemoveIndex1,:)=[];
                app.UpdateIndex=1;
                app.RemovedIndex=[app.RemovedIndex; RemoveIndex1'];
                app.FilteredStructuralAtt(1,~logical(app.RemovedIndex),IndexVal)=app.StructuralAtt(1,~logical(app.RemovedIndex),IndexVal);
                app.RemovedIndex = [];
            end
            if app.RemoveValuesGreaterthanCheckBox.Value
                RemoveGreat=app.RemoveValuesGreaterthanEditField.Value;
                RemoveIndex2=gather(app.FilteredStructuralAtt(1,:,IndexVal)>RemoveGreat);
                app.FilteredStructuralAtt(:,RemoveIndex2,:)=[];
                app.UpdateIndex=1;
                app.RemovedIndex=[app.RemovedIndex; RemoveIndex2'];
                app.FilteredStructuralAtt(1,~logical(app.RemovedIndex),IndexVal)=app.StructuralAtt(1,~logical(app.RemovedIndex),IndexVal);
                app.RemovedIndex = [];
            end
            app.ModStructuralAtt=app.FilteredStructuralAtt(1,:,IndexVal);
            if app.ChangeorderinthelistCheckBox.Value
                NewOrder=app.OrderintheListSpinner.Value;
                OldOrder=str2double(get(app.SelectedAttributeDropDown,'Value'));
                if isnan(OldOrder)
                    OldOrder=length(app.SelectedAttributeDropDown.Items);
                end
                FilteredData=app.FilteredStructuralAtt;
                app.FilteredStructuralAtt(1,:,NewOrder)=app.ModStructuralAtt;
                app.FilteredStructuralAtt(1,:,OldOrder)=FilteredData(1,:,NewOrder);
                OldAttList=get(app.SelectedAttributeDropDown,'Items');
                NewAttList=OldAttList;
                NewAttList{NewOrder}=OldAttList{OldOrder};
                NewAttList{OldOrder}=OldAttList{NewOrder};
                set(app.SelectedAttributeDropDown,'Items',NewAttList);
            end
            histogram(app.ShowHistogram,app.ModStructuralAtt);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.CleanAttributes)
        end

        % Button pushed function: ResetAllFiltersButton
        function ResetAllFiltersButtonPushed(app, event)
            app.RemovedIndex=[];    
            app.FilteredStructuralAtt=app.StructuralAtt;
            set(app.SelectedAttributeDropDown,'Items',[string(num2cell(1:length(app.SelectedAttributeDropDown.Items)-1))';'Avg. Observations'])
            app.UpdateIndex=0;
            app.NaNIndex=[];
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            IndexVal=find(strcmp(get(app.SelectedAttributeDropDown,'Value'),get(app.SelectedAttributeDropDown,'Items')));
            if ~app.UpdateIndex
                app.FilteredStructuralAtt=app.StructuralAtt;
            end
            app.ModStructuralAtt=app.FilteredStructuralAtt(1,:,IndexVal);
            histogram(app.ShowHistogram,app.ModStructuralAtt);
        end

        % Value changed function: SelectedAttributeDropDown
        function SelectedAttributeDropDownValueChanged(app, event)
            IndexVal=find(strcmp(get(app.SelectedAttributeDropDown,'Value'),get(app.SelectedAttributeDropDown,'Items')));
            if ~app.UpdateIndex
                app.FilteredStructuralAtt=app.StructuralAtt;
            end
            NumberofNan=sum(isnan(app.FilteredStructuralAtt(1,:,IndexVal)));
            app.NumNaN.Text=string(gather(NumberofNan));
        end

        % Button pushed function: Loadlables
        function LoadlablesButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [SALFileName,FilePath]=uigetfile(FilterSuffix,'Select Structural Attributes labels file');
                LabelsFile=load(fullfile(FilePath,SALFileName));
                LabelsFile=struct2cell(LabelsFile);
                app.AttributesLabels=LabelsFile{1};
                set(app.SelectedAttributeDropDown,'Tooltip',[string(1:length(app.AttributesLabels))'+ ':'+' '+string(app.AttributesLabels)]);
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.CleanAttributes,message,'Warning','Icon','warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CleanAttributes = uifigure('Visible', 'off');
            app.CleanAttributes.Name = 'View/Clean Attributes';
            app.RemovevalueslessthanEditField = uieditfield(app.CleanAttributes, 'numeric');
            app.ApplyFilterButton = uibutton(app.CleanAttributes, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.RemoveValuesGreaterthanEditField = uieditfield(app.CleanAttributes, 'numeric');
            app.ResetAllFiltersButton = uibutton(app.CleanAttributes, 'push');
            app.ResetAllFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllFiltersButtonPushed, true);
            app.ResetAllFiltersButton.Text = 'Reset All Filters';
            app.OKButton = uibutton(app.CleanAttributes, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.CleanAttributes, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectedAttributeDropDownLabel = uilabel(app.CleanAttributes);
            app.SelectedAttributeDropDownLabel.Text = 'Selected Attribute';
            app.SelectedAttributeDropDown = uidropdown(app.CleanAttributes);
            app.SelectedAttributeDropDown.Items = {'1'};
            app.SelectedAttributeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedAttributeDropDownValueChanged, true);
            app.SelectedAttributeDropDown.Value = '1';
            app.TransformAttributeDropDownLabel = uilabel(app.CleanAttributes);
            app.TransformAttributeDropDownLabel.Text = 'Transform Attribute';
            app.TransformAttributeDropDown = uidropdown(app.CleanAttributes);
            app.TransformAttributeDropDown.Items = {'No Transformation', 'Log(abs())', 'exp()', 'Abs()'};
            app.TransformAttributeDropDown.Value = 'No Transformation';
            app.RemoveValuesGreaterthanCheckBox = uicheckbox(app.CleanAttributes);
            app.RemoveValuesGreaterthanCheckBox.Text = 'Remove Values Greater than';
            app.RemoveValuesLessthanCheckBox = uicheckbox(app.CleanAttributes);
            app.RemoveValuesLessthanCheckBox.Text = 'Remove Values Less than';
            app.HistogramButton = uibutton(app.CleanAttributes, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.OrderintheListSpinner = uispinner(app.CleanAttributes);
            app.OrderintheListSpinner.Limits = [1 Inf];
            app.OrderintheListSpinner.Value = 1;
            app.ChangeorderinthelistCheckBox = uicheckbox(app.CleanAttributes);
            app.ChangeorderinthelistCheckBox.Text = 'Change order in the list';
            app.RemoveNaNsCheckBox = uicheckbox(app.CleanAttributes);
            app.RemoveNaNsCheckBox.Text = 'Remove NaN''s';
            app.NumNaN = uilabel(app.CleanAttributes);
            app.NumNaN.Text = '-';
            app.Loadlables = uibutton(app.CleanAttributes, 'push');
            app.Loadlables.ButtonPushedFcn = createCallbackFcn(app, @LoadlablesButtonPushed, true);
            app.Loadlables.Text = '...';
            app.InferNaNsCheckBox = uicheckbox(app.CleanAttributes);
            app.InferNaNsCheckBox.Text = 'Infer NaN''s';
            app.ShowHistogram = uiaxes(app.CleanAttributes);
            title(app.ShowHistogram, 'Histogram')
            xlabel(app.ShowHistogram, 'Attribute')
            ylabel(app.ShowHistogram, 'Frequency')
            % Show the figure after all components are created
            app.CleanAttributes.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CleanAttributeData_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CleanAttributes)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CleanAttributes)
        end
    end
end