classdef OptimizationVID_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainMenuFig                    matlab.ui.Figure
        DeveloperMenu                  matlab.ui.container.Menu
        UnitTestsMenu                  matlab.ui.container.Menu
        Untitled_3                     matlab.ui.container.Menu
        AboutModelTrainingToolboxMenu  matlab.ui.container.Menu
        uipanel2                       matlab.ui.container.Panel
        SelectOptimization             matlab.ui.control.DropDown
        SetBounds                      matlab.ui.control.Button
        text22                         matlab.ui.control.Label
        SelectSpace                    matlab.ui.control.DropDown
        text33                         matlab.ui.control.Label
        SelectOptAlgorithm             matlab.ui.control.DropDown
        text22_2                       matlab.ui.control.Label
        nLabel                         matlab.ui.control.Label
        nSpinner                       matlab.ui.control.Spinner
        uipanel4                       matlab.ui.container.Panel
        LoadInspections                matlab.ui.control.Button
        DataFileName                   matlab.ui.control.EditField
        ViewInspectors                 matlab.ui.control.Button
        InspDataPath                   matlab.ui.control.EditField
        DTransformedSpace              matlab.ui.control.CheckBox
        StatusBar                      matlab.ui.control.Label
        uipanel1_2                     matlab.ui.container.Panel
        LoglikelihoodPlot              matlab.ui.control.CheckBox
        LogParams                      matlab.ui.control.CheckBox
        ContRun                        matlab.ui.control.Button
        RunAssessment                  matlab.ui.control.Button
        LogParams_2                    matlab.ui.control.CheckBox
        GraphsPanel                    matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        OutputPlot1                    matlab.ui.control.UIAxes
        OutputPlot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Public Variables 
        VIData
        IncludeStructuralAttChkBox
        NumberofStructuralAttSpinner
        InspectorsID
        Data_FileName
        TrueInspectorsData=[];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function OptimizationVID_OpeningFcn(app)
            
        end

        % Menu selected function: AboutModelTrainingToolboxMenu
        function AboutModelTrainingToolboxMenu_Callback(app, event)
            uialert(app.MainMenuFig,[{'Model Training Toolbox'};{''};{'Developed By:'};{''};{'Zachary A. Hamida, Ph.D., Webpage:zachamida.github.io'};{''};{'Blanche Laurent, M.Eng., Email:blanche.laurent@laposte.net'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end

        % Callback function
        function AnalysesSingleTimeSeries_Callback(app, event)
            
        end

        % Callback function
        function BridgeNetworkMap_Callback(app, event)
            
        end

        % Button pushed function: ContRun
        function ContRun_Callback(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [ParametersFileName,FilePath]=uigetfile(FilterSuffix,'Select Parameters .mat file');
                ModelParameters=load(sprintf('%s\%s',FilePath,ParametersFileName));
                ModelParameters=struct2cell(ModelParameters);
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.MainMenuFig,message,'Warning','Icon','warning');
            end
            try
                FilterSuffix={'*.mat';'*.*'};
                [InspectorsParamFileName,FilePath]=uigetfile(FilterSuffix,'Select inspectors .mat file',FilePath);
                InspectorsParam=load(sprintf('%s\%s',FilePath,InspectorsParamFileName));
                InspectorsParam=struct2cell(InspectorsParam);
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.MainMenuFig,message,'Warning','Icon','warning');
            end
            try
                OperationIndex=find(strcmp(app.SelectOptimization.Value,app.SelectOptimization.Items));
                if OperationIndex>1
                    FilterSuffix={'*.mat';'*.*'};
                    [KRParamFileName,FilePath]=uigetfile(FilterSuffix,'Select Regression Model Parameters .mat file (Optional)',FilePath);
                    if KRParamFileName~=0
                        RegressionModel=load(sprintf('%s\%s',FilePath,KRParamFileName));
                        RegressionModel=struct2cell(RegressionModel);
                        RegressionModel=RegressionModel{1};
                    else
                        RegressionModel=[];
                    end
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.MainMenuFig,message,'Warning','Icon','warning');
            end
            try
                UpdatedInspectorsData = app.InspectorsID;
                NTr=get(app.nSpinner,'Value');
                AnalysisSpace=find(strcmp(app.SelectSpace.Value,app.SelectSpace.Items));
                DataTransformedSpace=get(app.DTransformedSpace,'value');
                RunAnalysis();
            catch
                message = 'Model training has failed due to an unexpected error, make sure that all required input files are loaded and configured correctly, in addition to having access to GPU and Parallel Computing resources.';
                uialert(app.MainMenuFig,message,'Error','Icon','error');
            end
        end

        % Callback function
        function ExitApp_Callback(app, event)

            close(app.MainMenuFig)
        end

        % Callback function
        function IPMultipleTimeSeries_Callback(app, event)
            
        end

        % Callback function
        function InspectorAnalysis_Callback(app, event)
            
        end

        % Button pushed function: LoadInspections
        function LoadInspections_Callback(app, event)
            global MdataEngy
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Visual Inspections data file');
                app.Data_FileName = VIDataFileName;
                set(app.DataFileName,'Value',sprintf('%s\%s',FilePath,VIDataFileName))
                app.VIData=load(sprintf('%s/%s',FilePath,VIDataFileName));
                app.VIData=struct2cell(app.VIData);
                MdataEngy=[];
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.MainMenuFig,message,'Warning','Icon','warning');
            end
        end

        % Callback function
        function PredictRealData_Callback(app, event)
            
        end

        % Callback function
        function PredictSyntheticData_Callback(app, event)
            
        end

        % Callback function
        function ReadInspectiondataMTQ_Callback(app, event)
            
        end

        % Button pushed function: RunAssessment
        function RunAssessment_Callback(app, event)
            % hObject    handle to RunAssessment (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ModelParameters=[];
            InspectorsParam=[];
            RegressionModel=[];
            try
                UpdatedInspectorsData = app.InspectorsID;
                NTr=get(app.nSpinner,'Value');
                AnalysisSpace=find(strcmp(app.SelectSpace.Value,app.SelectSpace.Items));
                DataTransformedSpace=get(app.DTransformedSpace,'value');
                RunAnalysis();
            catch
                message = 'Model training has failed due to an unexpected error, make sure that all required input files are loaded and configured correctly, in addition to having access to GPU and Parallel Computing resources.';
                uialert(app.MainMenuFig,message,'Error','Icon','error');
            end
        end

        % Value changed function: SelectSpace
        function SelectSpace_Callback(app, event)
            if strcmp(app.SelectSpace.Value,'None')
                app.nSpinner.Enable = 'off';
            else
                app.nSpinner.Enable = 'on';
            end
        end

        % Button pushed function: SetBounds
        function SetBounds_Callback(app, event)
            app.RunAssessment.Enable = 'On';
            app.ContRun.Enable = 'On';
            global ParCompVal VIDataSTAtt DataTransformedSpace UpdatedInspectorsData AnalysisSpace NTr
            if strcmp(app.SelectOptimization.Value,'SSM-KR') || strcmp(app.SelectOptimization.Value,'SSM-KR (+Inspector Bias)')
                if find(strcmp(app.SelectOptAlgorithm.Value,app.SelectOptAlgorithm.Items))==2
                    ParCompVal='Parallel';
                else
                    ParCompVal='GPU';
                end
                DataTransformedSpace = get(app.DTransformedSpace,'value');
                UpdatedInspectorsData = app.InspectorsID;
                try
                    VIDataSTAtt=app.VIData{1};
                    AnalysisSpace=find(strcmp(app.SelectSpace.Value,app.SelectSpace.Items));
                    NTr=get(app.nSpinner,'Value');
                    BoundsMenuKR_App()
                catch
                    message = 'Inspection data file is not loaded, or is not compatible';
                    uialert(app.MainMenuFig,message,'Error','Icon','error');
                end
            else
                BoundsMenu_App()
            end
        end

        % Callback function
        function SpaceTr_Callback(app, event)
            
        end

        % Callback function
        function SyntheticDataGeneratorTool_Callback(app, event)
            
        end

        % Callback function
        function TRsAnalysisSettings_Callback(app, event)
            
        end

        % Button pushed function: ViewInspectors
        function ViewInspectors_Callback(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Inspectors data file');
                set(app.InspDataPath,'Value',VIDataFileName)
                InspectorsData=load(sprintf('%s\%s',FilePath,VIDataFileName));
                InspectorsData=struct2cell(InspectorsData);
                app.InspectorsID=InspectorsData;
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.MainMenuFig,message,'Warning','Icon','warning');
            end
        end

        % Callback function
        function IncludeStructuralAttChkBoxValueChanged(app, event)
            value = app.IncludeStructuralAttChkBox.Value;
            if value
                app.NumberofStructuralAttSpinner.Enable=1;
            else
                app.NumberofStructuralAttSpinner.Enable=0;
            end
        end

        % Value changed function: LogParams_2
        function LogParams_2ValueChanged(app, event)
            plot_insp = app.LogParams_2.Value;
            SynData = app.DTransformedSpace.Value;
            if plot_insp && SynData
                FilterSuffix={'*.mat';'*.*'};
                [InspectorsDataFileName,FilePath]=uigetfile(FilterSuffix,'Select True inspectors Std.');
                app.TrueInspectorsData=load(sprintf('%s\%s',FilePath,InspectorsDataFileName));
                app.TrueInspectorsData=struct2cell(app.TrueInspectorsData);
            end
        end

        % Menu selected function: UnitTestsMenu
        function UnitTestsMenuSelected(app, event)
            results = runtests('Tools/ModelTraining_Generic/test_mtg.m');
            display(results)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainMenuFig = uifigure('Visible', 'off');
            app.MainMenuFig.Name = 'Model Training';
            app.DeveloperMenu = uimenu(app.MainMenuFig);
            app.DeveloperMenu.Text = 'Developer';
            app.UnitTestsMenu = uimenu(app.DeveloperMenu);
            app.UnitTestsMenu.MenuSelectedFcn = createCallbackFcn(app, @UnitTestsMenuSelected, true);
            app.UnitTestsMenu.Text = 'Unit Tests';
            app.Untitled_3 = uimenu(app.MainMenuFig);
            app.Untitled_3.Text = 'Help';
            app.AboutModelTrainingToolboxMenu = uimenu(app.Untitled_3);
            app.AboutModelTrainingToolboxMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutModelTrainingToolboxMenu_Callback, true);
            app.AboutModelTrainingToolboxMenu.Text = 'About Model Training Toolbox';
            app.uipanel2 = uipanel(app.MainMenuFig);
            app.uipanel2.Title = 'Configuration';
            app.SelectOptimization = uidropdown(app.uipanel2);
            app.SelectOptimization.Items = {'SSM', 'SSM-KR', 'SSM (+Inspector Bias)', 'SSM-KR (+Inspector Bias)'};
            app.SelectOptimization.Value = 'SSM';
            app.SetBounds = uibutton(app.uipanel2, 'push');
            app.SetBounds.ButtonPushedFcn = createCallbackFcn(app, @SetBounds_Callback, true);
            app.SetBounds.Text = 'Model Config.';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Text = 'Space Transformation:';
            app.SelectSpace = uidropdown(app.uipanel2);
            app.SelectSpace.Items = {'None', 'g(condition,n)'};
            app.SelectSpace.ValueChangedFcn = createCallbackFcn(app, @SelectSpace_Callback, true);
            app.SelectSpace.Value = 'g(condition,n)';
            app.text33 = uilabel(app.uipanel2);
            app.text33.Text = 'Parameters Estimation Algorithm:';
            app.SelectOptAlgorithm = uidropdown(app.uipanel2);
            app.SelectOptAlgorithm.Items = {'Newton Raphson (NR) (GPU+CPU)', 'NR + Online Inference (CPU)'};
            app.SelectOptAlgorithm.Value = 'Newton Raphson (NR) (GPU+CPU)';
            app.text22_2 = uilabel(app.uipanel2);
            app.text22_2.Text = 'Model:';
            app.nLabel = uilabel(app.uipanel2);
            app.nLabel.Text = 'n:';
            app.nSpinner = uispinner(app.uipanel2);
            app.nSpinner.Limits = [1 7];
            app.nSpinner.Value = 4;
            app.uipanel4 = uipanel(app.MainMenuFig);
            app.uipanel4.Title = 'Input Data';
            app.LoadInspections = uibutton(app.uipanel4, 'push');
            app.LoadInspections.ButtonPushedFcn = createCallbackFcn(app, @LoadInspections_Callback, true);
            app.LoadInspections.Text = 'Inspections';
            app.DataFileName = uieditfield(app.uipanel4, 'text');
            app.ViewInspectors = uibutton(app.uipanel4, 'push');
            app.ViewInspectors.ButtonPushedFcn = createCallbackFcn(app, @ViewInspectors_Callback, true);
            app.ViewInspectors.Text = 'Inspectors';
            app.InspDataPath = uieditfield(app.uipanel4, 'text');
            app.DTransformedSpace = uicheckbox(app.uipanel4);
            app.DTransformedSpace.Text = 'Synthetic Data';
            app.StatusBar = uilabel(app.MainMenuFig);
            app.StatusBar.Text = 'Status: Idle';
            app.uipanel1_2 = uipanel(app.MainMenuFig);
            app.uipanel1_2.Title = 'Analyses';
            app.LoglikelihoodPlot = uicheckbox(app.uipanel1_2);
            app.LoglikelihoodPlot.Text = 'PLot Loglikelihood';
            app.LogParams = uicheckbox(app.uipanel1_2);
            app.LogParams.Text = 'Save Paramaeters at each iteration';
            app.LogParams.Value = true;
            app.ContRun = uibutton(app.uipanel1_2, 'push');
            app.ContRun.ButtonPushedFcn = createCallbackFcn(app, @ContRun_Callback, true);
            app.ContRun.Enable = 'off';
            app.ContRun.Text = 'Cont. Run';
            app.RunAssessment = uibutton(app.uipanel1_2, 'push');
            app.RunAssessment.ButtonPushedFcn = createCallbackFcn(app, @RunAssessment_Callback, true);
            app.RunAssessment.Enable = 'off';
            app.RunAssessment.Text = 'Run';
            app.LogParams_2 = uicheckbox(app.uipanel1_2);
            app.LogParams_2.ValueChangedFcn = createCallbackFcn(app, @LogParams_2ValueChanged, true);
            app.LogParams_2.Text = 'Plot Inspectors Param';
            app.GraphsPanel = uipanel(app.MainMenuFig);
            app.GraphsPanel.Title = 'Graphs';
            app.GridLayout = uigridlayout(app.GraphsPanel);
            app.OutputPlot1 = uiaxes(app.GridLayout);
            xlabel(app.OutputPlot1, 'Time')
            ylabel(app.OutputPlot1, 'Test Set L.L.')
            app.OutputPlot1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.OutputPlot1.XTickLabel = {'2006'; '2008'; '2010'; '2012'; '2014'; '2016'};
            app.OutputPlot1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.OutputPlot1.YTickLabel = {'25'; '40'; '55'; '70'; '85'; '100'};
            app.OutputPlot = uiaxes(app.GridLayout);
            xlabel(app.OutputPlot, 'Inspector Estimated \sigma_{V(I_i)}')
            ylabel(app.OutputPlot, 'Inspector True \sigma_{V(I_i)}')
            app.OutputPlot.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.OutputPlot.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.OutputPlot.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.OutputPlot.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            % Show the figure after all components are created
            app.MainMenuFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizationVID_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainMenuFig)

            % Execute the startup function
            runStartupFcn(app, @OptimizationVID_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainMenuFig)
        end
    end
end