classdef Interventions_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterventionsAppUIFigure      matlab.ui.Figure
        DeveloperMenu                 matlab.ui.container.Menu
        UnitTestInterventionToolboxMenu  matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        AboutModelTrainingInterventionsMenu  matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        ModelInputTab                 matlab.ui.container.Tab
        uipanel1                      matlab.ui.container.Panel
        LoadInspectionDataBox         matlab.ui.control.EditField
        LoadInspectionData            matlab.ui.control.Button
        LoadEstimatedInspectorBox     matlab.ui.control.EditField
        LoadEstimatedInspectorData    matlab.ui.control.Button
        text2                         matlab.ui.control.Label
        text4                         matlab.ui.control.Label
        LoadRegressionModelTxtBox     matlab.ui.control.EditField
        LoadRegressionModelButton     matlab.ui.control.Button
        text4_2                       matlab.ui.control.Label
        LoadSSMModelButton            matlab.ui.control.Button
        LoadSSMModelTxtBox            matlab.ui.control.EditField
        text4_3                       matlab.ui.control.Label
        InterventionCategoryDropDownLabel  matlab.ui.control.Label
        InterventionCategoryDropDown  matlab.ui.control.DropDown
        RunTrainingButton             matlab.ui.control.Button
        SaveParamsButton              matlab.ui.control.Button
        gnLabel                       matlab.ui.control.Label
        gnSpinner                     matlab.ui.control.Spinner
        StartYearSpinnerLabel         matlab.ui.control.Label
        StartYearSpinner              matlab.ui.control.Spinner
        SyntheticDataCheckBox         matlab.ui.control.CheckBox
        ModelConfigTab                matlab.ui.container.Tab
        BoundsTable                   matlab.ui.control.Table
    end

    
    properties (Access = public)
        InspectionData      % Inspection Data with Interventions
        AllInspectors       % All inspectors IDs
        InspectorsParams    % Estimated Inspectors Params
        SSMParams           % SSM Model Params
        RegressionModel=[];     % KR Params
        int_Ex              % Mu,Var network
        int_param           % Intial Model Param
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                load(sprintf(['%s/Parameters/ModelTraining_Int_DefaultBounds.mat'],pwd));
                %load('DefaultBounds.mat');
                set(app.BoundsTable,'data',BoundsData);
            catch
                message = 'Initial parameters are not loaded, make sure that the file "DefaultBounds.mat" is accessible by the software';
                uialert(app.InterventionsAppUIFigure,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: RunTrainingButton
        function RunTrainingButtonPushed(app, event)
            try
                OptBoundsInt = app.BoundsTable.Data;
                IntCat = app.InterventionCategoryDropDown.Value;
                Ncurve = app.gnSpinner.Value;
                SynDataChk = app.SyntheticDataCheckBox.Value;
                training_interventions_cat();
            catch
                message = 'Model training has failed, make sure all the required input and parameters are loaded';
                uialert(app.InterventionsAppUIFigure,message,'Error','Icon','error');
            end
        end

        % Button pushed function: LoadInspectionData
        function LoadInspectionDataButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Inspections with interventions data file');
                set(app.LoadInspectionDataBox,'Value',VIDataFileName)
                app.InspectionData=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.InspectionData=struct2cell(app.InspectionData);
                app.InspectionData=app.InspectionData{1};
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.InterventionsAppUIFigure,message,'Warning','Icon','warning');
            end
        end

        % Callback function
        function LoadInspectorIDsButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select All Inspectors IDs data file');
                set(app.LoadInspectorIDDataBox,'Value',VIDataFileName)
                app.AllInspectors=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.AllInspectors=struct2cell(app.AllInspectors);
                app.AllInspectors=app.AllInspectors{1};
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.InterventionsAppUIFigure,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadEstimatedInspectorData
        function LoadEstimatedInspectorDataButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select the Estimated Inspectors Params');
                set(app.LoadEstimatedInspectorBox,'Value',VIDataFileName)
                app.InspectorsParams=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.InspectorsParams=struct2cell(app.InspectorsParams);
                app.InspectorsParams=app.InspectorsParams{1};
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.InterventionsAppUIFigure,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadSSMModelButton
        function LoadSSMModelButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select the Estimated SSM model Params');
                set(app.LoadSSMModelTxtBox,'Value',VIDataFileName)
                app.SSMParams=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.SSMParams=struct2cell(app.SSMParams);
                app.SSMParams=app.SSMParams{1};
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.InterventionsAppUIFigure,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadRegressionModelButton
        function LoadRegressionModelButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select the Estimated KR model Params');
                set(app.LoadRegressionModelTxtBox,'Value',VIDataFileName)
                app.RegressionModel=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.RegressionModel=struct2cell(app.RegressionModel);
                app.RegressionModel=app.RegressionModel{1};
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.InterventionsAppUIFigure,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: SaveParamsButton
        function SaveParamsButtonPushed(app, event)
            try
                Ex_Var = app.int_Ex;
                Int_Params = app.int_param;
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as',sprintf('Network_scale_effect'));
                save(fullfile(pathname,filename),'Ex_Var');
                filename=sprintf('Interventions_params');
                save(fullfile(pathname,filename),'Int_Params');
            catch
                message = 'Parameters are not saved, due to the parameters and/or the destination folder are not accessible';
                uialert(app.InterventionsAppUIFigure,message,'Warning','Icon','warning');
            end
        end

        % Menu selected function: 
        % AboutModelTrainingInterventionsMenu
        function AboutModelTrainingInterventionsMenuSelected(app, event)
            uialert(app.InterventionsAppUIFigure,[{'Model Training - Interventions Toolbox'};{''};{'Developed By: Zachary A. Hamida, Ph.D.'};{'Webpage:zachamida.github.io'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end

        % Menu selected function: UnitTestInterventionToolboxMenu
        function UnitTestInterventionToolboxMenuSelected(app, event)
            results = runtests('Tools/ModelTraining_Int/test_IMT.m');
            display(results)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterventionsAppUIFigure = uifigure('Visible', 'off');
            app.InterventionsAppUIFigure.Name = 'Interventions App';
            app.InterventionsAppUIFigure.Icon = 'OpenIPDM_icon_r.png';
            app.DeveloperMenu = uimenu(app.InterventionsAppUIFigure);
            app.DeveloperMenu.Text = 'Developer';
            app.UnitTestInterventionToolboxMenu = uimenu(app.DeveloperMenu);
            app.UnitTestInterventionToolboxMenu.MenuSelectedFcn = createCallbackFcn(app, @UnitTestInterventionToolboxMenuSelected, true);
            app.UnitTestInterventionToolboxMenu.Text = 'Unit Test Intervention Toolbox';
            app.AboutMenu = uimenu(app.InterventionsAppUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutModelTrainingInterventionsMenu = uimenu(app.AboutMenu);
            app.AboutModelTrainingInterventionsMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutModelTrainingInterventionsMenuSelected, true);
            app.AboutModelTrainingInterventionsMenu.Text = 'About Model Training - Interventions ';
            app.TabGroup = uitabgroup(app.InterventionsAppUIFigure);
            app.ModelInputTab = uitab(app.TabGroup);
            app.ModelInputTab.Title = 'Model Input';
            app.uipanel1 = uipanel(app.ModelInputTab);
            app.uipanel1.Title = 'Load Input Data';
            app.LoadInspectionDataBox = uieditfield(app.uipanel1, 'text');
            app.LoadInspectionData = uibutton(app.uipanel1, 'push');
            app.LoadInspectionData.ButtonPushedFcn = createCallbackFcn(app, @LoadInspectionDataButtonPushed, true);
            app.LoadInspectionData.Text = '...';
            app.LoadEstimatedInspectorBox = uieditfield(app.uipanel1, 'text');
            app.LoadEstimatedInspectorData = uibutton(app.uipanel1, 'push');
            app.LoadEstimatedInspectorData.ButtonPushedFcn = createCallbackFcn(app, @LoadEstimatedInspectorDataButtonPushed, true);
            app.LoadEstimatedInspectorData.Text = '...';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Inspections with Interventions';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Estimated Inspectors Params';
            app.LoadRegressionModelTxtBox = uieditfield(app.uipanel1, 'text');
            app.LoadRegressionModelButton = uibutton(app.uipanel1, 'push');
            app.LoadRegressionModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRegressionModelButtonPushed, true);
            app.LoadRegressionModelButton.Text = '...';
            app.text4_2 = uilabel(app.uipanel1);
            app.text4_2.Text = 'Regression Model';
            app.LoadSSMModelButton = uibutton(app.uipanel1, 'push');
            app.LoadSSMModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSSMModelButtonPushed, true);
            app.LoadSSMModelButton.Text = '...';
            app.LoadSSMModelTxtBox = uieditfield(app.uipanel1, 'text');
            app.text4_3 = uilabel(app.uipanel1);
            app.text4_3.Text = 'SSM Model Params.';
            app.InterventionCategoryDropDownLabel = uilabel(app.ModelInputTab);
            app.InterventionCategoryDropDownLabel.Text = 'Intervention Category';
            app.InterventionCategoryDropDown = uidropdown(app.ModelInputTab);
            app.InterventionCategoryDropDown.Items = {'1000', '2000', '3000'};
            app.InterventionCategoryDropDown.Value = '1000';
            app.RunTrainingButton = uibutton(app.ModelInputTab, 'push');
            app.RunTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrainingButtonPushed, true);
            app.RunTrainingButton.Text = 'Run Training';
            app.SaveParamsButton = uibutton(app.ModelInputTab, 'push');
            app.SaveParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamsButtonPushed, true);
            app.SaveParamsButton.Text = 'Save Params';
            app.gnLabel = uilabel(app.ModelInputTab);
            app.gnLabel.Text = 'g(.), n=';
            app.gnSpinner = uispinner(app.ModelInputTab);
            app.gnSpinner.Limits = [1 7];
            app.gnSpinner.Value = 4;
            app.StartYearSpinnerLabel = uilabel(app.ModelInputTab);
            app.StartYearSpinnerLabel.Text = 'Start Year';
            app.StartYearSpinner = uispinner(app.ModelInputTab);
            app.StartYearSpinner.Limits = [1900 3000];
            app.StartYearSpinner.Value = 2008;
            app.SyntheticDataCheckBox = uicheckbox(app.ModelInputTab);
            app.SyntheticDataCheckBox.Text = 'Synthetic Data';
            app.ModelConfigTab = uitab(app.TabGroup);
            app.ModelConfigTab.Title = 'Model Config.';
            app.BoundsTable = uitable(app.ModelConfigTab);
            app.BoundsTable.ColumnName = {'Init.'; 'Min.'; 'Max.'};
            app.BoundsTable.RowName = {'SigmaW1 (cond.)'; 'SigmaW2 (speed.)'; 'SigmaW3 (acc.)'; 'Sigma (cond.)'; 'Sigma (speed.)'; 'Sigma (acc.)'; 'Mu (cond.)'; 'Mu (speed)'; 'Mu (acc.)'};
            app.BoundsTable.ColumnEditable = true;
            % Show the figure after all components are created
            app.InterventionsAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interventions_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterventionsAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterventionsAppUIFigure)
        end
    end
end