classdef RealDataModelParameters_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        DeveloperMenu              matlab.ui.container.Menu
        UniteTestDeteriorationModelMenu  matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        AboutModelValidationMenu   matlab.ui.container.Menu
        LoadRealInspectors         matlab.ui.control.Button
        uipanel2                   matlab.ui.container.Panel
        EstimatedParameters        matlab.ui.control.Table
        LoadRealInspectorsBox      matlab.ui.control.EditField
        PlotHistogram              matlab.ui.control.Button
        text2                      matlab.ui.control.Label
        text3                      matlab.ui.control.Label
        popupmenu1                 matlab.ui.control.DropDown
        text4                      matlab.ui.control.Label
        LoadInspectorsIDs          matlab.ui.control.Button
        InspectorsIDs              matlab.ui.control.EditField
        text5                      matlab.ui.control.Label
        LoadRegressionModelButton  matlab.ui.control.Button
        LoadRegressionModel        matlab.ui.control.EditField
        text5_2                    matlab.ui.control.Label
        LoadDataBeforetxt          matlab.ui.control.EditField
        LoadDataBefore             matlab.ui.control.Button
        LoadDataAftertxt           matlab.ui.control.EditField
        LoadDataAfter              matlab.ui.control.Button
        text3_2                    matlab.ui.control.Label
        text5_3                    matlab.ui.control.Label
        RunAnalyses                matlab.ui.control.Button
        text9                      matlab.ui.control.Label
        NumRealElm                 matlab.ui.control.EditField
        PlotTimeSeries             matlab.ui.control.CheckBox
        LLEditFieldLabel           matlab.ui.control.Label
        LLEditField                matlab.ui.control.NumericEditField
        RegmodelDropDownLabel      matlab.ui.control.Label
        RegmodelDropDown           matlab.ui.control.DropDown
        InspectorHist              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ActiveParametersSet=[]                 % The selected set of parameters (Table row)
        TableEstimatedParameters=[]            % Table data
        RealInspectorsID=[]                    % All Inspectors in the dataset
        RealInspectorsEstimatedSigma=[]        % Estimated Inspectors parameters
        RegressionModel=[];                     % Regression Model Parameters
        MdataEngy 
        SSPDsorted
        StructureElements
        BatchMode
        OldIds
        NewIds
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RealDataModelParameters_OpeningFcn(app)
            try
                load(sprintf(['%s/Parameters/PredictRealandSynthetic_DefaultRealParameters.mat'],pwd));
                set(app.EstimatedParameters,'Data',DefaultRealParameters);
            catch
                message = 'Loading default real data parameters has failed, please ensure that the default parameters file is in the toolbox folder.';
                uialert(app.figure1,message,'Error','Icon','error');
            end
            
        end

        % Callback function
        function CancelModelParameters_Callback(app, event)

            close(app.figure1);
        end

        % Button pushed function: LoadInspectorsIDs
        function LoadInspectorsIDs_Callback(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select inspectors ID file');
                set(app.InspectorsIDs,'Value',VIDataFileName)
                app.RealInspectorsID=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.RealInspectorsID=struct2cell(app.RealInspectorsID);
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadRealInspectors
        function LoadRealInspectors_Callback(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select estimated inspector parameters file');
                set(app.LoadRealInspectorsBox,'Value',VIDataFileName)
                app.RealInspectorsEstimatedSigma=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.RealInspectorsEstimatedSigma=struct2cell(app.RealInspectorsEstimatedSigma);
                app.RealInspectorsEstimatedSigma=app.RealInspectorsEstimatedSigma{1}{1};
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: PlotHistogram
        function PlotHistogram_Callback(app, event)
            try
                histogram(app.InspectorHist,app.RealInspectorsEstimatedSigma(:,3));
                app.InspectorHist.XLabel.String='Estimated Inspectors Uncertainty  \sigma_v(I_i)';
                app.InspectorHist.YLabel.String='Frequency';
                box(app.InspectorHist,"on");
            catch
                message = 'Inspectors data are not loaded';
                uialert(app.figure1,message,'Error','Icon','error');
            end
        end

        % Callback function
        function SaveModelParameters_Callback(app, event)
            
        end

        % Button pushed function: LoadRegressionModelButton
        function LoadRegressionModelButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                if strcmp(app.RegmodelDropDown.Value,'KR')
                    [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the Regression Model Parameters');
                    set(app.LoadRegressionModel,'Value',VIDataFileName)
                    app.RegressionModel=load(sprintf('%s\%s',FilePath,VIDataFileName));
                    app.RegressionModel=struct2cell(app.RegressionModel);
                else
                    [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the Ann Model Parameters');
                    set(app.LoadRegressionModel,'Value',VIDataFileName)
                    app.RegressionModel=load(sprintf('%s\%s',FilePath,VIDataFileName));
                    app.RegressionModel=struct2cell(app.RegressionModel);
                    addpath(sprintf('%s/Tools/ModelTraining/OpenTAGI/src/matlab',pwd))
                    addpath(sprintf('%s/Tools/PredictRealandSynthetic/TAGI',pwd))
                end
                app.RegressionModel = cell2mat(app.RegressionModel);
%                 if VIDataFileName~=0
%                     set(app.LoadRegressionModel,'Value',VIDataFileName)
%                     app.RegressionModel=load(sprintf('%s\%s',FilePath,VIDataFileName));
%                     app.RegressionModel=struct2cell(app.RegressionModel);
%                     app.RegressionModel=app.RegressionModel{1};
%                 else
%                     app.RegressionModel=[];
%                 end
            catch
                app.RegressionModel=[];
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: RunAnalyses
        function RunAnalysesButtonPushed(app, event)
            try
                app.ActiveParametersSet=find(strcmp(app.popupmenu1.Value,app.popupmenu1.Items));
                GetTableEstimatedParameters=get(app.EstimatedParameters,'Data');
                EstimatedSigma=app.RealInspectorsEstimatedSigma;
                if size(app.RealInspectorsID{1},1)~=size(EstimatedSigma,1)
                    AverageInspector=mean(EstimatedSigma(:,3));
                    for i=1:length(EstimatedSigma)
                        FindIndex=find(app.RealInspectorsID{1}==EstimatedSigma(i,1));
                        if ~isempty(FindIndex)
                            EstInd(i)=find(app.RealInspectorsID{1}==EstimatedSigma(i,1));
                        end
                    end
                    EstInd(find(EstInd==0))=[];
                    OutInd=1:length(app.RealInspectorsID{1});
                    OutInd(EstInd)=[];
                    EstimatedSigmaFinal=[zeros(length(app.RealInspectorsID{1}),2) app.RealInspectorsID{1}];
                    if ~isempty(EstInd)
                        EstimatedSigmaFinal(EstInd,2)=EstimatedSigma(:,3);
                        EstimatedSigmaFinal(EstInd,1)=EstimatedSigma(:,2);
                    end
                    if ~isempty(OutInd)
                        EstimatedSigmaFinal(OutInd,2)=AverageInspector;
                    end
                    app.RealInspectorsEstimatedSigma=EstimatedSigmaFinal;
                end
            catch
                message = 'Input data is NOT loaded, or the input files are not compatible.';
                uialert(app.figure1,message,'Error','Icon','error');
            end
            if isempty(app.RealInspectorsEstimatedSigma)
                message = 'Inspectors parameters are NOT loaded!';
                uialert(app.figure1,message,'Error','Icon','error');
            else
                if app.ActiveParametersSet<=size(GetTableEstimatedParameters,1)
                    if isempty(GetTableEstimatedParameters(app.ActiveParametersSet,1))
                        app.ActiveParametersSet=1;
                        message = 'Selected active set of parameters is EMPTY!.';
                        uialert(app.figure1,message,'Error','Icon','error');
                    else
                        if isnumeric(GetTableEstimatedParameters)
                            app.TableEstimatedParameters=(GetTableEstimatedParameters(app.ActiveParametersSet,:));
                        else
                            app.TableEstimatedParameters=cell2mat(GetTableEstimatedParameters(app.ActiveParametersSet,:));
                        end
                        if sum(isnan(app.TableEstimatedParameters))>=1
                            message = 'One or more of the parameters is inserted incorrectly, loading parameters failed';
                            uialert(app.figure1,message,'Error','Icon','error');
                        elseif sum(isempty(app.TableEstimatedParameters))>=1
                            message = 'Selected active set of parameters is EMPTY!.';
                            uialert(app.figure1,message,'Error','Icon','error');
                        else
                            AllParameters.ActiveParametersSet=app.ActiveParametersSet;
                            AllParameters.TableEstimatedParameters=app.TableEstimatedParameters;
                            AllParameters.RealInspectorsEstimatedSigma=app.RealInspectorsEstimatedSigma;
                            AllParameters.RealInspectorsID=app.RealInspectorsID;
                            AllParameters.RegressionModel=app.RegressionModel;
                        end
                    end
                else
                    message = 'Selected active set of parameters is EMPTY!.';
                    uialert(app.figure1,message,'Error','Icon','error');
                end
            end
            % Number of low noise structural elements
            try
                NumRealElements=str2double(get(app.NumRealElm,'Value'));
%                 app.synthetic_analysis = false;
                RunValidationRealData
            catch
                message = 'Validation has failed due to an unexpected error, please verify the input database file is correct and compatible with this toolbox requirements.';
                uialert(app.figure1,message,'Error','Icon','error');
            end
        end

        % Value changed function: PlotTimeSeries
        function PlotTimeSeriesValueChanged(app, event)
            PlotIt=get(app.PlotTimeSeries,'Value');
            if PlotIt==0
                set(app.NumRealElm,'Enable','off')
            else
                set(app.NumRealElm,'Enable','on')
            end
        end

        % Button pushed function: LoadDataBefore
        function LoadDataBeforeButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Old Real Inspections data file');
                set(app.LoadDataBeforetxt,'Value',VIDataFileName)
                app.MdataEngy=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.MdataEngy=struct2cell(app.MdataEngy);
                app.MdataEngy=app.MdataEngy{1};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select structures ID data file');
                app.OldIds=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.OldIds=struct2cell(app.OldIds);
                app.OldIds=app.OldIds{1};
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadDataAfter
        function LoadDataAfterButtonPushed(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select New Real Inspections data file');
                set(app.LoadDataAftertxt,'Value',VIDataFileName)
                app.SSPDsorted=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.SSPDsorted=struct2cell(app.SSPDsorted);
                app.SSPDsorted=app.SSPDsorted{1};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select structures ID data file');
                app.NewIds=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.NewIds=struct2cell(app.NewIds);
                app.NewIds=app.NewIds{1};
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Menu selected function: AboutModelValidationMenu
        function AboutModelValidationMenuSelected(app, event)
            uialert(app.figure1,[{'Model Validation Toolbox'};{''};{'Developed By: Zachary A. Hamida, Ph.D.'};{'Webpage:zachamida.github.io'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end

        % Menu selected function: UniteTestDeteriorationModelMenu
        function UniteTestDeteriorationModelMenuSelected(app, event)
            results = runtests('test_vvt_model');
            display(results)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Real Data Validation';
            app.figure1.Icon = 'OpenIPDM_icon_r.png';
            app.DeveloperMenu = uimenu(app.figure1);
            app.DeveloperMenu.Text = 'Developer';
            app.UniteTestDeteriorationModelMenu = uimenu(app.DeveloperMenu);
            app.UniteTestDeteriorationModelMenu.MenuSelectedFcn = createCallbackFcn(app, @UniteTestDeteriorationModelMenuSelected, true);
            app.UniteTestDeteriorationModelMenu.Text = 'Unite Test Deterioration Model';
            app.AboutMenu = uimenu(app.figure1);
            app.AboutMenu.Text = 'About';
            app.AboutModelValidationMenu = uimenu(app.AboutMenu);
            app.AboutModelValidationMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutModelValidationMenuSelected, true);
            app.AboutModelValidationMenu.Text = 'About Model Validation';
            app.LoadRealInspectors = uibutton(app.figure1, 'push');
            app.LoadRealInspectors.ButtonPushedFcn = createCallbackFcn(app, @LoadRealInspectors_Callback, true);
            app.LoadRealInspectors.Text = '...';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Model Parameters';
            app.EstimatedParameters = uitable(app.uipanel2);
            app.EstimatedParameters.ColumnName = {'Sigma_W'; 'Sigma_Cond'; 'Sigma_Speed'; 'Sigma_Acc'; 'mu_Speed'; 'mu_Acc'; 'Min(Speed)'; 'n'};
            app.EstimatedParameters.RowName = {'Estimated Set 1'; 'Estimated Set 2'; 'Estimated Set 3'; 'Estimated Set 4'};
            app.EstimatedParameters.ColumnEditable = [true true true true true true true true];
            app.LoadRealInspectorsBox = uieditfield(app.figure1, 'text');
            app.PlotHistogram = uibutton(app.figure1, 'push');
            app.PlotHistogram.ButtonPushedFcn = createCallbackFcn(app, @PlotHistogram_Callback, true);
            app.PlotHistogram.Text = 'Histogram';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Load Inspectors Parameters';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Active Parameters Set';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'1', '2', '3', '4'};
            app.popupmenu1.Value = '1';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = {'*Change sets for '; 'sensetivity analysis'};
            app.LoadInspectorsIDs = uibutton(app.figure1, 'push');
            app.LoadInspectorsIDs.ButtonPushedFcn = createCallbackFcn(app, @LoadInspectorsIDs_Callback, true);
            app.LoadInspectorsIDs.Text = '...';
            app.InspectorsIDs = uieditfield(app.figure1, 'text');
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Load Inspectors ID (full)';
            app.LoadRegressionModelButton = uibutton(app.figure1, 'push');
            app.LoadRegressionModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRegressionModelButtonPushed, true);
            app.LoadRegressionModelButton.Text = '...';
            app.LoadRegressionModel = uieditfield(app.figure1, 'text');
            app.text5_2 = uilabel(app.figure1);
            app.text5_2.Text = 'Load Regression Model';
            app.LoadDataBeforetxt = uieditfield(app.figure1, 'text');
            app.LoadDataBefore = uibutton(app.figure1, 'push');
            app.LoadDataBefore.ButtonPushedFcn = createCallbackFcn(app, @LoadDataBeforeButtonPushed, true);
            app.LoadDataBefore.Text = '...';
            app.LoadDataAftertxt = uieditfield(app.figure1, 'text');
            app.LoadDataAfter = uibutton(app.figure1, 'push');
            app.LoadDataAfter.ButtonPushedFcn = createCallbackFcn(app, @LoadDataAfterButtonPushed, true);
            app.LoadDataAfter.Text = '...';
            app.text3_2 = uilabel(app.figure1);
            app.text3_2.Text = 'Old Dataase & IDs:';
            app.text5_3 = uilabel(app.figure1);
            app.text5_3.Text = 'New Database & IDs:';
            app.RunAnalyses = uibutton(app.figure1, 'push');
            app.RunAnalyses.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysesButtonPushed, true);
            app.RunAnalyses.Text = 'Run Analyses';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'Sample Size ';
            app.NumRealElm = uieditfield(app.figure1, 'text');
            app.NumRealElm.Enable = 'off';
            app.NumRealElm.Value = '500';
            app.PlotTimeSeries = uicheckbox(app.figure1);
            app.PlotTimeSeries.ValueChangedFcn = createCallbackFcn(app, @PlotTimeSeriesValueChanged, true);
            app.PlotTimeSeries.Text = 'Plot Time Series';
            app.LLEditFieldLabel = uilabel(app.figure1);
            app.LLEditFieldLabel.Text = 'L.L.';
            app.LLEditField = uieditfield(app.figure1, 'numeric');
            app.LLEditField.Editable = 'off';
            app.RegmodelDropDownLabel = uilabel(app.figure1);
            app.RegmodelDropDownLabel.Text = 'Reg. model:';
            app.RegmodelDropDown = uidropdown(app.figure1);
            app.RegmodelDropDown.Items = {'TAGI', 'KR'};
            app.RegmodelDropDown.Value = 'TAGI';
            app.InspectorHist = uiaxes(app.figure1);
            xlabel(app.InspectorHist, 'Estimated Inspectors Uncertainty  \sigma_v(I_i)')
            ylabel(app.InspectorHist, 'Frequency')
            app.InspectorHist.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RealDataModelParameters_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @RealDataModelParameters_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end