classdef SyntheticPred_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        DeveloperMenu               matlab.ui.container.Menu
        UniteTestDeteriorationModelMenu  matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        AboutModelVerificationMenu  matlab.ui.container.Menu
        stop_run                    matlab.ui.control.Button
        NumFigures                  matlab.ui.control.EditField
        text11                      matlab.ui.control.Label
        uipanel3                    matlab.ui.container.Panel
        ParamTable                  matlab.ui.control.Table
        ExitSynPred                 matlab.ui.control.Button
        RunAnalysis                 matlab.ui.control.Button
        uipanel2                    matlab.ui.container.Panel
        uipanel4                    matlab.ui.container.Panel
        CIVIolation                 matlab.ui.control.CheckBox
        NumberSigma                 matlab.ui.control.CheckBox
        CondCInterval               matlab.ui.control.CheckBox
        SpeedHistogram              matlab.ui.control.CheckBox
        InitSpeedAccScatter         matlab.ui.control.CheckBox
        ForecastError               matlab.ui.control.CheckBox
        SpeedAccScatter             matlab.ui.control.CheckBox
        CondScatter                 matlab.ui.control.CheckBox
        CondSpeedAcc                matlab.ui.control.CheckBox
        text10                      matlab.ui.control.Label
        NumStElements               matlab.ui.control.EditField
        text8                       matlab.ui.control.Label
        text7                       matlab.ui.control.Label
        ObservationsWindow          matlab.ui.control.EditField
        text6                       matlab.ui.control.Label
        ForecastTime                matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        uipanel1                    matlab.ui.container.Panel
        RegmodelDropDown            matlab.ui.control.DropDown
        RegmodelDropDownLabel       matlab.ui.control.Label
        text4_2                     matlab.ui.control.Label
        LoadRegressionModelButton   matlab.ui.control.Button
        LoadRegressionModelTxtBox   matlab.ui.control.EditField
        text4                       matlab.ui.control.Label
        text3                       matlab.ui.control.Label
        text2                       matlab.ui.control.Label
        LoadEstimatedInspectorData  matlab.ui.control.Button
        LoadEstimatedInspectorBox   matlab.ui.control.EditField
        LoadTrueInspectorData       matlab.ui.control.Button
        LoadTrueInspectorDataBox    matlab.ui.control.EditField
        LoadInspectionData          matlab.ui.control.Button
        LoadInspectionDataBox       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        InspectorsEstimatedSigma % Estimated standard deviation (Inspectors)
        SynInspectionDatabase    % Synthetic Inspections Database
        InspectorsTrueSigma      % True standard deviation (Inspectors)
        RegressionModel          % Regression Model Parameters
        stoprun = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SyntheticPred_OpeningFcn(app)
            try
                load(sprintf(['%s/Parameters/PredictRealandSynthetic_DefaultTableParameters.mat'],pwd));
                set(app.ParamTable,'Data',DefaultParameters);
                set(app.ForecastTime,'Value','10');
                set(app.ObservationsWindow,'Value','10');
                set(app.NumStElements,'Value','4000');
            catch
                message = 'Loading default real data parameters has failed, please ensure that the default parameters file is in the toolbox folder.';
                uialert(app.figure1,message,'Error','Icon','error');
            end
        end

        % Button pushed function: ExitSynPred
        function ExitSynPred_Callback(app, event)
            % hObject    handle to ExitSynPred (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(app.figure1);
        end

        % Button pushed function: LoadEstimatedInspectorData
        function LoadEstimatedInspectorData_Callback(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the Estimated Inspectors Sigma');
                set(app.LoadEstimatedInspectorBox,'Value',VIDataFileName)
                app.InspectorsEstimatedSigma=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.InspectorsEstimatedSigma=struct2cell(app.InspectorsEstimatedSigma);
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadInspectionData
        function LoadInspectionData_Callback(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Synthetic Inspections data file');
                set(app.LoadInspectionDataBox,'Value',VIDataFileName)
                app.SynInspectionDatabase=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.SynInspectionDatabase=struct2cell(app.SynInspectionDatabase);
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadTrueInspectorData
        function LoadTrueInspectorData_Callback(app, event)
            try
                FilterSuffix={'*.mat';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the True Inspectors Sigma');
                set(app.LoadTrueInspectorDataBox,'Value',VIDataFileName)
                app.InspectorsTrueSigma=load(sprintf('%s\%s',FilePath,VIDataFileName));
                app.InspectorsTrueSigma=struct2cell(app.InspectorsTrueSigma);
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: RunAnalysis
        function RunAnalysis_Callback(app, event)
            try
                app.stop_run.Enable = 'On';
                TableOfParameters=get(app.ParamTable,'Data');
                M=str2double(get(app.ForecastTime,'Value'));
                ObsWindow=str2double(get(app.ObservationsWindow,'Value'));
                NumOfElements=str2double(get(app.NumStElements,'Value'));
                NumOfFigures=str2double(get(app.NumFigures,'Value'));
                GraphMatrix(1,1)=get(app.CondSpeedAcc,'value');GraphMatrix(1,2)=0;
                GraphMatrix(2,1)=get(app.ForecastError,'value');GraphMatrix(2,2)=get(app.SpeedHistogram,'value');
                GraphMatrix(3,1)=get(app.CondScatter,'value');GraphMatrix(3,2)=get(app.CondCInterval,'value');
                GraphMatrix(4,1)=get(app.SpeedAccScatter,'value');GraphMatrix(4,2)=get(app.NumberSigma,'value');
                GraphMatrix(5,1)=get(app.InitSpeedAccScatter,'value');GraphMatrix(5,2)=get(app.CIVIolation,'value');
%                 regmodel = [];
                regmodel = cell2mat(app.RegressionModel);
%                 app.synthetic_analysis = true;
                PredictSyntheticData(app.SynInspectionDatabase{1},app.SynInspectionDatabase{2},M,app.InspectorsTrueSigma{1},...
                    app.InspectorsEstimatedSigma{1},ObsWindow,NumOfElements,regmodel, TableOfParameters,...
                    GraphMatrix,NumOfFigures,app);
                app.stop_run.Enable = 'off';
                app.stoprun = 0;
            catch
                app.stop_run.Enable = 'Off';
                message = 'Verifications have failed due to an unexpected error, please verify the input database file is correct and compatible with this toolbox requirements.';
                uialert(app.figure1,message,'Error','Icon','error');
            end
        end

        % Button pushed function: LoadRegressionModelButton
        function LoadRegressionModelButtonPushed(app, event)
            try
                if strcmp(app.RegmodelDropDown.Value,'KR')
                    FilterSuffix={'*.mat';'*.*'};
                    [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the Regression Model Parameters');
                    set(app.LoadRegressionModelTxtBox,'Value',VIDataFileName)
                    app.RegressionModel=load(sprintf('%s\%s',FilePath,VIDataFileName));
                    app.RegressionModel=struct2cell(app.RegressionModel);
                    regmodel = cell2mat(app.RegressionModel);
                else
                    FilterSuffix={'*.mat';'*.*'};
                    [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select a .mat file containing the Ann Model Parameters');
                    set(app.LoadRegressionModelTxtBox,'Value',VIDataFileName)
                    app.RegressionModel=load(sprintf('%s\%s',FilePath,VIDataFileName));
                    app.RegressionModel=struct2cell(app.RegressionModel);
                    regmodel = cell2mat(app.RegressionModel);
                    addpath(sprintf('%s/Tools/ModelTraining/OpenTAGI/src/matlab',pwd))
                    addpath(sprintf('%s/Tools/PredictRealandSynthetic/TAGI',pwd))
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.figure1,message,'Warning','Icon','warning');
                app.RegressionModel=[];
                regmodel = [];
            end
        end

        % Menu selected function: AboutModelVerificationMenu
        function AboutModelVerificationMenuSelected(app, event)
            uialert(app.figure1,[{'Model Verification Toolbox'};{''};{'Developed By: Zachary A. Hamida, Ph.D.'};{'Webpage:zachamida.github.io'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end

        % Menu selected function: UniteTestDeteriorationModelMenu
        function UniteTestDeteriorationModelMenuSelected(app, event)
            results = runtests('test_vvt_model');
            display(results)
        end

        % Button pushed function: stop_run
        function stop_runButtonPushed(app, event)
            app.stoprun = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Verify Synthetic Data';
            app.figure1.Icon = 'OpenIPDM_icon_r.png';
            app.DeveloperMenu = uimenu(app.figure1);
            app.DeveloperMenu.Text = 'Developer';
            app.UniteTestDeteriorationModelMenu = uimenu(app.DeveloperMenu);
            app.UniteTestDeteriorationModelMenu.MenuSelectedFcn = createCallbackFcn(app, @UniteTestDeteriorationModelMenuSelected, true);
            app.UniteTestDeteriorationModelMenu.Text = 'Unite Test Deterioration Model';
            app.AboutMenu = uimenu(app.figure1);
            app.AboutMenu.Text = 'About';
            app.AboutModelVerificationMenu = uimenu(app.AboutMenu);
            app.AboutModelVerificationMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutModelVerificationMenuSelected, true);
            app.AboutModelVerificationMenu.Text = 'About Model Verification';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Load Synthetic Data';
            app.LoadInspectionDataBox = uieditfield(app.uipanel1, 'text');
            app.LoadInspectionData = uibutton(app.uipanel1, 'push');
            app.LoadInspectionData.ButtonPushedFcn = createCallbackFcn(app, @LoadInspectionData_Callback, true);
            app.LoadInspectionData.Text = '...';
            app.LoadTrueInspectorDataBox = uieditfield(app.uipanel1, 'text');
            app.LoadTrueInspectorData = uibutton(app.uipanel1, 'push');
            app.LoadTrueInspectorData.ButtonPushedFcn = createCallbackFcn(app, @LoadTrueInspectorData_Callback, true);
            app.LoadTrueInspectorData.Text = '...';
            app.LoadEstimatedInspectorBox = uieditfield(app.uipanel1, 'text');
            app.LoadEstimatedInspectorData = uibutton(app.uipanel1, 'push');
            app.LoadEstimatedInspectorData.ButtonPushedFcn = createCallbackFcn(app, @LoadEstimatedInspectorData_Callback, true);
            app.LoadEstimatedInspectorData.Text = '...';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Load True & Observed Data';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Load True Inspectors Data';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Load Est. Inspectors Data';
            app.LoadRegressionModelTxtBox = uieditfield(app.uipanel1, 'text');
            app.LoadRegressionModelButton = uibutton(app.uipanel1, 'push');
            app.LoadRegressionModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRegressionModelButtonPushed, true);
            app.LoadRegressionModelButton.Text = '...';
            app.text4_2 = uilabel(app.uipanel1);
            app.text4_2.Text = 'Load Regression Model';
            app.RegmodelDropDownLabel = uilabel(app.uipanel1);
            app.RegmodelDropDownLabel.Text = 'Reg. model:';
            app.RegmodelDropDown = uidropdown(app.uipanel1);
            app.RegmodelDropDown.Items = {'TAGI', 'KR'};
            app.RegmodelDropDown.Value = 'TAGI';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Analyses Parameters';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Text = 'Forecast Time';
            app.ForecastTime = uieditfield(app.uipanel2, 'text');
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = 'Years';
            app.ObservationsWindow = uieditfield(app.uipanel2, 'text');
            app.text7 = uilabel(app.uipanel2);
            app.text7.Text = 'Years';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Text = 'Observations Window';
            app.NumStElements = uieditfield(app.uipanel2, 'text');
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'Number of Structural Elements';
            app.uipanel4 = uipanel(app.uipanel2);
            app.uipanel4.Title = 'Graphs';
            app.CondSpeedAcc = uicheckbox(app.uipanel4);
            app.CondSpeedAcc.Text = 'Cond., Speed, Acc.';
            app.CondScatter = uicheckbox(app.uipanel4);
            app.CondScatter.Text = 'Cond. Scatter';
            app.SpeedAccScatter = uicheckbox(app.uipanel4);
            app.SpeedAccScatter.Text = 'Speed, Acc. Scatter';
            app.ForecastError = uicheckbox(app.uipanel4);
            app.ForecastError.Text = 'Forecast Err.';
            app.InitSpeedAccScatter = uicheckbox(app.uipanel4);
            app.InitSpeedAccScatter.Text = 'Speed, Acc. Scatter (Init)';
            app.SpeedHistogram = uicheckbox(app.uipanel4);
            app.SpeedHistogram.Text = 'Speed Hist.';
            app.CondCInterval = uicheckbox(app.uipanel4);
            app.CondCInterval.Text = 'Cond. C. I.';
            app.NumberSigma = uicheckbox(app.uipanel4);
            app.NumberSigma.Text = 'Num. Sigma';
            app.CIVIolation = uicheckbox(app.uipanel4);
            app.CIVIolation.Text = 'C. I. Violation';
            app.RunAnalysis = uibutton(app.figure1, 'push');
            app.RunAnalysis.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysis_Callback, true);
            app.RunAnalysis.Text = 'Run';
            app.ExitSynPred = uibutton(app.figure1, 'push');
            app.ExitSynPred.ButtonPushedFcn = createCallbackFcn(app, @ExitSynPred_Callback, true);
            app.ExitSynPred.Text = 'Exit';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Model Parameters';
            app.ParamTable = uitable(app.uipanel3);
            app.ParamTable.ColumnName = {'Sigma_W'; 'Sigma_Cond'; 'Sigma_Speed'; 'Sigma_Acc'; 'mu_Speed'; 'mu_Acc'; 'Min(Speed)'; 'n'};
            app.ParamTable.RowName = {'True'; 'Estimated'; ''; ''};
            app.ParamTable.ColumnEditable = [true true true true true true true true];
            app.text11 = uilabel(app.figure1);
            app.text11.Text = {'Save Time-series Results'; ' (Number of Cases)'};
            app.NumFigures = uieditfield(app.figure1, 'text');
            app.NumFigures.Value = '20';
            app.stop_run = uibutton(app.figure1, 'push');
            app.stop_run.ButtonPushedFcn = createCallbackFcn(app, @stop_runButtonPushed, true);
            app.stop_run.Enable = 'off';
            app.stop_run.Text = 'Stop Run';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SyntheticPred_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @SyntheticPred_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end