classdef ReadDatabase_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReaddatabaseMain               matlab.ui.Figure
        AboutMenu                      matlab.ui.container.Menu
        AboutReadRealDatabaseMenu      matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        LoadDataTab                    matlab.ui.container.Tab
        LoadDataPanel                  matlab.ui.container.Panel
        LoadMainDatabase               matlab.ui.control.Button
        FileNamePath                   matlab.ui.control.EditField
        text4                          matlab.ui.control.Label
        LoadStructuresData             matlab.ui.control.Button
        StructuresPathData             matlab.ui.control.EditField
        text8                          matlab.ui.control.Label
        LoadInspectorsData             matlab.ui.control.Button
        InspectorsPathData             matlab.ui.control.EditField
        text9                          matlab.ui.control.Label
        DataFilteringPanel             matlab.ui.container.Panel
        SelectStructuralElement        matlab.ui.control.DropDown
        SelectFilterComponent          matlab.ui.control.DropDown
        text2                          matlab.ui.control.Label
        text3                          matlab.ui.control.Label
        SelectStructureStatus          matlab.ui.control.DropDown
        IncludeInterventionsLabel      matlab.ui.control.Label
        StructuresStatusColumnLabel    matlab.ui.control.Label
        SelectStructureActiveCol       matlab.ui.control.DropDown
        StructuresStatusActiveLabel_2  matlab.ui.control.Label
        SelectStructureActiveStatus    matlab.ui.control.DropDown
        ExtraceSEData                  matlab.ui.control.Button
        ExportFiltredData              matlab.ui.control.Button
        AttributesTab                  matlab.ui.container.Tab
        text10                         matlab.ui.control.Label
        text11                         matlab.ui.control.Label
        InspecYearCol                  matlab.ui.control.DropDown
        ConstYearCol                   matlab.ui.control.DropDown
        text12                         matlab.ui.control.Label
        StructuralElementLabel         matlab.ui.control.Label
        SelectSNSpans                  matlab.ui.control.DropDown
        SelectSElementID               matlab.ui.control.DropDown
        text12_2                       matlab.ui.control.Label
        SelectSAtt1                    matlab.ui.control.DropDown
        text12_3                       matlab.ui.control.Label
        SelectSAtt2                    matlab.ui.control.DropDown
        text12_4                       matlab.ui.control.Label
        SelectSAtt3                    matlab.ui.control.DropDown
        text12_5                       matlab.ui.control.Label
        SelectSAtt4                    matlab.ui.control.DropDown
        text12_6                       matlab.ui.control.Label
        SelectSAtt5                    matlab.ui.control.DropDown
        text12_7                       matlab.ui.control.Label
        SelectSAtt6                    matlab.ui.control.DropDown
        text12_8                       matlab.ui.control.Label
        SelectSAtt7                    matlab.ui.control.DropDown
        text12_9                       matlab.ui.control.Label
        SelectSAtt8                    matlab.ui.control.DropDown
        text12_10                      matlab.ui.control.Label
        SelectSAtt9                    matlab.ui.control.DropDown
        SelectMaterial                 matlab.ui.control.DropDown
        StructureMaterialLabel         matlab.ui.control.Label
        InterventionsTab               matlab.ui.container.Tab
        MergeFiles                     matlab.ui.control.Button
        NumFiles                       matlab.ui.control.EditField
        text16                         matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        SelectedYear                   matlab.ui.control.DropDown
        DataFilteringPanel_2           matlab.ui.container.Panel
        SelectStructureActiveCol2      matlab.ui.control.DropDown
        text2_2                        matlab.ui.control.Label
        SelectFilterComponent_2        matlab.ui.control.DropDown
        text3_2                        matlab.ui.control.Label
        LoadInterventionDB             matlab.ui.control.Button
        FileNamePathInterventionDB     matlab.ui.control.EditField
        text4_2                        matlab.ui.control.Label
        LoadInterventionDB_2           matlab.ui.control.Button
        InterventionsCostsPanel        matlab.ui.container.Panel
        LoadInterventionDB_plan        matlab.ui.control.Button
        FileNamePathInterventionDB_Plan  matlab.ui.control.EditField
        text4_3                        matlab.ui.control.Label
        ExtractCosts                   matlab.ui.control.Button
        NetworkScaleTab                matlab.ui.container.Tab
        LoadDataPanel_2                matlab.ui.container.Panel
        LoadElementDetails             matlab.ui.control.Button
        ElementDetailsPath             matlab.ui.control.EditField
        text9_3                        matlab.ui.control.Label
        DataManagmentPanel             matlab.ui.container.Panel
        SelectQuantitiyColumnLabel     matlab.ui.control.Label
        SelectQuantityCol              matlab.ui.control.DropDown
        SelectTrafficColumnLabel       matlab.ui.control.Label
        SelectTrafficCol               matlab.ui.control.DropDown
        PickButton                     matlab.ui.control.Button
        SelectElementswithoutVILabel   matlab.ui.control.Label
        PickPrimary                    matlab.ui.control.Button
        SelectElementsLabel            matlab.ui.control.Label
        SelectTrucksColumnLabel        matlab.ui.control.Label
        SelectTruckCol                 matlab.ui.control.DropDown
        SelectTotalLengthColumnLabel   matlab.ui.control.Label
        SelectLengthCol                matlab.ui.control.DropDown
        ExtractDataButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        SEDatabaseStore         % structural elements Database
        InterventionSEData      % Intervention Database
        StructuresDatabaseStore % Structures Database
        InspectorsDatabaseStore % Inspectors Database
        InterventionsDataStore  % Import CSV database (origin: MS. access)
        InterventionsCostStore  % Import CSV database with costs (origin: MS. access)
        CostsPerElement         % Intervention costs per element
        IntPerElement           % Intervention type per element
        ElementDetailsDataStore % Element Details
        PrimaryElements         % Primary Structural Element Group
        NoVIData                % Elements without visual inspections 
    end
    
    methods (Access = private)
        function DataColumnList_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to DataColumnList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function ExitReadDatabase_Callback(app, hObject, eventdata, handles)
            % hObject    handle to ExitReadDatabase (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close()
        end
        
        function FileNamePath2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to FileNamePath2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function ImportedDataColumns_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to ImportedDataColumns (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ReadDatabase_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.

        end

        % Button pushed function: ExportFiltredData
        function ExportFiltredData_Callback(app, event)
            try
                SelectedVars=get(app.SelectStructuralElement,'Items');
                SelectedVars(1)=[];
                app.SEDatabaseStore.SelectedVariableNames=SelectedVars;
                SEData=readall(app.SEDatabaseStore);
                SelectedItem=find(strcmp(app.SelectStructuralElement.Value,app.SelectStructuralElement.Items));
                if SelectedItem~=1
                    ColsVal=get(app.SelectFilterComponent,'value');
                    ColsInd=SelectedItem-1;
                    Rows=find(strcmp(ColsVal,table2array(SEData(:,ColsInd))));
                    Vars=SelectedVars;
                    SEData=SEData(Rows,Vars);
                end
                SelectedStatus=find(strcmp(app.SelectStructureStatus.Value,app.SelectStructureStatus.Items));
                if SelectedStatus~=1
                    AllRows=[];
                    UISEData=unique(app.InterventionSEData);
                    for i=1:length(table2cell(UISEData))
                        SRows = find(strcmp(table2cell(UISEData(i,1)),table2cell(SEData(:,1))));
                        AllRows=[AllRows;SRows];
                    end
                end
                if SelectedStatus==2
                   NoInterRows=setdiff(1:size(SEData,1),AllRows)';
                   SEData=SEData(NoInterRows,:);
                elseif SelectedStatus==3
                   SEData=SEData(AllRows,:);
                end
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','Save Filtred Data');
                save(fullfile(pathname,filename),'SEData');
            catch
                message = 'Invalid input data, please ensure to load the required ".csv" data files, or verify the format of the data in each column.';
                uialert(app.ReaddatabaseMain,message,'Error','Icon','error');
            end
        end

        % Button pushed function: ExtraceSEData
        function ExtraceSEData_Callback(app, event)
            try
                SEDatabaseStore=app.SEDatabaseStore;
                InterventionSEData=app.InterventionSEData;
                InspectorsDatabaseStore=app.InspectorsDatabaseStore; 
                StructuresDatabaseStore=app.StructuresDatabaseStore;
                ExcuteReadData();
            catch
                message = 'Invalid input data, please ensure to load the required ".csv" data files, or verify the format of the data in each column.';
                uialert(app.ReaddatabaseMain,message,'Error','Icon','error');
            end
        end

        % Button pushed function: LoadInspectorsData
        function LoadInspectorsData_Callback(app, event)
            try
                FilterSuffix={'*.csv';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Inspectors Database file');
                set(app.InspectorsPathData,'value',VIDataFileName)
                app.InspectorsDatabaseStore=datastore(fullfile(FilePath,VIDataFileName));
                DataColumns=app.InspectorsDatabaseStore.VariableNames';
                [s,v] = listdlg('PromptString','Select Columns to import:',...
                                'SelectionMode','multiple',...
                                'ListString',DataColumns);
                if v
                    app.InspectorsDatabaseStore.SelectedVariableNames = DataColumns(s)';
                    set(app.InspectorsPathData,'value',VIDataFileName);
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadMainDatabase
        function LoadMainDatabase_Callback(app, event)
            try
                FilterSuffix={'*.csv';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Structural Elements Database file');
                set(app.FileNamePath,'value',VIDataFileName)
                app.SEDatabaseStore=datastore(fullfile(FilePath,VIDataFileName));
                DataColumns=app.SEDatabaseStore.VariableNames';
                [s,v] = listdlg('PromptString','Select Columns to import:',...
                                'SelectionMode','multiple',...
                                'ListString',DataColumns);
                if v
                    ListSelectedColumns=['No Filter';DataColumns(s)];
                    ListSelectedColumns1=['No Selection';DataColumns(s)];
                    app.SEDatabaseStore.SelectedVariableNames = DataColumns(s)';
                    DateIndexFix=strcmp(ListSelectedColumns,'DATEINSP');
                    if sum(DateIndexFix)>0
                        app.SEDatabaseStore.TextscanFormats{1,find(DateIndexFix)-1}='%q';
                    end
                    set(app.SelectStructuralElement,'items',ListSelectedColumns);
                    set(app.SelectMaterial,'items',ListSelectedColumns1);
                    set(app.InspecYearCol,'items',ListSelectedColumns1);
                    set(app.SelectSNSpans,'items',ListSelectedColumns1);
                    set(app.SelectSElementID,'items',ListSelectedColumns1);
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadStructuresData
        function LoadStructuresData_Callback(app, event)
            try
                FilterSuffix={'*.csv';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Structures Database file');
                set(app.StructuresPathData,'value',VIDataFileName)
                app.StructuresDatabaseStore=datastore(fullfile(FilePath,VIDataFileName));
                DataColumns=app.StructuresDatabaseStore.VariableNames';
                [s,v] = listdlg('PromptString','Select Columns to import:',...
                                'SelectionMode','multiple',...
                                'ListString',DataColumns);
                if v
                    app.StructuresDatabaseStore.SelectedVariableNames = DataColumns(s)';
                    set(app.StructuresPathData,'value',VIDataFileName);
                    ListSelectedColumns=['No Selection';DataColumns(s)];
                    set(app.ConstYearCol,'items',DataColumns(s));
                    set(app.SelectStructureActiveCol,'items',ListSelectedColumns);
                    set(app.SelectSAtt1,'items',ListSelectedColumns);
                    set(app.SelectSAtt2,'items',ListSelectedColumns);
                    set(app.SelectSAtt3,'items',ListSelectedColumns);
                    set(app.SelectSAtt4,'items',ListSelectedColumns);
                    set(app.SelectSAtt5,'items',ListSelectedColumns);
                    set(app.SelectSAtt6,'items',ListSelectedColumns);
                    set(app.SelectSAtt7,'items',ListSelectedColumns);
                    set(app.SelectSAtt8,'items',ListSelectedColumns);
                    set(app.SelectSAtt9,'items',ListSelectedColumns);
                    set(app.SelectTrafficCol,'items',ListSelectedColumns);
                    set(app.SelectTruckCol,'items',ListSelectedColumns);
                    set(app.SelectLengthCol,'items',ListSelectedColumns);
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: MergeFiles
        function MergeFiles_Callback(app, event)
            try
                TNumFiles=str2double(get(app.NumFiles,'Value'));
                InterventionYearList=(get(app.SelectedYear,'Value'));
                InterventionYear=2005+InterventionYearList;
                InterventionPrep(TNumFiles,InterventionYear)
            catch
                message = 'File(s) not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Value changed function: SelectStructuralElement
        function SelectStructuralElement_Callback(app, event)

            ValIndex=find(strcmp(app.SelectStructuralElement.Value,app.SelectStructuralElement.Items));
            if ValIndex>1
                ColumnData=get(app.SelectStructuralElement,'items');
                ColumnData=ColumnData(ValIndex);
                DateIndexFix=strcmp(ColumnData,'DATEINSP');
                if sum(DateIndexFix)>0
                    app.SEDatabaseStore.TextscanFormats{1,1}='%q';
                end
                LocalSEDatabaseStore=app.SEDatabaseStore;
                LocalSEDatabaseStore.SelectedVariableNames=ColumnData;
                FilteredColumn = readall(LocalSEDatabaseStore);
                UniqueColumnValues=unique(FilteredColumn);
                set(app.SelectFilterComponent,'items',table2cell(UniqueColumnValues));
            end
        end

        % Value changed function: SelectStructureStatus
        function SelectStructureStatus_Callback(app, event)
            try
                ValIndex=find(strcmp(app.SelectStructureStatus.Value,app.SelectStructureStatus.Items));
                if ValIndex>1
                    FilterSuffix={'*.csv';'*.*'};
                    [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Interventions Database file');
                    app.InterventionSEData=datastore(fullfile(FilePath,VIDataFileName));
                    DataColumns=app.InterventionSEData.VariableNames';
                    [s,v] = listdlg('PromptString','Select columns to import:',...
                                    'SelectionMode','multiple',...
                                    'ListString',DataColumns);
                    if v
    %                     app.InterventionSEData.TextscanFormats{1}='%s';
                        app.InterventionSEData.SelectedFormats(strcmp(app.InterventionSEData.SelectedFormats,'%f'))={'%q'};
                        app.InterventionSEData.SelectedVariableNames = DataColumns(s)';
                        app.InterventionSEData=readall(app.InterventionSEData);
                        msgbox('Structures ID with Interventions Loaded Successfully!')
                    end
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Value changed function: SelectStructureActiveCol
        function SelectStructureActiveColValueChanged(app, event)

            ValIndex=find(strcmp(app.SelectStructureActiveCol.Value,app.SelectStructureActiveCol.Items));
            if ValIndex>1
                ColumnData=app.SelectStructureActiveCol.Value;
                PrevSelectedVarNames=app.StructuresDatabaseStore.SelectedVariableNames;
                app.StructuresDatabaseStore.SelectedVariableNames=ColumnData;
                FilteredColumn = readall(app.StructuresDatabaseStore);
                UniqueColumnValues=unique(FilteredColumn);
                set(app.SelectStructureActiveStatus,'items',table2cell(UniqueColumnValues));
                app.StructuresDatabaseStore.SelectedVariableNames=PrevSelectedVarNames;
            end
        end

        % Button pushed function: LoadInterventionDB
        function LoadInterventionDBButtonPushed(app, event)
            try
                FilterSuffix={'*.csv';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Interventions Database file');
                set(app.FileNamePathInterventionDB,'value',VIDataFileName)
                app.InterventionsDataStore=datastore(fullfile(FilePath,VIDataFileName));
                DataColumns=app.InterventionsDataStore.VariableNames';
                [s,v] = listdlg('PromptString','Select Columns to import:',...
                                'SelectionMode','multiple',...
                                'ListString',DataColumns);
                if v
                    app.InterventionsDataStore.SelectedVariableNames = DataColumns(s)';
                    ListSelectedColumns=['No Selection';DataColumns(s)];
                    set(app.SelectStructureActiveCol2,'items',ListSelectedColumns);
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Value changed function: SelectFilterComponent_2
        function SelectFilterComponent_2ValueChanged(app, event)
            
        end

        % Value changed function: SelectStructureActiveCol2
        function SelectStructureActiveCol2ValueChanged(app, event)
            ValIndex=find(strcmp(app.SelectStructureActiveCol2.Value,app.SelectStructureActiveCol2.Items));
            if ValIndex>1
                ColumnData=app.SelectStructureActiveCol2.Value;
                PrevSelectedVarNames=app.InterventionsDataStore.SelectedVariableNames;
                app.InterventionsDataStore.SelectedVariableNames=ColumnData;
                FilteredColumn = readall(app.InterventionsDataStore);
                UniqueColumnValues=unique(FilteredColumn);
                set(app.SelectFilterComponent_2,'items',table2cell(UniqueColumnValues));
                app.InterventionsDataStore.SelectedVariableNames=PrevSelectedVarNames;
            end
            
        end

        % Button pushed function: LoadInterventionDB_2
        function LoadInterventionDB_2ButtonPushed(app, event)
            try
                SelectedVars=get(app.SelectStructureActiveCol2,'items');
                SelectedVars(1)=[];
                app.InterventionsDataStore.SelectedVariableNames=SelectedVars;
                SEInterventionData=readall(app.InterventionsDataStore);
                FilterCond=find(strcmp(app.SelectStructureActiveCol2.Items,app.SelectStructureActiveCol2.Value));
                if FilterCond~=1
                    ColsVal=get(app.SelectFilterComponent_2,'value');
                    ColsInd=find(strcmp(app.SelectStructureActiveCol2.Items,app.SelectStructureActiveCol2.Value))-1;
                    Rows=find(strcmp(ColsVal,table2array(SEInterventionData(:,ColsInd))));
                    Vars=SelectedVars;
                    SEInterventionData=SEInterventionData(Rows,Vars);
                    InterventionCode=str2double(table2array(SEInterventionData(:,3)));
                    SEInterventionData.CODEACT=(round(InterventionCode/1000));
                    writetable(SEInterventionData(:,[1,2,3,5]),sprintf('Intervention_%s.csv',erase(ColsVal,"/")),'Delimiter',',')
                end  
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadElementDetails
        function LoadElementDetailsButtonPushed(app, event)
            try
                FilterSuffix={'*.csv';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Element Details Database file');
                set(app.ElementDetailsPath,'value',VIDataFileName)
                app.ElementDetailsDataStore=datastore(fullfile(FilePath,VIDataFileName));
                DataColumns=app.ElementDetailsDataStore.VariableNames';
                [s,v] = listdlg('PromptString','Select Columns to import:',...
                                'SelectionMode','multiple',...
                                'ListString',DataColumns);
                if v
                    app.ElementDetailsDataStore.SelectedVariableNames = DataColumns(s)';
                    set(app.SelectQuantityCol,'items',DataColumns(s));
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: PickButton
        function PickButtonPushed(app, event)
            try
                DataColumns=app.SelectFilterComponent.Items;
                [s,v] = listdlg('PromptString','Select Elements without VI to import:',...
                                'SelectionMode','multiple',...
                                'ListString',DataColumns);
                if v   
                    app.NoVIData= DataColumns(s)';
                end
            catch
                message = 'No data is available to load the list';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: PickPrimary
        function PickPrimaryButtonPushed(app, event)
            try
                DataColumns=app.SelectFilterComponent.Items;
                [s,v] = listdlg('PromptString','Select Primary Elements:',...
                                'SelectionMode','multiple',...
                                'ListString',DataColumns);
                if v
                    app.PrimaryElements = DataColumns(s)';
                end
            catch
                message = 'No data is available to load the list';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: ExtractDataButton
        function ExtractDataButtonPushed(app, event)
            try
                SEDatabaseStore=app.SEDatabaseStore;
                InterventionSEData=app.InterventionSEData;
                InspectorsDatabaseStore=app.InspectorsDatabaseStore; 
                StructuresDatabaseStore=app.StructuresDatabaseStore;
                NoVIData=app.NoVIData;
                PrimaryElements=app.PrimaryElements;
                ElementDetailsDataStore=app.ElementDetailsDataStore;% NO Struc : NOELem : NOTrav : Elem : CalQuantity
                SelectedVars=get(app.SelectStructuralElement,'items');
                SelectedVars(1)=[];
                ExcuteReadData_Network();
            catch
                message = 'Invalid input data, please ensure to load the required ".csv" data files, or verify the format of the data in each column.';
                uialert(app.ReaddatabaseMain,message,'Error','Icon','error');
            end
        end

        % Callback function
        function PrincipalButton_2Pushed(app, event)
            try
                DataColumns=app.SelectFilterComponent.Items;
                s=[4,5,6,7,11,12,15,18,19,20,22,23,24,26,27,30,31,35,38,44,47,49,50,52,62,64,67,70,71,73,74,78,79,83,84];
                app.PrimaryElements = DataColumns(s)';
            catch
                message = 'No data is available to load the list';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadInterventionDB_plan
        function LoadInterventionDB_planButtonPushed(app, event)
            try
                FilterSuffix={'*.csv';'*.*'};
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select Interventions Database file');
                set(app.FileNamePathInterventionDB_Plan,'value',VIDataFileName)
                app.InterventionsCostStore=datastore(fullfile(FilePath,VIDataFileName));
                DataColumns=app.InterventionsCostStore.VariableNames';
                [s,v] = listdlg('PromptString','Select Columns to import:',...
                                'SelectionMode','multiple',...
                                'ListString',DataColumns);
                if v
                    app.InterventionsCostStore.SelectedVariableNames = DataColumns(s)';
                end
            catch
                message = 'File is not loaded, or file format is not compatible';
                uialert(app.ReaddatabaseMain,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: ExtractCosts
        function ExtractCostsButtonPushed(app, event)
            try
                Int_Costs=table2cell(readall(app.InterventionsCostStore));
                Elements=unique(Int_Costs(:,4));
                for i=1:length(Elements)
                    Id = strcmp(Elements(i),Int_Costs(:,4));
                    Elem_Type = Int_Costs(Id,:);
                    Int_Code = unique(Elem_Type(:,2));
                    for j=1:length(Int_Code)
                        Int_Id=strcmp(Int_Code(j),Elem_Type(:,2));
                        app.CostsPerElement{i,j} = Elem_Type(Int_Id,3);
                        Elem_Id{i,j} = Elem_Type(Int_Id,5);
                    end
                    app.IntPerElement{i,1}=Elements(i);
                    app.IntPerElement{i,2}=Int_Code;
                    app.IntPerElement{i,3}=Elem_Id(i,:);
                end
                
                Elem_details=app.IntPerElement;
                Int_Cost = app.CostsPerElement;
                
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','Costs_and_Elements');
                save(fullfile(pathname,filename),'Int_Cost');
                filename2=sprintf('%s_metafile',filename(1:end-4));
                save(fullfile(pathname,filename2),'Elem_details');
            catch
                message = 'Invalid input data, please ensure to load the required ".csv" data files, or verify the format of the data in each column.';
                uialert(app.ReaddatabaseMain,message,'Error','Icon','error');
            end
        end

        % Callback function
        function SecondaryButtonPushed(app, event)
            DataColumns=app.SelectFilterComponent.Items;
            s=[];
            app.PrimaryElements = DataColumns(s)';
        end

        % Menu selected function: AboutReadRealDatabaseMenu
        function AboutReadRealDatabaseMenuSelected(app, event)
            uialert(app.ReaddatabaseMain,[{'Read Real Database Toolbox'};{''};{'Developed By: Zachary A. Hamida, Ph.D.'};{'email:zachary.hamida@polymtl.ca'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReaddatabaseMain = uifigure('Visible', 'off');
            app.ReaddatabaseMain.Name = 'Read Database';
            app.AboutMenu = uimenu(app.ReaddatabaseMain);
            app.AboutMenu.Text = 'About';
            app.AboutReadRealDatabaseMenu = uimenu(app.AboutMenu);
            app.AboutReadRealDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutReadRealDatabaseMenuSelected, true);
            app.AboutReadRealDatabaseMenu.Text = 'About Read Real Database';
            app.TabGroup = uitabgroup(app.ReaddatabaseMain);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.LoadDataPanel = uipanel(app.LoadDataTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.LoadMainDatabase = uibutton(app.LoadDataPanel, 'push');
            app.LoadMainDatabase.ButtonPushedFcn = createCallbackFcn(app, @LoadMainDatabase_Callback, true);
            app.LoadMainDatabase.Text = 'Load';
            app.FileNamePath = uieditfield(app.LoadDataPanel, 'text');
            app.text4 = uilabel(app.LoadDataPanel);
            app.text4.Text = 'Elements Inspection Database';
            app.LoadStructuresData = uibutton(app.LoadDataPanel, 'push');
            app.LoadStructuresData.ButtonPushedFcn = createCallbackFcn(app, @LoadStructuresData_Callback, true);
            app.LoadStructuresData.Text = 'Load';
            app.StructuresPathData = uieditfield(app.LoadDataPanel, 'text');
            app.text8 = uilabel(app.LoadDataPanel);
            app.text8.Text = 'Structures Data';
            app.LoadInspectorsData = uibutton(app.LoadDataPanel, 'push');
            app.LoadInspectorsData.ButtonPushedFcn = createCallbackFcn(app, @LoadInspectorsData_Callback, true);
            app.LoadInspectorsData.Text = 'Load';
            app.InspectorsPathData = uieditfield(app.LoadDataPanel, 'text');
            app.text9 = uilabel(app.LoadDataPanel);
            app.text9.Text = 'Inspectors Data';
            app.DataFilteringPanel = uipanel(app.LoadDataTab);
            app.DataFilteringPanel.Title = 'Data Filtring';
            app.SelectStructuralElement = uidropdown(app.DataFilteringPanel);
            app.SelectStructuralElement.Items = {};
            app.SelectStructuralElement.ValueChangedFcn = createCallbackFcn(app, @SelectStructuralElement_Callback, true);
            app.SelectStructuralElement.Value = {};
            app.SelectFilterComponent = uidropdown(app.DataFilteringPanel);
            app.SelectFilterComponent.Items = {};
            app.SelectFilterComponent.Value = {};
            app.text2 = uilabel(app.DataFilteringPanel);
            app.text2.Text = 'Filter by:';
            app.text3 = uilabel(app.DataFilteringPanel);
            app.text3.Text = 'With Value:';
            app.SelectStructureStatus = uidropdown(app.DataFilteringPanel);
            app.SelectStructureStatus.Items = {'All', 'Without Interventions', 'Interventions Only'};
            app.SelectStructureStatus.ValueChangedFcn = createCallbackFcn(app, @SelectStructureStatus_Callback, true);
            app.SelectStructureStatus.Value = 'All';
            app.IncludeInterventionsLabel = uilabel(app.DataFilteringPanel);
            app.IncludeInterventionsLabel.Text = 'Include Interventions:';
            app.StructuresStatusColumnLabel = uilabel(app.DataFilteringPanel);
            app.StructuresStatusColumnLabel.Text = 'Structures Status Column:';
            app.SelectStructureActiveCol = uidropdown(app.DataFilteringPanel);
            app.SelectStructureActiveCol.Items = {};
            app.SelectStructureActiveCol.ValueChangedFcn = createCallbackFcn(app, @SelectStructureActiveColValueChanged, true);
            app.SelectStructureActiveCol.Value = {};
            app.StructuresStatusActiveLabel_2 = uilabel(app.DataFilteringPanel);
            app.StructuresStatusActiveLabel_2.Text = 'Structures Status (Active):';
            app.SelectStructureActiveStatus = uidropdown(app.DataFilteringPanel);
            app.SelectStructureActiveStatus.Items = {};
            app.SelectStructureActiveStatus.Value = {};
            app.ExtraceSEData = uibutton(app.LoadDataTab, 'push');
            app.ExtraceSEData.ButtonPushedFcn = createCallbackFcn(app, @ExtraceSEData_Callback, true);
            app.ExtraceSEData.Text = 'Export Analysis Ready Data';
            app.ExportFiltredData = uibutton(app.LoadDataTab, 'push');
            app.ExportFiltredData.ButtonPushedFcn = createCallbackFcn(app, @ExportFiltredData_Callback, true);
            app.ExportFiltredData.Text = 'Export Filtered Data';
            app.AttributesTab = uitab(app.TabGroup);
            app.AttributesTab.Title = 'Attributes';
            app.text10 = uilabel(app.AttributesTab);
            app.text10.Text = 'Inspection Date:';
            app.text11 = uilabel(app.AttributesTab);
            app.text11.Text = 'Construction Date:';
            app.InspecYearCol = uidropdown(app.AttributesTab);
            app.InspecYearCol.Items = {};
            app.InspecYearCol.Value = {};
            app.ConstYearCol = uidropdown(app.AttributesTab);
            app.ConstYearCol.Items = {};
            app.ConstYearCol.Value = {};
            app.text12 = uilabel(app.AttributesTab);
            app.text12.Text = 'Number of spans:';
            app.StructuralElementLabel = uilabel(app.AttributesTab);
            app.StructuralElementLabel.Text = 'Structural Element #:';
            app.SelectSNSpans = uidropdown(app.AttributesTab);
            app.SelectSNSpans.Items = {};
            app.SelectSNSpans.Value = {};
            app.SelectSElementID = uidropdown(app.AttributesTab);
            app.SelectSElementID.Items = {};
            app.SelectSElementID.Value = {};
            app.text12_2 = uilabel(app.AttributesTab);
            app.text12_2.Text = 'Structural Attribute 1:';
            app.SelectSAtt1 = uidropdown(app.AttributesTab);
            app.SelectSAtt1.Items = {};
            app.SelectSAtt1.Value = {};
            app.text12_3 = uilabel(app.AttributesTab);
            app.text12_3.Text = 'Structural Attribute 2:';
            app.SelectSAtt2 = uidropdown(app.AttributesTab);
            app.SelectSAtt2.Items = {};
            app.SelectSAtt2.Value = {};
            app.text12_4 = uilabel(app.AttributesTab);
            app.text12_4.Text = 'Structural Attribute 3:';
            app.SelectSAtt3 = uidropdown(app.AttributesTab);
            app.SelectSAtt3.Items = {};
            app.SelectSAtt3.Value = {};
            app.text12_5 = uilabel(app.AttributesTab);
            app.text12_5.Text = 'Structural Attribute 4:';
            app.SelectSAtt4 = uidropdown(app.AttributesTab);
            app.SelectSAtt4.Items = {};
            app.SelectSAtt4.Value = {};
            app.text12_6 = uilabel(app.AttributesTab);
            app.text12_6.Text = 'Structural Attribute 5:';
            app.SelectSAtt5 = uidropdown(app.AttributesTab);
            app.SelectSAtt5.Items = {};
            app.SelectSAtt5.Value = {};
            app.text12_7 = uilabel(app.AttributesTab);
            app.text12_7.Text = 'Structural Attribute 6:';
            app.SelectSAtt6 = uidropdown(app.AttributesTab);
            app.SelectSAtt6.Items = {};
            app.SelectSAtt6.Value = {};
            app.text12_8 = uilabel(app.AttributesTab);
            app.text12_8.Text = 'Structural Attribute 7:';
            app.SelectSAtt7 = uidropdown(app.AttributesTab);
            app.SelectSAtt7.Items = {};
            app.SelectSAtt7.Value = {};
            app.text12_9 = uilabel(app.AttributesTab);
            app.text12_9.Text = 'Structural Attribute 8:';
            app.SelectSAtt8 = uidropdown(app.AttributesTab);
            app.SelectSAtt8.Items = {};
            app.SelectSAtt8.Value = {};
            app.text12_10 = uilabel(app.AttributesTab);
            app.text12_10.Text = 'Structural Attribute 9:';
            app.SelectSAtt9 = uidropdown(app.AttributesTab);
            app.SelectSAtt9.Items = {};
            app.SelectSAtt9.Value = {};
            app.SelectMaterial = uidropdown(app.AttributesTab);
            app.SelectMaterial.Items = {};
            app.SelectMaterial.Value = {};
            app.StructureMaterialLabel = uilabel(app.AttributesTab);
            app.StructureMaterialLabel.Text = 'Structure Material:';
            app.InterventionsTab = uitab(app.TabGroup);
            app.InterventionsTab.Title = 'Interventions';
            app.MergeFiles = uibutton(app.InterventionsTab, 'push');
            app.MergeFiles.ButtonPushedFcn = createCallbackFcn(app, @MergeFiles_Callback, true);
            app.MergeFiles.Text = 'Merge Files';
            app.NumFiles = uieditfield(app.InterventionsTab, 'text');
            app.NumFiles.Value = '3';
            app.text16 = uilabel(app.InterventionsTab);
            app.text16.Text = 'Num. Files:';
            app.text17 = uilabel(app.InterventionsTab);
            app.text17.Text = 'Up to Year:';
            app.SelectedYear = uidropdown(app.InterventionsTab);
            app.SelectedYear.Items = {'2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018', '2019'};
            app.SelectedYear.Value = '2011';
            app.DataFilteringPanel_2 = uipanel(app.InterventionsTab);
            app.DataFilteringPanel_2.Title = 'Intervention Data (.csv from MS. Access)';
            app.SelectStructureActiveCol2 = uidropdown(app.DataFilteringPanel_2);
            app.SelectStructureActiveCol2.Items = {};
            app.SelectStructureActiveCol2.ValueChangedFcn = createCallbackFcn(app, @SelectStructureActiveCol2ValueChanged, true);
            app.SelectStructureActiveCol2.Value = {};
            app.text2_2 = uilabel(app.DataFilteringPanel_2);
            app.text2_2.Text = 'Filter by:';
            app.SelectFilterComponent_2 = uidropdown(app.DataFilteringPanel_2);
            app.SelectFilterComponent_2.Items = {};
            app.SelectFilterComponent_2.ValueChangedFcn = createCallbackFcn(app, @SelectFilterComponent_2ValueChanged, true);
            app.SelectFilterComponent_2.Value = {};
            app.text3_2 = uilabel(app.DataFilteringPanel_2);
            app.text3_2.Text = 'With Value:';
            app.LoadInterventionDB = uibutton(app.DataFilteringPanel_2, 'push');
            app.LoadInterventionDB.ButtonPushedFcn = createCallbackFcn(app, @LoadInterventionDBButtonPushed, true);
            app.LoadInterventionDB.Text = 'Load';
            app.FileNamePathInterventionDB = uieditfield(app.DataFilteringPanel_2, 'text');
            app.text4_2 = uilabel(app.DataFilteringPanel_2);
            app.text4_2.Text = 'Load Intervention Database';
            app.LoadInterventionDB_2 = uibutton(app.DataFilteringPanel_2, 'push');
            app.LoadInterventionDB_2.ButtonPushedFcn = createCallbackFcn(app, @LoadInterventionDB_2ButtonPushed, true);
            app.LoadInterventionDB_2.Text = 'Extract Data';
            app.InterventionsCostsPanel = uipanel(app.InterventionsTab);
            app.InterventionsCostsPanel.Title = 'Interventions Costs';
            app.LoadInterventionDB_plan = uibutton(app.InterventionsCostsPanel, 'push');
            app.LoadInterventionDB_plan.ButtonPushedFcn = createCallbackFcn(app, @LoadInterventionDB_planButtonPushed, true);
            app.LoadInterventionDB_plan.Text = 'Load';
            app.FileNamePathInterventionDB_Plan = uieditfield(app.InterventionsCostsPanel, 'text');
            app.text4_3 = uilabel(app.InterventionsCostsPanel);
            app.text4_3.Text = 'Load Intervention Database';
            app.ExtractCosts = uibutton(app.InterventionsCostsPanel, 'push');
            app.ExtractCosts.ButtonPushedFcn = createCallbackFcn(app, @ExtractCostsButtonPushed, true);
            app.ExtractCosts.Text = 'Extract Costs';
            app.NetworkScaleTab = uitab(app.TabGroup);
            app.NetworkScaleTab.Title = 'Network-Scale';
            app.LoadDataPanel_2 = uipanel(app.NetworkScaleTab);
            app.LoadDataPanel_2.Title = 'Load Data';
            app.LoadElementDetails = uibutton(app.LoadDataPanel_2, 'push');
            app.LoadElementDetails.ButtonPushedFcn = createCallbackFcn(app, @LoadElementDetailsButtonPushed, true);
            app.LoadElementDetails.Text = 'Load';
            app.ElementDetailsPath = uieditfield(app.LoadDataPanel_2, 'text');
            app.text9_3 = uilabel(app.LoadDataPanel_2);
            app.text9_3.Text = 'Elements Details';
            app.DataManagmentPanel = uipanel(app.NetworkScaleTab);
            app.DataManagmentPanel.Title = 'Data Managment';
            app.SelectQuantitiyColumnLabel = uilabel(app.DataManagmentPanel);
            app.SelectQuantitiyColumnLabel.Text = 'Select Quantitiy Column:';
            app.SelectQuantityCol = uidropdown(app.DataManagmentPanel);
            app.SelectQuantityCol.Items = {};
            app.SelectQuantityCol.Value = {};
            app.SelectTrafficColumnLabel = uilabel(app.DataManagmentPanel);
            app.SelectTrafficColumnLabel.Text = 'Select Traffic Column:';
            app.SelectTrafficCol = uidropdown(app.DataManagmentPanel);
            app.SelectTrafficCol.Items = {};
            app.SelectTrafficCol.Value = {};
            app.PickButton = uibutton(app.DataManagmentPanel, 'push');
            app.PickButton.ButtonPushedFcn = createCallbackFcn(app, @PickButtonPushed, true);
            app.PickButton.Text = 'Pick';
            app.SelectElementswithoutVILabel = uilabel(app.DataManagmentPanel);
            app.SelectElementswithoutVILabel.Text = 'Select Elements without VI';
            app.PickPrimary = uibutton(app.DataManagmentPanel, 'push');
            app.PickPrimary.ButtonPushedFcn = createCallbackFcn(app, @PickPrimaryButtonPushed, true);
            app.PickPrimary.Text = 'Pick';
            app.SelectElementsLabel = uilabel(app.DataManagmentPanel);
            app.SelectElementsLabel.Text = 'Select Elements';
            app.SelectTrucksColumnLabel = uilabel(app.DataManagmentPanel);
            app.SelectTrucksColumnLabel.Text = 'Select Trucks% Column:';
            app.SelectTruckCol = uidropdown(app.DataManagmentPanel);
            app.SelectTruckCol.Items = {};
            app.SelectTruckCol.Value = {};
            app.SelectTotalLengthColumnLabel = uilabel(app.DataManagmentPanel);
            app.SelectTotalLengthColumnLabel.Text = 'Select Total  Length Column:';
            app.SelectLengthCol = uidropdown(app.DataManagmentPanel);
            app.SelectLengthCol.Items = {};
            app.SelectLengthCol.Value = {};
            app.ExtractDataButton = uibutton(app.NetworkScaleTab, 'push');
            app.ExtractDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractDataButtonPushed, true);
            app.ExtractDataButton.Text = 'Extract Data';
            % Show the figure after all components are created
            app.ReaddatabaseMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReadDatabase_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReaddatabaseMain)

            % Execute the startup function
            runStartupFcn(app, @ReadDatabase_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReaddatabaseMain)
        end
    end
end