classdef single_time < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        IntmuEditField            matlab.ui.control.NumericEditField
        IntmuEditFieldLabel       matlab.ui.control.Label
        IntsigmaEditField         matlab.ui.control.NumericEditField
        IntsigmaEditFieldLabel    matlab.ui.control.Label
        UnderwentIntervention     matlab.ui.control.CheckBox
        text22_2                  matlab.ui.control.Label
        InterventionSpinner       matlab.ui.control.Spinner
        InterventionSpinnerLabel  matlab.ui.control.Label
        text24                    matlab.ui.control.Label
        TransParam                matlab.ui.control.EditField
        SaveGraphs                matlab.ui.control.Button
        SaveCase                  matlab.ui.control.Button
        DataTable                 matlab.ui.control.Table
        uibuttongroup1            matlab.ui.container.ButtonGroup
        Case2                     matlab.ui.control.RadioButton
        Case1                     matlab.ui.control.RadioButton
        text23                    matlab.ui.control.Label
        text22                    matlab.ui.control.Label
        Durations                 matlab.ui.control.EditField
        Constrained               matlab.ui.control.CheckBox
        text20                    matlab.ui.control.Label
        text19                    matlab.ui.control.Label
        text18                    matlab.ui.control.Label
        text17                    matlab.ui.control.Label
        text16                    matlab.ui.control.Label
        text15                    matlab.ui.control.Label
        text14                    matlab.ui.control.Label
        TxtIntercept              matlab.ui.control.Label
        TxtExpectedAcc            matlab.ui.control.Label
        TxtExpectedSpeed          matlab.ui.control.Label
        TxtSigmaAcc               matlab.ui.control.Label
        TxtSigmaSpeed             matlab.ui.control.Label
        TxtSigmaCond              matlab.ui.control.Label
        TxtSigmaW                 matlab.ui.control.Label
        InterceptSpeed            matlab.ui.control.Slider
        ExpectedAcc               matlab.ui.control.Slider
        ExpectedSpeed             matlab.ui.control.Slider
        InitialAccSigma           matlab.ui.control.Slider
        InitialSpeedSigma         matlab.ui.control.Slider
        InitialCondSigma          matlab.ui.control.Slider
        SigmaW                    matlab.ui.control.Slider
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function slider9_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Main_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Main (see VARARGIN)
%             
%             % Choose default command line output for Main
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes Main wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
            set(app.SigmaW, 'Limits',[0.0001,0.1]);
            set(app.InitialCondSigma, 'Limits',[1,10]);
            set(app.InitialSpeedSigma, 'Limits',[0.0001,1]);
            set(app.InitialAccSigma, 'Limits',[0.00001,1]);
            set(app.ExpectedSpeed, 'Limits',[-1,1]);
            set(app.ExpectedAcc, 'Limits',[-0.5,0.5]);
            set(app.InterceptSpeed, 'Limits',[0.0001,1]);
            set(app.SigmaW, 'Value', 0.005);
            set(app.InitialCondSigma,'Value',2);
            set(app.InitialSpeedSigma,'Value',0.02);
            set(app.InitialAccSigma,'Value',0.01);
            set(app.ExpectedSpeed,'Value',-0.1);
            set(app.ExpectedAcc,'Value',-0.1);
            set(app.InterceptSpeed,'Value',0.05);
            set(app.TxtSigmaW, 'Text',num2str(get(app.SigmaW,'Value')));
            set(app.TxtSigmaCond, 'Text',num2str(get(app.InitialCondSigma,'Value')));
            set(app.TxtSigmaSpeed, 'Text',num2str(get(app.InitialSpeedSigma,'Value')));
            set(app.TxtSigmaAcc, 'Text',num2str(get(app.InitialAccSigma,'Value')));
            set(app.TxtExpectedSpeed, 'Text',num2str(get(app.ExpectedSpeed,'Value')));
            set(app.TxtExpectedAcc, 'Text',num2str(get(app.ExpectedAcc,'Value')));
            set(app.TxtIntercept, 'Text',num2str(get(app.InterceptSpeed,'Value')));
            y=[NaN 100 100 NaN NaN 85 NaN NaN NaN NaN NaN NaN NaN NaN NaN];
            R=[NaN 8 9 NaN NaN 2 NaN NaN NaN NaN NaN NaN NaN NaN NaN];
            set(app.DataTable,'data',[y' R'])
        end

        % Value changed function: Constrained
        function Constrained_Callback(app, event)
            % hObject    handle to Constrained (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of Constrained
            SimpleTimeSeries
        end

        % Cell edit callback: DataTable
        function DataTable_CellEditCallback(app, event)
            % hObject    handle to DataTable (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            SimpleTimeSeries
        end

        % Value changed function: Durations
        function Durations_Callback(app, event)
            % hObject    handle to Durations (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Durations as text
            %        str2double(get(hObject,'String')) returns contents of Durations as a double
            SimpleTimeSeries
        end

        % Value changed function: ExpectedAcc
        function ExpectedAcc_Callback(app, event)
            % hObject    handle to ExpectedAcc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            SimpleTimeSeries
        end

        % Value changed function: ExpectedSpeed
        function ExpectedSpeed_Callback(app, event)
            % hObject    handle to ExpectedSpeed (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            SimpleTimeSeries
        end

        % Value changed function: InitialAccSigma
        function InitialAccSigma_Callback(app, event)
            % hObject    handle to InitialAccSigma (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            SimpleTimeSeries
        end

        % Value changed function: InitialCondSigma
        function InitialCondSigma_Callback(app, event)
            % hObject    handle to InitialCondSigma (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            SimpleTimeSeries
        end

        % Value changed function: InitialSpeedSigma
        function InitialSpeedSigma_Callback(app, event)
            % hObject    handle to InitialSpeedSigma (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            SimpleTimeSeries
        end

        % Value changed function: InterceptSpeed
        function InterceptSpeed_Callback(app, event)
            % hObject    handle to InterceptSpeed (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            SimpleTimeSeries
        end

        % Button pushed function: SaveCase
        function SaveCase_Callback(app, event)
            % hObject    handle to SaveCase (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SigmaWsliderValue = get(app.SigmaW,'Value');
            InitialCondSigmasliderValue = get(app.InitialCondSigma,'Value');
            InitialSpeedSigmasliderValue = get(app.InitialSpeedSigma,'Value');
            InitialAccSigmasliderValue = get(app.InitialAccSigma,'Value');
            ExpectedSpeedsliderValue = get(app.ExpectedSpeed,'Value');
            ExpectedAccsliderValue = get(app.ExpectedAcc,'Value');
            InterceptSpeedsliderValue = get(app.InterceptSpeed,'Value');
            Case1=get(app.Case1,'Value');
            Case2=get(app.Case2,'Value');
            Constraints=get(app.Constrained,'Value');
            DataTable=get(app.DataTable,'data');
            % ParameterLables={"Sigma_W",'Sigma_Cond','Sigma_Speed','Sigma_Acc',...
            %     'Expected_Speed','Expected_Acc','Intercept_Speed','Case1','Case2','Constraints'};
            Parameters=[SigmaWsliderValue,InitialCondSigmasliderValue,InitialSpeedSigmasliderValue,...
                InitialAccSigmasliderValue,ExpectedSpeedsliderValue,ExpectedAccsliderValue,...
                InterceptSpeedsliderValue,Case1,Case2,Constraints];
            [filename, pathname] = uiputfile({'*.csv';'*.*'}, 'Save as','case');
            try
                dlmwrite(fullfile(pathname,filename),DataTable,'delimiter',',','-append');
                dlmwrite(fullfile(pathname,filename),Parameters,'delimiter',',','-append');
            catch
                message = 'File is not saved';
                uialert(app.MainWindow,message,'Warning','Icon','warning');
            end
            
        end

        % Button pushed function: SaveGraphs
        function SaveGraphs_Callback(app, event)
            % hObject    handle to SaveGraphs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            datetime.setDefaultFormats('default','MM_dd_hh_mm_ss');
            figure(1)
            saveas(gcf,sprintf('%s/Tools/SingleTimeSeriesAnalyses/SavedCases/%sCondition.pdf',pwd,datetime));
            figure(2)
            saveas(gcf,sprintf('%s/Tools/SingleTimeSeriesAnalyses/SavedCases/%sSpeed.pdf',pwd,datetime));
            figure(3)
            saveas(gcf,sprintf('%s/Tools/SingleTimeSeriesAnalyses/SavedCases/%sAcc.pdf',pwd,datetime));
        end

        % Value changed function: SigmaW
        function SigmaW_Callback(app, event)
            % hObject    handle to SigmaW (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            SimpleTimeSeries
        end

        % Value changed function: TransParam
        function TransParam_Callback(app, event)
            % hObject    handle to TransParam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TransParam as text
            %        str2double(get(hObject,'String')) returns contents of TransParam as a double
            SimpleTimeSeries
        end

        % Selection changed function: uibuttongroup1
        function uibuttongroup1SelectionChanged(app, event)

            SimpleTimeSeries
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Single Time Series';
            app.figure1.Icon = 'OpenIPDM_icon_r.png';
            app.UIAxes = uiaxes(app.figure1);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.figure1);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.figure1);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SigmaW = uislider(app.figure1);
            app.SigmaW.Limits = [0 1];
            app.SigmaW.MajorTicks = [];
            app.SigmaW.ValueChangedFcn = createCallbackFcn(app, @SigmaW_Callback, true);
            app.SigmaW.MinorTicks = [];
            app.InitialCondSigma = uislider(app.figure1);
            app.InitialCondSigma.Limits = [0 1];
            app.InitialCondSigma.MajorTicks = [];
            app.InitialCondSigma.ValueChangedFcn = createCallbackFcn(app, @InitialCondSigma_Callback, true);
            app.InitialCondSigma.MinorTicks = [];
            app.InitialSpeedSigma = uislider(app.figure1);
            app.InitialSpeedSigma.Limits = [0 1];
            app.InitialSpeedSigma.MajorTicks = [];
            app.InitialSpeedSigma.ValueChangedFcn = createCallbackFcn(app, @InitialSpeedSigma_Callback, true);
            app.InitialSpeedSigma.MinorTicks = [];
            app.InitialAccSigma = uislider(app.figure1);
            app.InitialAccSigma.Limits = [0 1];
            app.InitialAccSigma.MajorTicks = [];
            app.InitialAccSigma.ValueChangedFcn = createCallbackFcn(app, @InitialAccSigma_Callback, true);
            app.InitialAccSigma.MinorTicks = [];
            app.ExpectedSpeed = uislider(app.figure1);
            app.ExpectedSpeed.Limits = [-1 1];
            app.ExpectedSpeed.MajorTicks = [];
            app.ExpectedSpeed.ValueChangedFcn = createCallbackFcn(app, @ExpectedSpeed_Callback, true);
            app.ExpectedSpeed.MinorTicks = [];
            app.ExpectedAcc = uislider(app.figure1);
            app.ExpectedAcc.Limits = [0 1];
            app.ExpectedAcc.MajorTicks = [];
            app.ExpectedAcc.ValueChangedFcn = createCallbackFcn(app, @ExpectedAcc_Callback, true);
            app.ExpectedAcc.MinorTicks = [];
            app.InterceptSpeed = uislider(app.figure1);
            app.InterceptSpeed.Limits = [0 1];
            app.InterceptSpeed.MajorTicks = [];
            app.InterceptSpeed.ValueChangedFcn = createCallbackFcn(app, @InterceptSpeed_Callback, true);
            app.InterceptSpeed.MinorTicks = [];
            app.TxtSigmaW = uilabel(app.figure1);
            app.TxtSigmaW.Text = 'Static Text';
            app.TxtSigmaCond = uilabel(app.figure1);
            app.TxtSigmaCond.Text = 'Static Text';
            app.TxtSigmaSpeed = uilabel(app.figure1);
            app.TxtSigmaSpeed.Text = 'Static Text';
            app.TxtSigmaAcc = uilabel(app.figure1);
            app.TxtSigmaAcc.Text = 'Static Text';
            app.TxtExpectedSpeed = uilabel(app.figure1);
            app.TxtExpectedSpeed.Text = 'Static Text';
            app.TxtExpectedAcc = uilabel(app.figure1);
            app.TxtExpectedAcc.Text = 'Static Text';
            app.TxtIntercept = uilabel(app.figure1);
            app.TxtIntercept.Text = 'Static Text';
            app.text14 = uilabel(app.figure1);
            app.text14.Text = 'Sigma_W';
            app.text15 = uilabel(app.figure1);
            app.text15.Text = 'Sigma Initial Cond';
            app.text16 = uilabel(app.figure1);
            app.text16.Text = 'Sigma Initial Speed';
            app.text17 = uilabel(app.figure1);
            app.text17.Text = 'Sigma Initial Acc';
            app.text18 = uilabel(app.figure1);
            app.text18.Text = 'Expected Initial Speed';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Expected Initial Acc';
            app.text20 = uilabel(app.figure1);
            app.text20.Text = 'Intercept (Initial Speed) ';
            app.Constrained = uicheckbox(app.figure1);
            app.Constrained.ValueChangedFcn = createCallbackFcn(app, @Constrained_Callback, true);
            app.Constrained.Text = 'Constrained KF';
            app.Durations = uieditfield(app.figure1, 'text');
            app.Durations.ValueChangedFcn = createCallbackFcn(app, @Durations_Callback, true);
            app.Durations.Value = '10';
            app.text22 = uilabel(app.figure1);
            app.text22.Text = 'Years';
            app.text23 = uilabel(app.figure1);
            app.text23.Text = 'Duration';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup1SelectionChanged, true);
            app.uibuttongroup1.Title = 'Method';
            app.uibuttongroup1.Visible = 'off';
            app.Case1 = uiradiobutton(app.uibuttongroup1);
            app.Case1.Text = 'Kalman Filter';
            app.Case1.Value = true;
            app.Case2 = uiradiobutton(app.uibuttongroup1);
            app.Case2.Visible = 'off';
            app.Case2.Text = 'Cubature Kalman Filter';
            app.DataTable = uitable(app.figure1);
            app.DataTable.ColumnName = {'Data'; 'Sigma_V'};
            app.DataTable.ColumnEditable = [true true];
            app.DataTable.CellEditCallback = createCallbackFcn(app, @DataTable_CellEditCallback, true);
            app.SaveCase = uibutton(app.figure1, 'push');
            app.SaveCase.ButtonPushedFcn = createCallbackFcn(app, @SaveCase_Callback, true);
            app.SaveCase.Text = 'Save Case';
            app.SaveGraphs = uibutton(app.figure1, 'push');
            app.SaveGraphs.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphs_Callback, true);
            app.SaveGraphs.Enable = 'off';
            app.SaveGraphs.Visible = 'off';
            app.SaveGraphs.Text = 'Save Graphs';
            app.TransParam = uieditfield(app.figure1, 'text');
            app.TransParam.ValueChangedFcn = createCallbackFcn(app, @TransParam_Callback, true);
            app.TransParam.Value = '3';
            app.text24 = uilabel(app.figure1);
            app.text24.Text = 'Tr. Param';
            app.InterventionSpinnerLabel = uilabel(app.figure1);
            app.InterventionSpinnerLabel.Text = 'Intervention';
            app.InterventionSpinner = uispinner(app.figure1);
            app.InterventionSpinner.Limits = [0 Inf];
            app.InterventionSpinner.Value = 3;
            app.text22_2 = uilabel(app.figure1);
            app.text22_2.Text = 'Year';
            app.UnderwentIntervention = uicheckbox(app.figure1);
            app.UnderwentIntervention.Text = 'Underwent Intervention';
            app.IntsigmaEditFieldLabel = uilabel(app.figure1);
            app.IntsigmaEditFieldLabel.Text = 'Int. sigma';
            app.IntsigmaEditField = uieditfield(app.figure1, 'numeric');
            app.IntsigmaEditField.Limits = [0 Inf];
            app.IntsigmaEditField.Value = 1;
            app.IntmuEditFieldLabel = uilabel(app.figure1);
            app.IntmuEditFieldLabel.Text = 'Int. mu';
            app.IntmuEditField = uieditfield(app.figure1, 'numeric');
            app.IntmuEditField.Limits = [0 Inf];
            app.IntmuEditField.Value = 10;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = single_time

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end