classdef SyntheticData_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SyntheticDataGeneratorFigure    matlab.ui.Figure
        AboutD                          matlab.ui.container.Menu
        AboutSDG                        matlab.ui.container.Menu
        uipanel1                        matlab.ui.container.Panel
        TimeSpan                        matlab.ui.control.EditField
        TimeStep                        matlab.ui.control.EditField
        NumElem                         matlab.ui.control.EditField
        text2                           matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        NumInsp                         matlab.ui.control.EditField
        text15                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        MinCondVal                      matlab.ui.control.EditField
        justtext                        matlab.ui.control.Label
        MaxCondVal                      matlab.ui.control.EditField
        text20                          matlab.ui.control.Label
        LoadWeight                      matlab.ui.control.Button
        NumObservationsDensityEditFieldLabel  matlab.ui.control.Label
        NumObservationsDensityEditField  matlab.ui.control.EditField
        uipanel2                        matlab.ui.container.Panel
        SigmaW                          matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        EInitSpeed                      matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        StdInitSpeed                    matlab.ui.control.EditField
        text13                          matlab.ui.control.Label
        EInitAcc                        matlab.ui.control.EditField
        text14                          matlab.ui.control.Label
        StdInitAcc                      matlab.ui.control.EditField
        TransN                          matlab.ui.control.EditField
        text18                          matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        MaxInspSTD                      matlab.ui.control.EditField
        text10                          matlab.ui.control.Label
        MaxBiasVal                      matlab.ui.control.EditField
        MinBiasVal                      matlab.ui.control.EditField
        text10_2                        matlab.ui.control.Label
        text6                           matlab.ui.control.Label
        MinInspSTD                      matlab.ui.control.EditField
        GenerateData                    matlab.ui.control.Button
        CloseAll                        matlab.ui.control.Button
        PlotSample                      matlab.ui.control.Button
        SaveSyntheticData               matlab.ui.control.Button
        uipanel4                        matlab.ui.container.Panel
        CondSlider                      matlab.ui.control.Slider
        text29                          matlab.ui.control.Label
        CondLbl                         matlab.ui.control.Label
        SpeedSlider                     matlab.ui.control.Slider
        text31                          matlab.ui.control.Label
        SpeedLbl                        matlab.ui.control.Label
        AccSlider                       matlab.ui.control.Slider
        text33                          matlab.ui.control.Label
        AccLbl                          matlab.ui.control.Label
        RefreshSample                   matlab.ui.control.Button
        text37                          matlab.ui.control.Label
        TSStatus                        matlab.ui.control.Label
        text39                          matlab.ui.control.Label
        RJReason                        matlab.ui.control.Label
        StructuralAttributesPanel       matlab.ui.container.Panel
        GenerateStructuralAttributesDefault2AttributesCheckBox  matlab.ui.control.CheckBox
        SigmaW_2                        matlab.ui.control.EditField
        text8_2                         matlab.ui.control.Label
        SigmaW_4                        matlab.ui.control.EditField
        text8_4                         matlab.ui.control.Label
        InterventionsPanel              matlab.ui.container.Panel
        UnderwentInterventionsCheckBox  matlab.ui.control.CheckBox
        LoadInterventionsParam          matlab.ui.control.Button
        LoadInterventionsParamEditFieldLabel  matlab.ui.control.Label
        LoadInterventionsParamEditField  matlab.ui.control.EditField
        FinishNowCheckBox               matlab.ui.control.CheckBox
        EarlystopforthedatagenerationLabel  matlab.ui.control.Label
        timeseries_lbl                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ModuleGeneratedSyntheticData % Description
        ModuleObservedData
        ModuleTrueState
        InterventionsParam
        ObservationDensity=[];
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SyntheticData_OpeningFcn(app)

            set(app.CondSlider, 'Limits',[50,100]);
            set(app.SpeedSlider, 'Limits',[-1,0]);
            set(app.AccSlider, 'Limits',[-0.5,0.5]);
            set(app.CondSlider, 'Value', 100);
            set(app.SpeedSlider,'Value',-0.2);
            set(app.AccSlider,'Value',-0.02);
            set(app.CondLbl, 'Text',num2str(get(app.CondSlider,'Value')));
            set(app.SpeedLbl, 'Text',num2str(get(app.SpeedSlider,'Value')));
            set(app.AccLbl, 'Text',num2str(get(app.AccSlider,'Value')));
        end

        % Menu selected function: AboutSDG
        function AboutSDG_Callback(app, event)
            
            uialert(app.SyntheticDataGeneratorFigure,[{'Generate Synthetic Visual Inspections Data'};{''};{'Developed By: Zachary A. Hamida, Ph.D.'};{'Webpage:zachamida.github.io'};{''};{'Prof. James-A. Goulet Research Group'}],'Info',"Icon","info")
        end

        % Value changed function: AccSlider
        function AccSlider_Callback(app, event)

            AnlysesSingleTimeSeries
        end

        % Button pushed function: CloseAll
        function CloseAll_Callback(app, event)

            close(app.SyntheticDataGeneratorFigure);
        end

        % Value changed function: CondSlider
        function CondSlider_Callback(app, event)

            AnlysesSingleTimeSeries
        end

        % Button pushed function: GenerateData
        function GenerateData_Callback(app, event)

            try
                TimeSpanV=str2double(get(app.TimeSpan,'Value'));
                TimeStepV=str2double(get(app.TimeStep,'Value'));
                NumElemV=str2double(get(app.NumElem,'Value'));
                NumInspV=str2double(get(app.NumInsp,'Value'));
                MinInspSTDV=str2double(get(app.MinInspSTD,'Value'));
                MaxInspSTDV=str2double(get(app.MaxInspSTD,'Value'));
                MaxCondV=str2double(get(app.MaxCondVal,'Value'));
                MinCondV=str2double(get(app.MinCondVal,'Value'));
                SigmaW1=str2double(get(app.SigmaW_4,'Value'));
                SigmaW2=str2double(get(app.SigmaW_2,'Value'));
                SigmaWV=str2double(get(app.SigmaW,'Value'));
                MaxBias=str2double(get(app.MaxBiasVal,'Value'));
                MinBias=str2double(get(app.MinBiasVal,'Value'));
                MaxTimeCuted=75;%str2double(get(app.MaxTimeCuts,'Value'));
                MaxInitCondV=MaxCondV;%str2double(get(app.MaxBiasVal,'Value'));
                EInitSpeedV=str2double(get(app.EInitSpeed,'Value'));
                StdInitSpeedV=str2double(get(app.StdInitSpeed,'Value'));
                EInitAccV=str2double(get(app.EInitAcc,'Value'));
                StdInitAccV=str2double(get(app.StdInitAcc,'Value'));
                Ncurve=str2double(get(app.TransN,'Value'));
                InterventionCheck=get(app.UnderwentInterventionsCheckBox,'Value');
                if ~isempty(app.ObservationDensity)
                    WeightedProb=app.ObservationDensity;
                else
                    WeightedProb=[];
                end
                [app.ModuleTrueState,app.ModuleObservedData,app.ModuleGeneratedSyntheticData]=GenerateSyntheticData(app...
                    ,TimeSpanV,TimeStepV,NumElemV,NumInspV,MinInspSTDV,MaxInspSTDV,SigmaWV,SigmaW1,SigmaW2,...
                    MaxInitCondV,MaxTimeCuted,StdInitSpeedV,EInitSpeedV,StdInitAccV,EInitAccV,...
                    Ncurve,MaxCondV,MinCondV,InterventionCheck,WeightedProb,MaxBias,MinBias);
            catch
                message = 'Invalid input data, or the run has been interrupted by the user';
                uialert(app.SyntheticDataGeneratorFigure,message,'Error','Icon','error');
            end
        end

        % Button pushed function: PlotSample
        function PlotSample_Callback(app, event)
            try
                MaxCondV=str2double(get(app.MaxCondVal,'Value'));
                MinCondV=str2double(get(app.MinCondVal,'Value'));
                C = mat2cell(rand(1,3*length(app.ModuleTrueState)),1,3*ones(1,length(app.ModuleTrueState)));
                Ncurve=str2double(get(app.TransN,'Value'));
                VR=randi([1 length(app.ModuleTrueState)]);
                figure(2)
                for i=VR:VR
                    TrueOriginal=RevSpaceTransform(Ncurve,app.ModuleTrueState{1,i}(1,:),MaxCondV,MinCondV);
                    plot(1964:(1963+length(TrueOriginal)),TrueOriginal,'color',C{i});
                    title('True State Sample Data')
                    xlabel('Time')
                    ylabel('Structural Element Condition')
                    xlim([1964,1963+length(TrueOriginal)])
                    ylim([MinCondV,MaxCondV])
                    hold('on')
                    ObsOriginal=RevSpaceTransform(Ncurve,app.ModuleObservedData{i,1}(2:end,1),MaxCondV,MinCondV);
                    plot(1965:(1964+length(ObsOriginal)),ObsOriginal,'*','color',C{i});
                    title('Observed State Sample Data')
                    xlabel('Time')
                    ylabel('Structural Element Condition')
                    xlim([1964,1963+length(TrueOriginal)])
                    ylim([MinCondV,MaxCondV])
                end
            catch
                message = 'No synthetic data to plot, generate synthetic by pressing "Generate!"';
                uialert(app.SyntheticDataGeneratorFigure,message,'Warning','Icon','warning');
            end
             
        end

        % Button pushed function: RefreshSample
        function RefreshSample_Callback(app, event)
            
            AnlysesSingleTimeSeries
        end

        % Button pushed function: SaveSyntheticData
        function SaveSyntheticData_Callback(app, event)
            try
                SynDatabaseShort=app.ModuleGeneratedSyntheticData.ShortObserved;
                SynDatabaseState=app.ModuleGeneratedSyntheticData.TrueState';
                InspTrue=app.ModuleGeneratedSyntheticData.InspectorsDataError(:,2:3);
                InspID=app.ModuleGeneratedSyntheticData.InspectorsDataError(:,1);
                GeneratedData=app.ModuleGeneratedSyntheticData;
                TrueInsp=[InspTrue InspID];
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','Generated Data');
                save(fullfile(pathname,filename),'GeneratedData');
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','Full and Short');
                save(fullfile(pathname,filename),'SynDatabaseShort','SynDatabaseState');
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','Observed');
                save(fullfile(pathname,filename),'SynDatabaseShort');
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','TrueInspector');
                save(fullfile(pathname,filename),'TrueInsp');
                [filename, pathname] = uiputfile({'*.mat';'*.*'}, 'Save as','InspectorID');
                save(fullfile(pathname,filename),'InspID');
            catch
                message = 'No synthetic data to save, generate synthetic by pressing "Generate!"';
                uialert(app.SyntheticDataGeneratorFigure,message,'Warning','Icon','warning');
            end
        end

        % Value changed function: SpeedSlider
        function SpeedSlider_Callback(app, event)
            % hObject    handle to SpeedSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            AnlysesSingleTimeSeries
        end

        % Button pushed function: LoadInterventionsParam
        function LoadInterventionsParamButtonPushed(app, event)
            FilterSuffix={'*.mat';'*.*'};
            try
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select interventions parameters file');
                set(app.LoadInterventionsParamEditField,'Value',sprintf('%s\%s',FilePath,VIDataFileName))
                app.InterventionsParam=load(sprintf('%s/%s',FilePath,VIDataFileName));
                FNames = fieldnames(app.InterventionsParam);
                app.InterventionsParam=app.InterventionsParam.(FNames{1});
            catch
                message = 'No file was selected, or the selected file is incompatible';
                uialert(app.SyntheticDataGeneratorFigure,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: LoadWeight
        function LoadWeightButtonPushed(app, event)
            FilterSuffix={'*.mat';'*.*'};
            try
                [VIDataFileName,FilePath]=uigetfile(FilterSuffix,'Select observations density file');
                set(app.NumObservationsDensityEditField,'Value',sprintf('%s\%s',FilePath,VIDataFileName))
                app.ObservationDensity=load(sprintf('%s/%s',FilePath,VIDataFileName));
                FNames = fieldnames(app.ObservationDensity);
                app.ObservationDensity=app.ObservationDensity.(FNames{1});
            catch
                message = 'No file was selected, or the selected file is incompatible';
                uialert(app.SyntheticDataGeneratorFigure,message,'Warning','Icon','warning');
            end
        end

        % Callback function
        function Stop_genButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SyntheticDataGeneratorFigure = uifigure('Visible', 'off');
            app.SyntheticDataGeneratorFigure.Name = 'Generate Synthetic Data';
            app.SyntheticDataGeneratorFigure.Icon = 'OpenIPDM_icon_r.png';
            app.AboutD = uimenu(app.SyntheticDataGeneratorFigure);
            app.AboutD.Text = 'About';
            app.AboutSDG = uimenu(app.AboutD);
            app.AboutSDG.MenuSelectedFcn = createCallbackFcn(app, @AboutSDG_Callback, true);
            app.AboutSDG.Text = 'About SDG';
            app.uipanel1 = uipanel(app.SyntheticDataGeneratorFigure);
            app.uipanel1.Title = 'General Properties';
            app.TimeSpan = uieditfield(app.uipanel1, 'text');
            app.TimeSpan.Value = '60';
            app.TimeStep = uieditfield(app.uipanel1, 'text');
            app.TimeStep.Value = '1';
            app.NumElem = uieditfield(app.uipanel1, 'text');
            app.NumElem.Value = '500';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Time Span';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Time Step';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Num. Struct. Elem.';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'Num. Inspectors';
            app.NumInsp = uieditfield(app.uipanel1, 'text');
            app.NumInsp.Value = '250';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Text = 'Yrs';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Text = 'Yrs';
            app.MinCondVal = uieditfield(app.uipanel1, 'text');
            app.MinCondVal.Value = '25';
            app.justtext = uilabel(app.uipanel1);
            app.justtext.Text = 'Min. Cond.';
            app.MaxCondVal = uieditfield(app.uipanel1, 'text');
            app.MaxCondVal.Value = '100';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Text = 'Max. Cond.';
            app.LoadWeight = uibutton(app.uipanel1, 'push');
            app.LoadWeight.ButtonPushedFcn = createCallbackFcn(app, @LoadWeightButtonPushed, true);
            app.LoadWeight.Text = '...';
            app.NumObservationsDensityEditFieldLabel = uilabel(app.uipanel1);
            app.NumObservationsDensityEditFieldLabel.Text = 'Num. Observations Density';
            app.NumObservationsDensityEditField = uieditfield(app.uipanel1, 'text');
            app.uipanel2 = uipanel(app.SyntheticDataGeneratorFigure);
            app.uipanel2.Title = 'Model Parameters';
            app.SigmaW = uieditfield(app.uipanel2, 'text');
            app.SigmaW.Value = '0.005';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Text = 'sigma_w';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Text = 'E [Init. Speed]';
            app.EInitSpeed = uieditfield(app.uipanel2, 'text');
            app.EInitSpeed.Value = '-0.05';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Text = 'Std. Init. Speed';
            app.StdInitSpeed = uieditfield(app.uipanel2, 'text');
            app.StdInitSpeed.Value = '0.015';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Text = 'E [Init. Acc.]';
            app.EInitAcc = uieditfield(app.uipanel2, 'text');
            app.EInitAcc.Value = '-0.001';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Text = 'Std. Init. Acc.';
            app.StdInitAcc = uieditfield(app.uipanel2, 'text');
            app.StdInitAcc.Value = '0.01';
            app.TransN = uieditfield(app.uipanel2, 'text');
            app.TransN.Value = '4';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Text = 'Trans. Param.';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Text = 'Max. sigma_v';
            app.MaxInspSTD = uieditfield(app.uipanel2, 'text');
            app.MaxInspSTD.Value = '6';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'Max. Bias';
            app.MaxBiasVal = uieditfield(app.uipanel2, 'text');
            app.MaxBiasVal.Value = '0';
            app.MinBiasVal = uieditfield(app.uipanel2, 'text');
            app.MinBiasVal.Value = '0';
            app.text10_2 = uilabel(app.uipanel2);
            app.text10_2.Text = 'Min. Bias';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = 'Min. sigma_v';
            app.MinInspSTD = uieditfield(app.uipanel2, 'text');
            app.MinInspSTD.Value = '1';
            app.GenerateData = uibutton(app.SyntheticDataGeneratorFigure, 'push');
            app.GenerateData.ButtonPushedFcn = createCallbackFcn(app, @GenerateData_Callback, true);
            app.GenerateData.Text = 'Generate!';
            app.CloseAll = uibutton(app.SyntheticDataGeneratorFigure, 'push');
            app.CloseAll.ButtonPushedFcn = createCallbackFcn(app, @CloseAll_Callback, true);
            app.CloseAll.Text = 'Exit';
            app.PlotSample = uibutton(app.SyntheticDataGeneratorFigure, 'push');
            app.PlotSample.ButtonPushedFcn = createCallbackFcn(app, @PlotSample_Callback, true);
            app.PlotSample.Text = 'Plot Sample';
            app.SaveSyntheticData = uibutton(app.SyntheticDataGeneratorFigure, 'push');
            app.SaveSyntheticData.ButtonPushedFcn = createCallbackFcn(app, @SaveSyntheticData_Callback, true);
            app.SaveSyntheticData.Text = 'Save';
            app.uipanel4 = uipanel(app.SyntheticDataGeneratorFigure);
            app.uipanel4.Title = 'Check True State';
            app.CondSlider = uislider(app.uipanel4);
            app.CondSlider.Limits = [0 1];
            app.CondSlider.MajorTicks = [];
            app.CondSlider.ValueChangedFcn = createCallbackFcn(app, @CondSlider_Callback, true);
            app.CondSlider.MinorTicks = [];
            app.text29 = uilabel(app.uipanel4);
            app.text29.Text = 'Init. [Cond.]';
            app.CondLbl = uilabel(app.uipanel4);
            app.CondLbl.Text = 'Static Text';
            app.SpeedSlider = uislider(app.uipanel4);
            app.SpeedSlider.Limits = [0 1];
            app.SpeedSlider.MajorTicks = [];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSlider_Callback, true);
            app.SpeedSlider.MinorTicks = [];
            app.text31 = uilabel(app.uipanel4);
            app.text31.Text = 'Init. [Speed]';
            app.SpeedLbl = uilabel(app.uipanel4);
            app.SpeedLbl.Text = 'Static Text';
            app.AccSlider = uislider(app.uipanel4);
            app.AccSlider.Limits = [0 1];
            app.AccSlider.MajorTicks = [];
            app.AccSlider.ValueChangedFcn = createCallbackFcn(app, @AccSlider_Callback, true);
            app.AccSlider.MinorTicks = [];
            app.text33 = uilabel(app.uipanel4);
            app.text33.Text = 'Init. [Acc.]';
            app.AccLbl = uilabel(app.uipanel4);
            app.AccLbl.Text = 'Static Text';
            app.RefreshSample = uibutton(app.uipanel4, 'push');
            app.RefreshSample.ButtonPushedFcn = createCallbackFcn(app, @RefreshSample_Callback, true);
            app.RefreshSample.Text = 'Refresh Plot';
            app.text37 = uilabel(app.uipanel4);
            app.text37.Text = 'Status:';
            app.TSStatus = uilabel(app.uipanel4);
            app.TSStatus.Text = 'N.A.';
            app.text39 = uilabel(app.uipanel4);
            app.text39.Text = 'Reason:';
            app.RJReason = uilabel(app.uipanel4);
            app.RJReason.Text = 'N.A.';
            app.StructuralAttributesPanel = uipanel(app.SyntheticDataGeneratorFigure);
            app.StructuralAttributesPanel.Title = 'Structural Attributes';
            app.GenerateStructuralAttributesDefault2AttributesCheckBox = uicheckbox(app.StructuralAttributesPanel);
            app.GenerateStructuralAttributesDefault2AttributesCheckBox.Text = 'Generate Structural Attributes (Default: 2 Attributes.)';
            app.SigmaW_2 = uieditfield(app.StructuralAttributesPanel, 'text');
            app.SigmaW_2.Value = '0.01';
            app.text8_2 = uilabel(app.StructuralAttributesPanel);
            app.text8_2.Text = 'sigma_w1';
            app.SigmaW_4 = uieditfield(app.StructuralAttributesPanel, 'text');
            app.SigmaW_4.Value = '0.1';
            app.text8_4 = uilabel(app.StructuralAttributesPanel);
            app.text8_4.Text = 'sigma_w2';
            app.InterventionsPanel = uipanel(app.SyntheticDataGeneratorFigure);
            app.InterventionsPanel.Title = 'Interventions';
            app.UnderwentInterventionsCheckBox = uicheckbox(app.InterventionsPanel);
            app.UnderwentInterventionsCheckBox.Text = 'Underwent Interventions';
            app.LoadInterventionsParam = uibutton(app.InterventionsPanel, 'push');
            app.LoadInterventionsParam.ButtonPushedFcn = createCallbackFcn(app, @LoadInterventionsParamButtonPushed, true);
            app.LoadInterventionsParam.Text = '...';
            app.LoadInterventionsParamEditFieldLabel = uilabel(app.InterventionsPanel);
            app.LoadInterventionsParamEditFieldLabel.Text = 'Load Interventions Param.';
            app.LoadInterventionsParamEditField = uieditfield(app.InterventionsPanel, 'text');
            app.FinishNowCheckBox = uicheckbox(app.SyntheticDataGeneratorFigure);
            app.FinishNowCheckBox.Enable = 'off';
            app.FinishNowCheckBox.Text = 'Finish Earlier';
            app.EarlystopforthedatagenerationLabel = uilabel(app.SyntheticDataGeneratorFigure);
            app.EarlystopforthedatagenerationLabel.Text = 'Early stop for the data generation:';
            app.timeseries_lbl = uilabel(app.SyntheticDataGeneratorFigure);
            app.timeseries_lbl.Text = '';
            % Show the figure after all components are created
            app.SyntheticDataGeneratorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SyntheticData_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SyntheticDataGeneratorFigure)

            % Execute the startup function
            runStartupFcn(app, @SyntheticData_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SyntheticDataGeneratorFigure)
        end
    end
end