classdef status_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatusUIFigure  matlab.ui.Figure
        AvailableLabel  matlab.ui.control.Label
        CloseButton     matlab.ui.control.Button
        UITable         matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           if isfolder('Parameters')
               ModelParams = dir('Parameters/D*.mat');
               if ~isempty(ModelParams)
                   FilePath = [pwd '/Parameters/'];
                   ModelParamLoad = load([FilePath ModelParams(1).name]);
                   table_data = cell(size(ModelParamLoad.AllElementsParameters,1),3);
                   table_data(:,1) = ModelParamLoad.AllElementsParameters(:,1);
                   available_count = 0;
                   for i=1:size(ModelParamLoad.AllElementsParameters,1)
                       if isempty(ModelParamLoad.AllElementsParameters{i,2})
                           table_data(i,2) = {sprintf('\x2716')};
                       else
                           table_data(i,2) = {sprintf('\x2713')};
                           available_count = available_count + 1;
                       end
                       table_data(i,3) = {sprintf('\x2716')};
                   end
                   app.UITable.Data = table_data;
                   app.AvailableLabel.Text = sprintf('Available: %d/(Total: %d)',available_count,size(ModelParamLoad.AllElementsParameters,1));
               end
           end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.StatusUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StatusUIFigure = uifigure('Visible', 'off');
            app.StatusUIFigure.Name = 'Status';
            app.StatusUIFigure.Icon = 'OpenIPDM_icon_r.png';
            app.UITable = uitable(app.StatusUIFigure);
            app.UITable.ColumnName = {'Category'; '%A/%B/%C/%D'; 'CEC'};
            app.UITable.RowName = {};
            app.CloseButton = uibutton(app.StatusUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.AvailableLabel = uilabel(app.StatusUIFigure);
            app.AvailableLabel.Text = {'Available: '; ''};
            % Show the figure after all components are created
            app.StatusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = status_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StatusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatusUIFigure)
        end
    end
end